classdef visualize_light < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CELL_ID                         matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        PreprocessingMenu               matlab.ui.container.Menu
        ArtifactRemovalMenu             matlab.ui.container.Menu
        ManualMenu                      matlab.ui.container.Menu
        AutomaticMenu                   matlab.ui.container.Menu
        DownsampleImageMenu             matlab.ui.container.Menu
        ThresholdImageMenu              matlab.ui.container.Menu
        HistogramMatchingMenu           matlab.ui.container.Menu
        ImageMenu                       matlab.ui.container.Menu
        NormalizeColorsMenu             matlab.ui.container.Menu
        SpectralUnmixingMenu            matlab.ui.container.Menu
        AdjustGammaMenu                 matlab.ui.container.Menu
        AllChannels                     matlab.ui.container.Menu
        IndividualChannels              matlab.ui.container.Menu
        AdjustBrightnessMenu            matlab.ui.container.Menu
        AdjustHistogramMenu             matlab.ui.container.Menu
        RotateAllMenu                   matlab.ui.container.Menu
        RotateAllHorizontalMenu         matlab.ui.container.Menu
        RotateAllVerticalMenu           matlab.ui.container.Menu
        RotateAllClockwiseMenu          matlab.ui.container.Menu
        RotateAllAntiClockwiseMenu      matlab.ui.container.Menu
        RotateImageMenu                 matlab.ui.container.Menu
        RotateImageHorizontalMenu       matlab.ui.container.Menu
        RotateImageVerticalMenu         matlab.ui.container.Menu
        RotateImageClockwiseMenu        matlab.ui.container.Menu
        RotateImageAntiClockwiseMenu    matlab.ui.container.Menu
        RotateNeuronsMenu               matlab.ui.container.Menu
        RotateNeuronsHorizontalMenu     matlab.ui.container.Menu
        RotateNeuronsVerticalMenu       matlab.ui.container.Menu
        RotateNeuronsClockwiseMenu      matlab.ui.container.Menu
        RotateNeuronsAntiClockwiseMenu  matlab.ui.container.Menu
        MirrorImageMenu                 matlab.ui.container.Menu
        MirrorNeuronsMenu               matlab.ui.container.Menu
        NeuronsMenu                     matlab.ui.container.Menu
        ImportneuronsfromMenu           matlab.ui.container.Menu
        CSVfileMenu                     matlab.ui.container.Menu
        NWBfileMenu                     matlab.ui.container.Menu
        ImportactivityfromMenu          matlab.ui.container.Menu
        NWBfileMenu_2                   matlab.ui.container.Menu
        ZephIRoutputMenu                matlab.ui.container.Menu
        FlipLRNeuronsMenu               matlab.ui.container.Menu
        AutoSegmentationMenu            matlab.ui.container.Menu
        NeuronActivityMenu              matlab.ui.container.Menu
        TraceActivityMenu_2             matlab.ui.container.Menu
        DisplayNeuronActivityMenu       matlab.ui.container.Menu
        BatchDetectIDNeuronsMenu        matlab.ui.container.Menu
        DeleteUserIDsMenu               matlab.ui.container.Menu
        DeleteModelIDsMenu              matlab.ui.container.Menu
        ToggleAutoIDUpdatesMenu         matlab.ui.container.Menu
        ToggleNeuronDetectionMenu       matlab.ui.container.Menu
        DisplayMenu                     matlab.ui.container.Menu
        ToggleBirthTimesMenu            matlab.ui.container.Menu
        NeuronDotsMenu                  matlab.ui.container.Menu
        GFPColorMenu                    matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        SaveIDInfoMenu                  matlab.ui.container.Menu
        SaveIDImageMenu                 matlab.ui.container.Menu
        SaveNWBMenu                     matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        CheckforUpdatesMenu             matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        InstructionsMenu                matlab.ui.container.Menu
        KeyboardShortcutsMenu           matlab.ui.container.Menu
        NeuroPALWebsiteMenu             matlab.ui.container.Menu
        ReportaBugMenu                  matlab.ui.container.Menu
        DebugMenu                       matlab.ui.container.Menu
        LogtesteventMenu                matlab.ui.container.Menu
        LogfadeeventMenu                matlab.ui.container.Menu
        DebugstatesMenu                 matlab.ui.container.Menu
        ResetProcessingTabMenu          matlab.ui.container.Menu
        DebugactionsMenu                matlab.ui.container.Menu
        GUIMenu                         matlab.ui.container.Menu
        RotationGUIMenu                 matlab.ui.container.Menu
        ProcessingMenu                  matlab.ui.container.Menu
        daspect_testMenu                matlab.ui.container.Menu
        projected_axes_testMenu         matlab.ui.container.Menu
        TriggerBreakpointMenu           matlab.ui.container.Menu
        OptimizationMenu                matlab.ui.container.Menu
        ProfilingMenu                   matlab.ui.container.Menu
        EnableautoprofilingMenu         matlab.ui.container.Menu
        ProfileOnMenu                   matlab.ui.container.Menu
        ProfileOffMenu                  matlab.ui.container.Menu
        load_graphic                    matlab.ui.control.Image
        ProcToggleChannelsPanel         matlab.ui.container.Panel
        ProcToggleChannelGrid           matlab.ui.container.GridLayout
        ProcRedPanel                    matlab.ui.container.Panel
        ProcRedGrid                     matlab.ui.container.GridLayout
        ProcRDropDown                   matlab.ui.control.DropDown
        ProcRCheckBox                   matlab.ui.control.CheckBox
        ProcRLabel                      matlab.ui.control.Label
        ProcBluePanel                   matlab.ui.container.Panel
        ProcBlueGrid                    matlab.ui.container.GridLayout
        ProcBDropDown                   matlab.ui.control.DropDown
        ProcBCheckBox                   matlab.ui.control.CheckBox
        ProcBLabel                      matlab.ui.control.Label
        ProcGreenPanel                  matlab.ui.container.Panel
        ProcGreenGrid                   matlab.ui.container.GridLayout
        ProcGDropDown                   matlab.ui.control.DropDown
        ProcGCheckBox                   matlab.ui.control.CheckBox
        ProcGLabel                      matlab.ui.control.Label
        ProcWhitePanel                  matlab.ui.container.Panel
        ProcWhiteGrid                   matlab.ui.container.GridLayout
        ProcWLabel                      matlab.ui.control.Label
        ProcWDropDown                   matlab.ui.control.DropDown
        ProcWCheckBox                   matlab.ui.control.CheckBox
        ProcGFPPanel                    matlab.ui.container.Panel
        ProcGFPGrid                     matlab.ui.container.GridLayout
        ProcGFPDropDown                 matlab.ui.control.DropDown
        ProcGFPCheckBox                 matlab.ui.control.CheckBox
        ProcGFPLabel                    matlab.ui.control.Label
        ProcDICPanel                    matlab.ui.container.Panel
        ProcDICGrid                     matlab.ui.container.GridLayout
        ProcDICLabel                    matlab.ui.control.Label
        ProcDICCheckBox                 matlab.ui.control.CheckBox
        ProcDICDropDown                 matlab.ui.control.DropDown
        PlaceholderTimelinesliderLabel  matlab.ui.control.Label
        PlaceholderProcTimeline         matlab.ui.container.Panel
        GridLayout40_2                  matlab.ui.container.GridLayout
        Panel_76                        matlab.ui.container.Panel
        GridLayout42_2                  matlab.ui.container.GridLayout
        Panel_78                        matlab.ui.container.Panel
        GridLayout44_2                  matlab.ui.container.GridLayout
        proc_tGrinder                   matlab.ui.control.RangeSlider
        proc_tSlider                    matlab.ui.control.Slider
        Panel_77                        matlab.ui.container.Panel
        GridLayout43_2                  matlab.ui.container.GridLayout
        proc_tEditField                 matlab.ui.control.NumericEditField
        tEditField_2Label               matlab.ui.control.Label
        Panel_75                        matlab.ui.container.Panel
        GridLayout41_2                  matlab.ui.container.GridLayout
        proc_zEditField                 matlab.ui.control.NumericEditField
        zEditField_2Label               matlab.ui.control.Label
        proc_yEditField                 matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        proc_xEditField                 matlab.ui.control.NumericEditField
        xEditField_2Label               matlab.ui.control.Label
        PlaceholderActivitytabLabel     matlab.ui.control.Label
        PlaceholderTrackingtabsLabel    matlab.ui.control.Label
        PlaceholderButtonsCovertabsifnodataloadedLabel  matlab.ui.control.Label
        ProcessingButton                matlab.ui.control.Button
        IdButton                        matlab.ui.control.Button
        TrackingButton                  matlab.ui.control.Button
        GridSearchPanel                 matlab.ui.container.Panel
        GridLayout54                    matlab.ui.container.GridLayout
        CancelGridSearchButton          matlab.ui.control.Button
        StartGridSearchButton           matlab.ui.control.Button
        Panel_47                        matlab.ui.container.Panel
        GridLayout57                    matlab.ui.container.GridLayout
        minutesLabel                    matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        EstimatedSearchtimeLabel        matlab.ui.control.Label
        SearchrangesPanel               matlab.ui.container.Panel
        GridLayout56                    matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        VideoPreprocessingPanel         matlab.ui.container.Panel
        GridLayout55                    matlab.ui.container.GridLayout
        ChannelstoincludeLabel          matlab.ui.control.Label
        ListBox_2                       matlab.ui.control.ListBox
        OnlyappliestogridsearchLabel    matlab.ui.control.Label
        DownsamplingFactortEditField    matlab.ui.control.NumericEditField
        DownsamplingFactortEditFieldLabel  matlab.ui.control.Label
        DownsamplingFactorzEditField    matlab.ui.control.NumericEditField
        DownsamplingFactorzEditFieldLabel  matlab.ui.control.Label
        DownsamplingFactorxyEditField   matlab.ui.control.NumericEditField
        DownsamplingFactorxyEditFieldLabel  matlab.ui.control.Label
        TrackingSelectionPanel_2        matlab.ui.container.Panel
        GridLayout49_2                  matlab.ui.container.GridLayout
        TrackspecificregionButton_2     matlab.ui.control.Button
        WorldlinestoincludeLabel        matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        StartingFrameLabel_2            matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        EndFrameLabel_2                 matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        AdvancedParameterPanel          matlab.ui.container.Panel
        NeuronMarkerGrid_2              matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        RegularizationLossCoefficientsTab  matlab.ui.container.Tab
        GridLayout35                    matlab.ui.container.GridLayout
        l_n                             matlab.ui.control.NumericEditField
        EditField4Label                 matlab.ui.control.Label
        DescriptionLabel                matlab.ui.control.Label
        ParameterLabel                  matlab.ui.control.Label
        GridSearchLabel                 matlab.ui.control.Label
        nn_max_grid                     matlab.ui.control.CheckBox
        l_n_mode_grid                   matlab.ui.control.CheckBox
        l_n_grid                        matlab.ui.control.CheckBox
        l_t_grid                        matlab.ui.control.CheckBox
        l_d_grid                        matlab.ui.control.CheckBox
        nn_max                          matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        nn_maxLabel                     matlab.ui.control.Label
        l_n_mode                        matlab.ui.control.DropDown
        MethodtouseforcalculatingNLabel  matlab.ui.control.Label
        NmodeLabel                      matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        l_t                             matlab.ui.control.NumericEditField
        EditField2Label                 matlab.ui.control.Label
        tLabel                          matlab.ui.control.Label
        l_d                             matlab.ui.control.NumericEditField
        EditField3Label                 matlab.ui.control.Label
        dLabel                          matlab.ui.control.Label
        GradientLearningRateTab         matlab.ui.container.Tab
        GridLayout32                    matlab.ui.container.GridLayout
        DescriptionLabel_2              matlab.ui.control.Label
        ParameterLabel_2                matlab.ui.control.Label
        GridSearchLabel_2               matlab.ui.control.Label
        z_comp_grid                     matlab.ui.control.CheckBox
        lr_ceil_grid                    matlab.ui.control.CheckBox
        lr_floor_grid                   matlab.ui.control.CheckBox
        lr_coeff_grid                   matlab.ui.control.CheckBox
        clip_grad_grid                  matlab.ui.control.CheckBox
        ZCompLabel                      matlab.ui.control.Label
        z_comp                          matlab.ui.control.NumericEditField
        Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel  matlab.ui.control.Label
        clip_grad                       matlab.ui.control.NumericEditField
        EditFieldLabel_4                matlab.ui.control.Label
        clip_gradLabel                  matlab.ui.control.Label
        lr_coeff                        matlab.ui.control.NumericEditField
        EditField6Label                 matlab.ui.control.Label
        LRCoeffLabel                    matlab.ui.control.Label
        lr_floor                        matlab.ui.control.NumericEditField
        Minimumvalueforinitiallearningratedefault002EditFieldLabel  matlab.ui.control.Label
        LRFloorLabel                    matlab.ui.control.Label
        lr_ceiling                      matlab.ui.control.NumericEditField
        EditFieldLabel_3                matlab.ui.control.Label
        lr_ceilingLabel                 matlab.ui.control.Label
        RetrievalFunctionTab            matlab.ui.container.Tab
        GridLayout50                    matlab.ui.container.GridLayout
        TextArea_6                      matlab.ui.control.TextArea
        OpenZephIRdocumentationButton   matlab.ui.control.Button
        CancelButton_2                  matlab.ui.control.Button
        SaveButton_2                    matlab.ui.control.Button
        OpenGridSearchMenuButton        matlab.ui.control.Button
        AdjustNeuronMarkerAlignmentPanel  matlab.ui.container.Panel
        NeuronMarkerGrid                matlab.ui.container.GridLayout
        Panel_44                        matlab.ui.container.Panel
        GridLayout48_2                  matlab.ui.container.GridLayout
        EnableinvolumeeditingCheckBox   matlab.ui.control.CheckBox
        EnableClickDragCheckBox         matlab.ui.control.CheckBox
        ShowallneuronsCheckBox_2        matlab.ui.control.CheckBox
        OverlayFrameMIPCheckBox_2       matlab.ui.control.CheckBox
        OverlayNeuroPALvolCheckBox_2    matlab.ui.control.CheckBox
        OverlaylastIDdframeCheckBox_2   matlab.ui.control.CheckBox
        AutojumponIDCheckBox_2          matlab.ui.control.CheckBox
        ResetMarkerPositionsButton      matlab.ui.control.Button
        Panel_43                        matlab.ui.container.Panel
        GridLayout53_2                  matlab.ui.container.GridLayout
        MarkerSizeSlider_2              matlab.ui.control.Slider
        MarkerSizeLabel_2               matlab.ui.control.Label
        SelectNeuronSubsetButton        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        ImportNeuronsfromCSVButton      matlab.ui.control.Button
        MoveNeuronMarkersPanel          matlab.ui.container.Panel
        GridLayout16_3                  matlab.ui.container.GridLayout
        Panel_86                        matlab.ui.container.Panel
        GridLayout77                    matlab.ui.container.GridLayout
        FlipinZdimensionButton          matlab.ui.control.Button
        FlipVerticallyButton            matlab.ui.control.Button
        FlipHorizontallyButton          matlab.ui.control.Button
        Panel_46                        matlab.ui.container.Panel
        GridLayout18_2                  matlab.ui.container.GridLayout
        degreesatatimeLabel             matlab.ui.control.Label
        RotateEditField                 matlab.ui.control.NumericEditField
        pixelsandLabel                  matlab.ui.control.Label
        MoveLabel                       matlab.ui.control.Label
        MoveEditField                   matlab.ui.control.NumericEditField
        Panel_45                        matlab.ui.container.Panel
        GridLayout27_2                  matlab.ui.container.GridLayout
        Left                            matlab.ui.control.Button
        Up                              matlab.ui.control.Button
        Right                           matlab.ui.control.Button
        Down                            matlab.ui.control.Button
        CCW                             matlab.ui.control.Button
        CW                              matlab.ui.control.Button
        CenterHorizontallyButton        matlab.ui.control.Button
        CenterVerticallyButton          matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        DeformNeuronMarkersPanel        matlab.ui.container.Panel
        GridLayout17_2                  matlab.ui.container.GridLayout
        ScaleEditField                  matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        WidthSpinner                    matlab.ui.control.Spinner
        WidthSpinnerLabel               matlab.ui.control.Label
        HeightSpinner                   matlab.ui.control.Spinner
        HeightSpinnerLabel              matlab.ui.control.Label
        HoverLabel                      matlab.ui.control.Label
        GridLayout59                    matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        NeuroPALIDTab                   matlab.ui.container.Tab
        IdGridLayout                    matlab.ui.container.GridLayout
        Panel_9                         matlab.ui.container.Panel
        IdMetadataGridLayout            matlab.ui.container.GridLayout
        Panel_26                        matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        SubjectNotesTextArea            matlab.ui.control.EditField
        NotesEditFieldLabel             matlab.ui.control.Label
        StrainEditField                 matlab.ui.control.EditField
        StrainEditFieldLabel            matlab.ui.control.Label
        AgeDropDown                     matlab.ui.control.DropDown
        AgeDropDownLabel                matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexLabel                        matlab.ui.control.Label
        BodyDropDown                    matlab.ui.control.DropDown
        BodyLabel                       matlab.ui.control.Label
        SaveLabel                       matlab.ui.control.Label
        SaveIDsButton                   matlab.ui.control.Button
        FileLabel                       matlab.ui.control.Label
        OpenButton                      matlab.ui.control.Button
        Panel_30                        matlab.ui.container.Panel
        GridLayout24_2                  matlab.ui.container.GridLayout
        ColorAtlasCheckBox              matlab.ui.control.CheckBox
        ColorAtlasCheckBoxLabel         matlab.ui.control.Label
        AutoNameCheckBox                matlab.ui.control.CheckBox
        AutoNameLabel                   matlab.ui.control.Label
        MouseClickDropDown              matlab.ui.control.DropDown
        ClicksLabel                     matlab.ui.control.Label
        UserIDButton                    matlab.ui.control.Button
        IDEditField                     matlab.ui.control.EditField
        UserLabel                       matlab.ui.control.Label
        AutoIDButton                    matlab.ui.control.Button
        AutoLabel                       matlab.ui.control.Label
        AutoIDAllButton                 matlab.ui.control.Button
        AutoDetectButton                matlab.ui.control.Button
        NeuronsLabel                    matlab.ui.control.Label
        AutoIDDropDown                  matlab.ui.control.DropDown
        XYPanel                         matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        XY                              matlab.ui.control.UIAxes
        Panel_8                         matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        Panel_16                        matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        ZSlider                         matlab.ui.control.Slider
        ZSliderLabel                    matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        ZCenterEditField                matlab.ui.control.NumericEditField
        CenterZLabel                    matlab.ui.control.Label
        ZRightLabel                     matlab.ui.control.Label
        FlipZButton                     matlab.ui.control.Button
        Panel_10                        matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        ZAxisDropDown                   matlab.ui.control.DropDown
        ZLeftLabel                      matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        TabGroup4                       matlab.ui.container.TabGroup
        MaximumIntensityProjectionTab   matlab.ui.container.Tab
        GridLayout76                    matlab.ui.container.GridLayout
        MaxProjection                   matlab.ui.control.UIAxes
        NeuronActivityTab               matlab.ui.container.Tab
        GridLayout75                    matlab.ui.container.GridLayout
        LoadNeuronActivityButton        matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        GanglionNeuronIDLabel           matlab.ui.control.Label
        GanglionTree                    matlab.ui.container.Tree
        PharynxNode                     matlab.ui.container.TreeNode
        AnteriorNode                    matlab.ui.container.TreeNode
        PosteriorNode                   matlab.ui.container.TreeNode
        HeadNode                        matlab.ui.container.TreeNode
        AnteriorLNode                   matlab.ui.container.TreeNode
        AnteriorRNode                   matlab.ui.container.TreeNode
        DorsalNode                      matlab.ui.container.TreeNode
        LateralLNode                    matlab.ui.container.TreeNode
        LateralRNode                    matlab.ui.container.TreeNode
        VentralNode                     matlab.ui.container.TreeNode
        RetroVesicularNode              matlab.ui.container.TreeNode
        MidbodyNode                     matlab.ui.container.TreeNode
        AnteriorNode_2                  matlab.ui.container.TreeNode
        CentralNode                     matlab.ui.container.TreeNode
        PosteriorNode_2                 matlab.ui.container.TreeNode
        VentralCordNode                 matlab.ui.container.TreeNode
        TailNode                        matlab.ui.container.TreeNode
        PreAnalNode                     matlab.ui.container.TreeNode
        DorsoRectalNode                 matlab.ui.container.TreeNode
        LumbarLNode                     matlab.ui.container.TreeNode
        LumbarRNode                     matlab.ui.container.TreeNode
        SidePanel                       matlab.ui.container.Panel
        SideGrid                        matlab.ui.container.GridLayout
        ChannelPanel                    matlab.ui.container.Panel
        ChannelGrid                     matlab.ui.container.GridLayout
        Panel_18                        matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        RDropDown                       matlab.ui.control.DropDown
        RCheckBox                       matlab.ui.control.CheckBox
        RLabel                          matlab.ui.control.Label
        Panel_21                        matlab.ui.container.Panel
        GridLayout26_3                  matlab.ui.container.GridLayout
        WLabel                          matlab.ui.control.Label
        WDropDown                       matlab.ui.control.DropDown
        WCheckBox                       matlab.ui.control.CheckBox
        Panel_20                        matlab.ui.container.Panel
        GridLayout26_2                  matlab.ui.container.GridLayout
        BDropDown                       matlab.ui.control.DropDown
        BCheckBox                       matlab.ui.control.CheckBox
        BLabel                          matlab.ui.control.Label
        Panel_24                        matlab.ui.container.Panel
        GridLayout26_6                  matlab.ui.container.GridLayout
        GFPDropDown                     matlab.ui.control.DropDown
        GFPCheckBox                     matlab.ui.control.CheckBox
        GFPLabel                        matlab.ui.control.Label
        Panel_19                        matlab.ui.container.Panel
        GridLayout26                    matlab.ui.container.GridLayout
        GDropDown                       matlab.ui.control.DropDown
        GCheckBox                       matlab.ui.control.CheckBox
        GLabel                          matlab.ui.control.Label
        Panel_23                        matlab.ui.container.Panel
        GridLayout26_5                  matlab.ui.container.GridLayout
        DICLabel                        matlab.ui.control.Label
        DICCheckBox                     matlab.ui.control.CheckBox
        DICDropDown                     matlab.ui.control.DropDown
        NeuronRankPanel                 matlab.ui.container.Panel
        NeuronRankGrid                  matlab.ui.container.GridLayout
        Panel_17                        matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        NextNeuronDropDown              matlab.ui.control.DropDown
        NextNeuronLabel                 matlab.ui.control.Label
        Panel_25                        matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        NeuronRankedConfidenceLabel     matlab.ui.control.Label
        UserNeuronIDsListBoxLabel       matlab.ui.control.Label
        UserNeuronIDsListBox            matlab.ui.control.ListBox
        NeuronListBoxPanel              matlab.ui.container.Panel
        NeuronListBoxGrid               matlab.ui.container.GridLayout
        UnIDdLabel                      matlab.ui.control.Label
        IDdNeuronsListBox               matlab.ui.control.ListBox
        IDdLabel                        matlab.ui.control.Label
        UnIDdNeuronsListBox             matlab.ui.control.ListBox
        VideoTrackingTab                matlab.ui.container.Tab
        VideoGridLayout                 matlab.ui.container.GridLayout
        DefaultTabGroup                 matlab.ui.container.TabGroup
        AnnotationSupportTab            matlab.ui.container.Tab
        GridLayout7_2                   matlab.ui.container.GridLayout
        ManipulateNeuronsButton         matlab.ui.control.Button
        Panel_40                        matlab.ui.container.Panel
        GridLayout53                    matlab.ui.container.GridLayout
        MarkerSizeSlider                matlab.ui.control.Slider
        MarkerSizeLabel                 matlab.ui.control.Label
        Panel_39                        matlab.ui.container.Panel
        GridLayout48                    matlab.ui.container.GridLayout
        OverlayNeuroPALvolCheckBox      matlab.ui.control.CheckBox
        ShowallneuronsCheckBox          matlab.ui.control.CheckBox
        OverlayFrameMIPCheckBox         matlab.ui.control.CheckBox
        OverlaylastIDdframeCheckBox     matlab.ui.control.CheckBox
        AutojumponIDCheckBox            matlab.ui.control.CheckBox
        AutosegmentFrameButton          matlab.ui.control.Button
        HotkeysPanel                    matlab.ui.container.Panel
        GridLayout47                    matlab.ui.container.GridLayout
        BwdFramesEditField              matlab.ui.control.NumericEditField
        FwdFramesEditField              matlab.ui.control.NumericEditField
        TextArea_5                      matlab.ui.control.TextArea
        InsertHKButton                  matlab.ui.control.StateButton
        DropDown_2                      matlab.ui.control.DropDown
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        JumpHKButton                    matlab.ui.control.StateButton
        DeleteHKButton                  matlab.ui.control.StateButton
        JmpFrameEditField_2             matlab.ui.control.NumericEditField
        Frame2HKButton                  matlab.ui.control.StateButton
        JmpFrameEditField               matlab.ui.control.NumericEditField
        Frame1HKButton                  matlab.ui.control.StateButton
        ColorControlPanel               matlab.ui.container.Panel
        GridLayout16_2                  matlab.ui.container.GridLayout
        BSlider                         matlab.ui.control.Slider
        BSliderLabel                    matlab.ui.control.Label
        GSlider                         matlab.ui.control.Slider
        GSliderLabel                    matlab.ui.control.Label
        RSlider                         matlab.ui.control.Slider
        RSliderLabel                    matlab.ui.control.Label
        BookmarkedFramesPanel           matlab.ui.container.Panel
        GridLayout8_2                   matlab.ui.container.GridLayout
        BookmarkListBox                 matlab.ui.control.ListBox
        DeleteBookmarkButton            matlab.ui.control.Button
        BookmarkNameEditField           matlab.ui.control.EditField
        SetBookmarkButton               matlab.ui.control.Button
        GotoBookmarkButton              matlab.ui.control.Button
        ParametersTab                   matlab.ui.container.Tab
        GridLayout58                    matlab.ui.container.GridLayout
        BasicParameterPanel             matlab.ui.container.Panel
        ParameterGridLayout             matlab.ui.container.GridLayout
        WormMotionPanel                 matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        MotionSlider                    matlab.ui.control.Slider
        HoveroverlabelsforfurtherdetailsorguidanceLabel  matlab.ui.control.Label
        AdvSetTab                       matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        GridLayout31                    matlab.ui.container.GridLayout
        TrackingSelectionPanel          matlab.ui.container.Panel
        GridLayout49                    matlab.ui.container.GridLayout
        TrackspecificneuronsButton      matlab.ui.control.Button
        TrackspecificregionButton       matlab.ui.control.Button
        StartingFrameLabel              matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EndFrameLabel                   matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        UseGPUSwitch                    matlab.ui.control.Switch
        UseGPUSwitchLabel               matlab.ui.control.Label
        MotionPredictionSwitch          matlab.ui.control.Switch
        MotionPredictionSwitchLabel     matlab.ui.control.Label
        AllowRotationsSwitch            matlab.ui.control.Switch
        AllowRotationsSwitchLabel       matlab.ui.control.Label
        ImageMotionTab                  matlab.ui.container.Tab
        GridLayout34                    matlab.ui.container.GridLayout
        dimmer_ratio                    matlab.ui.control.NumericEditField
        CoefficientfordimmingnonfoveatedregionsEditFieldLabel  matlab.ui.control.Label
        dimmer_ratioLabel               matlab.ui.control.Label
        grid_shape                      matlab.ui.control.NumericEditField
        SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel  matlab.ui.control.Label
        grid_shapeLabel                 matlab.ui.control.Label
        fovea_sigma                     matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        foveasigmaLabel                 matlab.ui.control.Label
        ComputationTab                  matlab.ui.container.Tab
        GridLayout31_2                  matlab.ui.container.GridLayout
        sort_mode                       matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        sort_modeLabel                  matlab.ui.control.Label
        n_chunks                        matlab.ui.control.NumericEditField
        NumberofstepstodividetheforwardpassintoEditFieldLabel  matlab.ui.control.Label
        n_chunksLabel                   matlab.ui.control.Label
        n_epoch_d                       matlab.ui.control.NumericEditField
        EditField2_2Label               matlab.ui.control.Label
        n_epoch                         matlab.ui.control.NumericEditField
        n_epoch_dLabel                  matlab.ui.control.Label
        NumberofiterationsforimageregistrationLREditFieldLabel  matlab.ui.control.Label
        n_epochLabel                    matlab.ui.control.Label
        ExportparametersButton          matlab.ui.control.Button
        EditadvancedparametersButton    matlab.ui.control.Button
        ImportparametersButton          matlab.ui.control.Button
        EnableadvancedparametersCheckBox  matlab.ui.control.CheckBox
        ActivityTab                     matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Panel_42                        matlab.ui.container.Panel
        GridLayout5_2                   matlab.ui.container.GridLayout
        ActivityAxes                    matlab.ui.control.UIAxes
        Panel_41                        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ActivityListBox                 matlab.ui.control.ListBox
        LoadStimulusFileButton          matlab.ui.control.Button
        SaveExitButton                  matlab.ui.control.Button
        ScanforIssuesButton             matlab.ui.control.Button
        CreditTab                       matlab.ui.container.Tab
        GridLayout14_3                  matlab.ui.container.GridLayout
        CitationTextArea                matlab.ui.control.TextArea
        CitationTextAreaLabel           matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        GithubRepositoryButton          matlab.ui.control.Button
        ViewPreprintButton              matlab.ui.control.Button
        LaboratoryPageButton            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Panel_55                        matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        vert_zSlider                    matlab.ui.control.Slider
        ySlider                         matlab.ui.control.Slider
        hor_zSlider                     matlab.ui.control.Slider
        xSlider                         matlab.ui.control.Slider
        yzAxes                          matlab.ui.control.UIAxes
        xzAxes                          matlab.ui.control.UIAxes
        xyAxes                          matlab.ui.control.UIAxes
        Panel_34                        matlab.ui.container.Panel
        GridLayout40                    matlab.ui.container.GridLayout
        Panel_36                        matlab.ui.container.Panel
        GridLayout42                    matlab.ui.container.GridLayout
        Panel_38                        matlab.ui.container.Panel
        GridLayout44                    matlab.ui.container.GridLayout
        tSlider                         matlab.ui.control.Slider
        Panel_37                        matlab.ui.container.Panel
        GridLayout43                    matlab.ui.container.GridLayout
        tEditField                      matlab.ui.control.NumericEditField
        tEditFieldLabel                 matlab.ui.control.Label
        Panel_35                        matlab.ui.container.Panel
        GridLayout41                    matlab.ui.container.GridLayout
        zEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        WorkflowPanel                   matlab.ui.container.Panel
        GridLayout13_2                  matlab.ui.container.GridLayout
        AllowflexibleaspectratioCheckBox  matlab.ui.control.CheckBox
        ImportexistingtracksButton      matlab.ui.control.Button
        InsertlastIDdFrameButton        matlab.ui.control.Button
        Panel_33                        matlab.ui.container.Panel
        GridLayout14_2                  matlab.ui.container.GridLayout
        ExtractActivityButton           matlab.ui.control.Button
        TrackNeuronsButton              matlab.ui.control.Button
        RecommendFramesButton           matlab.ui.control.Button
        SaveAnnotationsButton           matlab.ui.control.Button
        InsertNeuroPALNeuronsButton     matlab.ui.control.Button
        WorldlineInformationPanel       matlab.ui.container.Panel
        GridLayout51                    matlab.ui.container.GridLayout
        WorldlineTree                   matlab.ui.container.CheckBoxTree
        IDdNode                         matlab.ui.container.TreeNode
        UnIDdNode                       matlab.ui.container.TreeNode
        Panel_32                        matlab.ui.container.Panel
        GridLayout52                    matlab.ui.container.GridLayout
        SelectworldlinebyIDButton       matlab.ui.control.Button
        AssignrandomcolorstoblanksButton  matlab.ui.control.Button
        NeuronInformationPanel          matlab.ui.container.Panel
        GridLayout9_2                   matlab.ui.container.GridLayout
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        ProvenanceEditField             matlab.ui.control.EditField
        ProvenanceEditFieldLabel        matlab.ui.control.Label
        WorldlineIDEditField            matlab.ui.control.NumericEditField
        WorldlineIDEditFieldLabel       matlab.ui.control.Label
        ColorButton                     matlab.ui.control.Button
        ColorLabel                      matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        ZCoordinateEditField            matlab.ui.control.NumericEditField
        ZCoordinateEditFieldLabel       matlab.ui.control.Label
        YCoordinateEditField            matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel       matlab.ui.control.Label
        XCoordinateEditField            matlab.ui.control.NumericEditField
        XCoordinateEditFieldLabel       matlab.ui.control.Label
        ImageProcessingTab              matlab.ui.container.Tab
        ProcessingGridLayout            matlab.ui.container.GridLayout
        ProcAxPanel                     matlab.ui.container.Panel
        ProcAxGrid                      matlab.ui.container.GridLayout
        proc_xSlider                    matlab.ui.control.Slider
        proc_zSlider                    matlab.ui.control.Slider
        proc_vert_zSlider               matlab.ui.control.Slider
        proc_ySlider                    matlab.ui.control.Slider
        proc_hor_zSlider                matlab.ui.control.Slider
        proc_yzAxes                     matlab.ui.control.UIAxes
        proc_xyAxes                     matlab.ui.control.UIAxes
        proc_xzAxes                     matlab.ui.control.UIAxes
        ProcSidePanel                   matlab.ui.container.Panel
        ProcSideGrid                    matlab.ui.container.GridLayout
        ImageManipulationPanel          matlab.ui.container.Panel
        GridLayout83                    matlab.ui.container.GridLayout
        proc_crop_panel                 matlab.ui.container.Panel
        GridLayout82_2                  matlab.ui.container.GridLayout
        CancelButton_3                  matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        proc_rot_panel                  matlab.ui.container.Panel
        ProcGridOBSOLETE                matlab.ui.container.GridLayout
        proc_rot_knob                   matlab.ui.control.Knob
        proc_rot_spinner                matlab.ui.control.Spinner
        flip_lr                         matlab.ui.control.StateButton
        confirm_rotation                matlab.ui.control.Button
        flip_ud                         matlab.ui.control.StateButton
        proc_ds_panel                   matlab.ui.container.Panel
        ProcDownsamplingGrid            matlab.ui.container.GridLayout
        ProcPreviewZslowCheckBox        matlab.ui.control.CheckBox
        EndFrameEditField               matlab.ui.control.NumericEditField
        EndFrameEditFieldLabel          matlab.ui.control.Label
        StartFrameEditField             matlab.ui.control.NumericEditField
        StartFrameEditFieldLabel        matlab.ui.control.Label
        Panel_91                        matlab.ui.container.Panel
        GridLayout81                    matlab.ui.container.GridLayout
        ProcTStopEditField              matlab.ui.control.NumericEditField
        ProcTStartEditField             matlab.ui.control.NumericEditField
        TrimButton                      matlab.ui.control.Button
        ProcXYFactorUpdateButton        matlab.ui.control.Button
        ProcZSlicesEditField            matlab.ui.control.NumericEditField
        zSlicesEditFieldLabel           matlab.ui.control.Label
        ProcXYFactorEditField           matlab.ui.control.NumericEditField
        xyFactorLabel                   matlab.ui.control.Label
        DownsampleButton                matlab.ui.control.Button
        RotateButton                    matlab.ui.control.Button
        ProcCropImageButton             matlab.ui.control.Button
        VolumeChannelsPanel             matlab.ui.container.Panel
        GridLayout82                    matlab.ui.container.GridLayout
        EditChannelsButton              matlab.ui.control.StateButton
        proc_channel_panel              matlab.ui.container.Panel
        proc_channel_grid               matlab.ui.container.GridLayout
        proc_c1_editfield               matlab.ui.control.EditField
        proc_c2_editfield               matlab.ui.control.EditField
        proc_c3_editfield               matlab.ui.control.EditField
        proc_c4_editfield               matlab.ui.control.EditField
        proc_c5_editfield               matlab.ui.control.EditField
        proc_c6_editfield               matlab.ui.control.EditField
        proc_c1_checkbox                matlab.ui.control.CheckBox
        proc_c2_checkbox                matlab.ui.control.CheckBox
        proc_c3_checkbox                matlab.ui.control.CheckBox
        proc_c4_checkbox                matlab.ui.control.CheckBox
        proc_c5_checkbox                matlab.ui.control.CheckBox
        proc_c6_checkbox                matlab.ui.control.CheckBox
        proc_c1_ref                     matlab.ui.control.StateButton
        proc_c2_ref                     matlab.ui.control.StateButton
        proc_c3_ref                     matlab.ui.control.StateButton
        proc_c4_ref                     matlab.ui.control.DropDown
        proc_c5_ref                     matlab.ui.control.DropDown
        proc_c6_ref                     matlab.ui.control.DropDown
        proc_c1_dropdown                matlab.ui.control.DropDown
        proc_c2_dropdown                matlab.ui.control.DropDown
        proc_c3_dropdown                matlab.ui.control.DropDown
        proc_c4_dropdown                matlab.ui.control.DropDown
        proc_c5_dropdown                matlab.ui.control.DropDown
        proc_c6_dropdown                matlab.ui.control.DropDown
        proc_c1_up                      matlab.ui.control.Button
        proc_c2_up                      matlab.ui.control.Button
        proc_c3_up                      matlab.ui.control.Button
        proc_c4_up                      matlab.ui.control.Button
        proc_c5_up                      matlab.ui.control.Button
        proc_c6_up                      matlab.ui.control.Button
        proc_c1_down                    matlab.ui.control.Button
        proc_c2_down                    matlab.ui.control.Button
        proc_c3_down                    matlab.ui.control.Button
        proc_c4_down                    matlab.ui.control.Button
        proc_c5_down                    matlab.ui.control.Button
        proc_c6_down                    matlab.ui.control.Button
        proc_c1_delete                  matlab.ui.control.Button
        proc_c2_delete                  matlab.ui.control.Button
        proc_c3_delete                  matlab.ui.control.Button
        proc_c4_delete                  matlab.ui.control.Button
        proc_c5_delete                  matlab.ui.control.Button
        proc_c6_delete                  matlab.ui.control.Button
        ProcAdvancedOptionsButton       matlab.ui.control.Button
        SpectralUnmixingPanel           matlab.ui.container.Panel
        SpectralUnmixingGrid            matlab.ui.container.GridLayout
        background_measure              matlab.ui.control.Button
        background_b                    matlab.ui.control.NumericEditField
        background_g                    matlab.ui.control.NumericEditField
        background_r                    matlab.ui.control.NumericEditField
        BGLabel                         matlab.ui.control.Label
        Panel_89                        matlab.ui.container.Panel
        GridLayout2_3                   matlab.ui.container.GridLayout
        DropperradiusSpinner            matlab.ui.control.NumericEditField
        DropperRadiusEditFieldLabel     matlab.ui.control.Label
        SigmagaussEditField             matlab.ui.control.NumericEditField
        SigmagaussEditFieldLabel        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        blue_b                          matlab.ui.control.NumericEditField
        blue_g                          matlab.ui.control.NumericEditField
        blue_r                          matlab.ui.control.NumericEditField
        green_r                         matlab.ui.control.NumericEditField
        green_g                         matlab.ui.control.NumericEditField
        green_b                         matlab.ui.control.NumericEditField
        red_b                           matlab.ui.control.NumericEditField
        red_g                           matlab.ui.control.NumericEditField
        red_r                           matlab.ui.control.NumericEditField
        blue_measure                    matlab.ui.control.Button
        green_measure                   matlab.ui.control.Button
        red_measure                     matlab.ui.control.Button
        BLabel_2                        matlab.ui.control.Label
        GLabel_2                        matlab.ui.control.Label
        RLabel_2                        matlab.ui.control.Label
        BlueLabel                       matlab.ui.control.Label
        GreenLabel                      matlab.ui.control.Label
        RedLabel                        matlab.ui.control.Label
        ProcSavePanel                   matlab.ui.container.Panel
        ProcSaveGrid                    matlab.ui.container.GridLayout
        ProcResetButton                 matlab.ui.control.Button
        ProcSaveButton                  matlab.ui.control.Button
        ProcToggleVolumePanel           matlab.ui.container.Panel
        ProcToggleVolumeGrid            matlab.ui.container.GridLayout
        VolumeDropDown                  matlab.ui.control.DropDown
        ProcShowMIPCheckBox             matlab.ui.control.CheckBox
        ProcMainPanel                   matlab.ui.container.Panel
        ProcMainGrid                    matlab.ui.container.GridLayout
        Panel_57                        matlab.ui.container.Panel
        GridLayout64                    matlab.ui.container.GridLayout
        ProcHistogramPanel              matlab.ui.container.Panel
        ProcHistogramGrid               matlab.ui.container.GridLayout
        tl_hist_panel                   matlab.ui.container.Panel
        tl_hist_grid                    matlab.ui.container.GridLayout
        Panel_80                        matlab.ui.container.Panel
        GridLayout72_7                  matlab.ui.container.GridLayout
        tl_Label                        matlab.ui.control.Label
        tl_GammaEditField               matlab.ui.control.NumericEditField
        GammaEditFieldLabel             matlab.ui.control.Label
        tl_hist_slider                  matlab.ui.control.RangeSlider
        tl_hist_ax                      matlab.ui.control.UIAxes
        tm_hist_panel                   matlab.ui.container.Panel
        tm_hist_grid                    matlab.ui.container.GridLayout
        Panel_81                        matlab.ui.container.Panel
        GridLayout72_8                  matlab.ui.container.GridLayout
        tm_Label                        matlab.ui.control.Label
        tm_GammaEditField               matlab.ui.control.NumericEditField
        GammaEditField_2Label           matlab.ui.control.Label
        tm_hist_slider                  matlab.ui.control.RangeSlider
        tm_hist_ax                      matlab.ui.control.UIAxes
        tr_hist_panel                   matlab.ui.container.Panel
        tr_hist_grid                    matlab.ui.container.GridLayout
        Panel_82                        matlab.ui.container.Panel
        GridLayout72_9                  matlab.ui.container.GridLayout
        tr_Label                        matlab.ui.control.Label
        tr_GammaEditField               matlab.ui.control.NumericEditField
        GammaEditField_3Label           matlab.ui.control.Label
        tr_hist_slider                  matlab.ui.control.RangeSlider
        tr_hist_ax                      matlab.ui.control.UIAxes
        bl_hist_panel                   matlab.ui.container.Panel
        bl_hist_grid                    matlab.ui.container.GridLayout
        Panel_83                        matlab.ui.container.Panel
        GridLayout72_10                 matlab.ui.container.GridLayout
        bl_Label                        matlab.ui.control.Label
        bl_GammaEditField               matlab.ui.control.NumericEditField
        GammaEditField_4Label           matlab.ui.control.Label
        bl_hist_slider                  matlab.ui.control.RangeSlider
        bl_hist_ax                      matlab.ui.control.UIAxes
        bm_hist_panel                   matlab.ui.container.Panel
        bm_hist_grid                    matlab.ui.container.GridLayout
        Panel_84                        matlab.ui.container.Panel
        GridLayout72_11                 matlab.ui.container.GridLayout
        bm_Label                        matlab.ui.control.Label
        bm_GammaEditField               matlab.ui.control.NumericEditField
        GammaEditField_5Label           matlab.ui.control.Label
        bm_hist_slider                  matlab.ui.control.RangeSlider
        bm_hist_ax                      matlab.ui.control.UIAxes
        br_hist_panel                   matlab.ui.container.Panel
        br_hist_grid                    matlab.ui.container.GridLayout
        Panel_85                        matlab.ui.container.Panel
        GridLayout72_12                 matlab.ui.container.GridLayout
        br_Label                        matlab.ui.control.Label
        br_GammaEditField               matlab.ui.control.NumericEditField
        GammaEditField_6Label           matlab.ui.control.Label
        br_hist_slider                  matlab.ui.control.RangeSlider
        br_hist_ax                      matlab.ui.control.UIAxes
        ProcThresholdPanel              matlab.ui.container.Panel
        ProcThresholdGrid               matlab.ui.container.GridLayout
        ProcMeasure90pthNoiseButton     matlab.ui.control.Button
        ProcMeasureROINoiseButton       matlab.ui.control.Button
        ProcThresholdKnobPanel          matlab.ui.container.Panel
        ProcThresholdKnobGrid           matlab.ui.container.GridLayout
        ProcNoiseThresholdField         matlab.ui.control.NumericEditField
        ProcNoiseThresholdKnob          matlab.ui.control.Knob
        ProcThresholdManipulationLabel  matlab.ui.control.Label
        ProcHistogramManipulationLabel  matlab.ui.control.Label
        ProcNormalizeColorsButton       matlab.ui.control.Button
        ProcHistogramMatchingButton     matlab.ui.control.Button
        Panel_87                        matlab.ui.container.Panel
        GridLayout78                    matlab.ui.container.GridLayout
        HidezerointensitypixelsCheckBox  matlab.ui.control.CheckBox
        LogTab                          matlab.ui.container.Tab
        LogGrid                         matlab.ui.container.GridLayout
        Log                             matlab.ui.control.TextArea
        UpperLogPanel                   matlab.ui.container.Panel
        UpperLogGrid                    matlab.ui.container.GridLayout
        SystemInfoGrid                  matlab.ui.container.GridLayout
        LoadedFilesPanel                matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        FileListTree                    matlab.ui.container.CheckBoxTree
        DataNode                        matlab.ui.container.TreeNode
        DataMetadataNode                matlab.ui.container.TreeNode
        VolumeNode                      matlab.ui.container.TreeNode
        NeuronsNode                     matlab.ui.container.TreeNode
        ProcessingNode                  matlab.ui.container.TreeNode
        DownsamplingNode                matlab.ui.container.TreeNode
        SegmentationNode                matlab.ui.container.TreeNode
        ModelNode                       matlab.ui.container.TreeNode
        NeuronInfoNode                  matlab.ui.container.TreeNode
        ActivityNode                    matlab.ui.container.TreeNode
        ZephIRNode                      matlab.ui.container.TreeNode
        VideoNode                       matlab.ui.container.TreeNode
        AnnotationsNode                 matlab.ui.container.TreeNode
        ZephIRMetaDataNode              matlab.ui.container.TreeNode
        ActivityCSVNode                 matlab.ui.container.TreeNode
        OutputNode                      matlab.ui.container.TreeNode
        NeuroPALArrayNode               matlab.ui.container.TreeNode
        NeuroPALNeuronIDsNode           matlab.ui.container.TreeNode
        NeuroPALEditField_2Label        matlab.ui.control.Label
        NeuroPALEditField               matlab.ui.control.EditField
        PythonExecutableEditFieldLabel  matlab.ui.control.Label
        PythonExecutableEditField       matlab.ui.control.EditField
        MATLABRuntimeEditFieldLabel     matlab.ui.control.Label
        MATLABRuntimeEditField          matlab.ui.control.EditField
        OperatingSystemEditField        matlab.ui.control.EditField
        OperatingSystemEditField_2Label  matlab.ui.control.Label
        LogControlPanel                 matlab.ui.container.GridLayout
        DebugPanel                      matlab.ui.container.Panel
        DebugGrid                       matlab.ui.container.GridLayout
        TestResultPanel                 matlab.ui.container.Panel
        TestResultGrid                  matlab.ui.container.GridLayout
        SaveRoutineCheck                matlab.ui.control.CheckBox
        SaveRoutineLabel                matlab.ui.control.Label
        SystemCallsCheck                matlab.ui.control.CheckBox
        SystemcallsLabel                matlab.ui.control.Label
        TypeProofingCheck               matlab.ui.control.CheckBox
        FlexibleImportsCheck            matlab.ui.control.CheckBox
        RobustDataHandlingLabel         matlab.ui.control.Label
        TypeproofingLabel               matlab.ui.control.Label
        FlexibleimportsLabel            matlab.ui.control.Label
        AxialProjectionsCheck           matlab.ui.control.CheckBox
        DynamicGridStructuresCheck      matlab.ui.control.CheckBox
        DynamicPanelingCheck            matlab.ui.control.CheckBox
        AxialprojectionsLabel           matlab.ui.control.Label
        DynamicgridstructuresLabel      matlab.ui.control.Label
        DynamicpanelingLabel            matlab.ui.control.Label
        GUIFunctionalityLabel           matlab.ui.control.Label
        AdvancedFunctionalityLabel      matlab.ui.control.Label
        InterdependentFunctionalityLabel  matlab.ui.control.Label
        BasicFunctionalityLabel         matlab.ui.control.Label
        EditVideoCheck                  matlab.ui.control.CheckBox
        BrowseVideoCheck                matlab.ui.control.CheckBox
        LoadVideoCheck                  matlab.ui.control.CheckBox
        EditImageCheck                  matlab.ui.control.CheckBox
        BrowseImageCheck                matlab.ui.control.CheckBox
        LoadImageCheck                  matlab.ui.control.CheckBox
        ExtractActivityLabel            matlab.ui.control.Label
        TrackNeuronsLabel               matlab.ui.control.Label
        ThumbnailAnalysisLabel          matlab.ui.control.Label
        EditvideoLabel_3                matlab.ui.control.Label
        BrowsevideoLabel_3              matlab.ui.control.Label
        HistogramMatchingLabel          matlab.ui.control.Label
        AutoidentificationLabel_2       matlab.ui.control.Label
        LoadvideoLabel_3                matlab.ui.control.Label
        SpectralUnmixingLabel           matlab.ui.control.Label
        ArtifactRemovalLabel            matlab.ui.control.Label
        EditimageLabel_2                matlab.ui.control.Label
        BrowseimageLabel                matlab.ui.control.Label
        LoadimageLabel_2                matlab.ui.control.Label
        TrackNeuronsCheck               matlab.ui.control.CheckBox
        ThumbnailAnalysisCheck          matlab.ui.control.CheckBox
        ExtractActivityCheck            matlab.ui.control.CheckBox
        AutoIdentificationCheck         matlab.ui.control.CheckBox
        HistogramMatchingCheck          matlab.ui.control.CheckBox
        SpectralUnmixingCheck           matlab.ui.control.CheckBox
        ArtifactRemovalCheck            matlab.ui.control.CheckBox
        AutosegmentationLabel_2         matlab.ui.control.Label
        AutoSegmentationCheck           matlab.ui.control.CheckBox
        PrintdebuglogButton             matlab.ui.control.Button
        RuntestroutineButton            matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        EnabledebugmenuCheckBox         matlab.ui.control.CheckBox
        EnablelognotificationsCheckBox  matlab.ui.control.CheckBox
        EnablelogCheckBox               matlab.ui.control.CheckBox
        OpenlogfileButton               matlab.ui.control.Button
        LogFilterButtonGroup            matlab.ui.container.ButtonGroup
        PythoneventsonlyButton          matlab.ui.control.RadioButton
        MATLABeventsonlyButton          matlab.ui.control.RadioButton
        SystemeventsonlyButton          matlab.ui.control.RadioButton
        ShowalleventsButton             matlab.ui.control.RadioButton
        SubmitbugreportButton           matlab.ui.control.Button
        OpenRepositoryButton            matlab.ui.control.Button
        VolTraceParent                  matlab.ui.container.Panel
        VolTraceHelperGrid              matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        DropDown                        matlab.ui.control.DropDown
        LoadStimuliButton               matlab.ui.control.Button
        AddPlotButton                   matlab.ui.control.Button
        DeletePlotDropDown              matlab.ui.control.DropDown
        DeleteNeuronDropDown            matlab.ui.control.DropDown
    end

    properties (Access = public)                                                                                                                                                                                                

        %% Program info.                                                                                                                                                                                                        

        % The log of user activity for code evaluation/optimization.                                                                                                                                                            
        log                                                                                                                                                                                                                     

        % Version info.                                                                                                                                                                                                         
        version = Program.ProgramInfo.version;                                                                                                                                                                                  
        mouse = struct( ...                                                                                                                                                                                                     
            'state', {0}, ...                                                                                                                                                                                                   
            'pos', {[0 0]}, ...                                                                                                                                                                                                 
            'drag', {[]});                                                                                                                                                                                                      

        % Are we using the parallelization toolbox?                                                                                                                                                                             
        is_parallel = true;                                                                                                                                                                                                     
        parallel_pool;                                                                                                                                                                                                          

        data_flags = struct( ...                                                                                                                                                                                                
            'NeuroPAL_Volume', 0, ...                                                                                                                                                                                           
            'Video_Volume', 0, ...                                                                                                                                                                                              
            'Tracking_ROIs', 0, ...                                                                                                                                                                                             
            'Neurons', 0, ...                                                                                                                                                                                                   
            'Neuronal_Identities', 0, ...                                                                                                                                                                                       
            'Neuronal_Activity', 0, ...                                                                                                                                                                                         
            'Stimulus_Files', 0);                                                                                                                                                                                               


        %% Image info.                                                                                                                                                                                                          

        % The default parameters for the program & matching pursuit.                                                                                                                                                            
        params                                                                                                                                                                                                                  
        mp_params                                                                                                                                                                                                               

        % File locations for the open image.                                                                                                                                                                                    
        image_file = [];                                                                                                                                                                                                        
        id_file = [];                                                                                                                                                                                                           

        % The image preferences for loading/saving.                                                                                                                                                                             
        image_prefs = [];                                                                                                                                                                                                       

        % The image info                                                                                                                                                                                                        
        image_info = [];                                                                                                                                                                                                        

        % Worm information.                                                                                                                                                                                                     
        worm = [];                                                                                                                                                                                                              
        neuron_info = Neurons.Hermaphrodite;                                                                                                                                                                                    

        % The open image of the worm.                                                                                                                                                                                           
        % A pixel matrix of (x,y,z, color channel).                                                                                                                                                                             
        image_name                                                                                                                                                                                                              
        image_data                                                                                                                                                                                                              
        image_data_zscored = [];                                                                                                                                                                                                
        image_view % a subset of the image that shows only what we're viewing, a matrix of (x,y,z, subset of color channels)                                                                                                    
        image_um_scale = [1,1,1]; % image micron scale (x,y,z)                                                                                                                                                                  
        image_gamma = ones(6, 1); % the gamma adjustment for the image                                                                                                                                                          
        gamma_RGBW_DIC_GFP_index = 1:6; % the indices for gamma values                                                                                                                                                          

        % Reconstructed image from gaussian approximations of neuron positions, shapes, & colors.                                                                                                                               
        % A reconstructed pixel matrix of (x,y,z, color channel).                                                                                                                                                               
        recon                                                                                                                                                                                                                   
        recon_view  % a subset of the reconstructed image that shows only what we're viewing, a matrix of (x,y,z, subset of color channels)                                                                                     

        % The neurons in the image.                                                                                                                                                                                             
        image_neurons                                                                                                                                                                                                           

        % Which neuron is selected.                                                                                                                                                                                             
        selected_neuron                                                                                                                                                                                                         

        % Mouse click states (a hack to detect double clicks).                                                                                                                                                                  
        % Note: initialization is performed by startupFcn due construction issues.                                                                                                                                              
        mouse_clicked;                                                                                                                                                                                                          

        % Zoom percent for the keyboard shortcut.                                                                                                                                                                               
        zoom_percent = 0.2;                                                                                                                                                                                                     

        % Toggle image panning.                                                                                                                                                                                                 
        is_panning = false;                                                                                                                                                                                                     

        % Mouse position correction (the cursor is offset incorrectly).                                                                                                                                                         
        mouse_offset = [-1,1];                                                                                                                                                                                                  


        %% Neuron info.                                                                                                                                                                                                         

        % Neuron marker GUI attributes.                                                                                                                                                                                         
        % Note: initialization is performed by startupFcn due construction issues.                                                                                                                                              
        neuron_marker;                                                                                                                                                                                                          

        % Hermaphrodite and male neuron info.                                                                                                                                                                                   
        hermaphrodite_neurons;                                                                                                                                                                                                  
        male_neurons;                                                                                                                                                                                                           

        % Toggle neuron labels.                                                                                                                                                                                                 
        show_labels = false;                                                                                                                                                                                                    
        label_color = [1,1,0];                                                                                                                                                                                                  
        label_fontsize = 12;                                                                                                                                                                                                    
        label_fontweight = 'bold';                                                                                                                                                                                              

        % Are we opening a file?                                                                                                                                                                                                
        is_opening_file = false;                                                                                                                                                                                                

        % Are we saving a user ID?                                                                                                                                                                                              
        is_saving_ID = false;                                                                                                                                                                                                   

        neuron_activity;                                                                                                                                                                                                        
        neuron_activity_by_name;                                                                                                                                                                                                
        activity_var;                                                                                                                                                                                                           
        movie_var;                                                                                                                                                                                                              
        framerate;                                                                                                                                                                                                              
        activity_table;                                                                                                                                                                                                         
        VolTrace;                                                                                                                                                                                                               
        nwb_data;                                                                                                                                                                                                               
        stimuli;                                                                                                                                                                                                                

        color_tracker;                                                                                                                                                                                                          
        style_tracker;                                                                                                                                                                                                          
        activity_module;                                                                                                                                                                                                        

        %% Processing Properties                                                                                                                                                                                                

        proc_image;                                                                                                                                                                                                             

        % Properties related to GUI functionality                                                                                                                                                                               
        trigger_reload = 0;                                                                                                                                                                                                     
        flags = struct();                                                                                                                                                                                                       
        rotation_stack = struct( ...                                                                                                                                                                                            
            'roi', {[]}, ...                                                                                                                                                                                                    
            'gui', {{}}, ...                                                                                                                                                                                                    
            'listeners', {{}}, ...                                                                                                                                                                                              
            'cache', {0});                                                                                                                                                                                                      

        % Back up of original image_view and image_data                                                                                                                                                                         
        repo_view;                                                                                                                                                                                                              
        repo_data;                                                                                                                                                                                                              

        % ROI used for cropping previews                                                                                                                                                                                        
        volume_crop_roi;                                                                                                                                                                                                        
        video_crop_roi;                                                                                                                                                                                                         

        % Vertical & Horizontal lines drawn on volume                                                                                                                                                                           
        proc_xy_yline;      % (Horizontal) line on (XY) axes                                                                                                                                                                    
        proc_yz_yline;      % Horizontal line on YZ axes                                                                                                                                                                        
        proc_xz_yline;      % Horizontal line on XZ axes                                                                                                                                                                        

        proc_xy_xline;      % Vertical line on XY axes                                                                                                                                                                          
        proc_yz_xline;      % Vertical line on YZ axes                                                                                                                                                                          
        proc_xz_xline;      % Vertical line on XZ axes                                                                                                                                                                          

        % Vertical lines drawn on histogram plots                                                                                                                                                                               
        proc_tl_hist_min;   % Vertical line on (t)op (l)eft (hist)ogram at (min) location                                                                                                                                       
        proc_tl_hist_max;   % Vertical line on top left histogram at max location                                                                                                                                               

        proc_tm_hist_min;   % Vertical line on top middle histogram at min location                                                                                                                                             
        proc_tm_hist_max;   % Vertical line on top middle histogram at max location                                                                                                                                             
                                                                                                                                                                                                                                
        proc_tr_hist_min;   % Vertical line on top right histogram at min location                                                                                                                                              
        proc_tr_hist_max;   % Vertical line on top right histogram at max location                                                                                                                                              

        proc_bl_hist_min;   % Vertical line on bottom left histogram at min location                                                                                                                                            
        proc_bl_hist_max;   % Vertical line on bottom left histogram at max location                                                                                                                                            

        proc_bm_hist_min;   % Vertical line on bottom middle histogram at min location                                                                                                                                          
        proc_bm_hist_max;   % Vertical line on bottom middle histogram at max location                                                                                                                                          
                                                                                                                                                                                                                                
        proc_br_hist_min;   % Vertical line on bottom right histogram at min location                                                                                                                                           
        proc_br_hist_max;   % Vertical line on bottom right histogram at max location                                                                                                                                           

        % Character maps                                                                                                                                                                                                        
        nameMap;            % (Maps) channel dropdown values to color (names)                                                                                                                                                   
        shortMap;           % Maps channel dropdown values to color shorthand                                                                                                                                                   


        %% Tracking Properties                                                                                                                                                                                                  

        % Video properties                                                                                                                                                                                                      
        video_path    % Path to input video                                                                                                                                                                                     
        video_array   % 4D video array                                                                                                                                                                                          
        video_info    % Structure containing video metadata.                                                                                                                                                                    
        video_neurons % 4D neuron array                                                                                                                                                                                         
        id_frames     % Keeps track of IDd frames                                                                                                                                                                               
        activity      % Struct containing per-neuron activity                                                                                                                                                                   

        % File handler properties                                                                                                                                                                                               
        nwb_file       % Read nwb file.                                                                                                                                                                                         
        nwb_mod        % NWB module corresponding to calcium data.                                                                                                                                                              
        nd2_file       % Read nd2 file.                                                                                                                                                                                         
        nd2_mod        % ND2 module corresponding to video data.                                                                                                                                                                
        tif_file       % Read tif file.                                                                                                                                                                                         
        tif_mod        % Tif module corresponding to video data.                                                                                                                                                                
        spectral_cache = struct( ...                                                                                                                                                                                            
            'ch_db', {[]}, ...                                                                                                                                                                                                  
            'ch_px', {{}}, ...                                                                                                                                                                                                  
            'ch_val', {[]}, ...                                                                                                                                                                                                 
            'bg_px', {[]}, ...                                                                                                                                                                                                  
            'bg_val', {[]}, ...                                                                                                                                                                                                 
            'blurred_img', {[]}); % Tracks spectral unmixing parameters.                                                                                                                                                        

        % Neuron Manipulation properties                                                                                                                                                                                        
        repo_neurons  % Backup 4D neuron array                                                                                                                                                                                  
        sel_neurons   % Keeps track of neurons being manipulated                                                                                                                                                                

        % Volume properties                                                                                                                                                                                                     
        parent_app    % Handle for main GUI                                                                                                                                                                                     

        % ZephIR properties                                                                                                                                                                                                     
        annotations   % 4D array containing all annotations.                                                                                                                                                                    
        worldlines    % Struct containing all worldlines.                                                                                                                                                                       
        arguments     % Struct containing ZephIR arguments.                                                                                                                                                                     

        % GUI properties                                                                                                                                                                                                        
        ctrl_state    % Tracks whether control key is down.                                                                                                                                                                     

        xy_xline      % Variable serving as GUI handle.                                                                                                                                                                         
        xz_xline      % Variable serving as GUI handle.                                                                                                                                                                         
        yz_xline      % Variable serving as GUI handle.                                                                                                                                                                         

        xy_yline      % Variable serving as GUI handle.                                                                                                                                                                         
        xz_yline      % Variable serving as GUI handle.                                                                                                                                                                         
        yz_yline      % Variable serving as GUI handle.                                                                                                                                                                         

        script_dir    % Local working directory, changes based on deployment & OS.                                                                                                                                              
        script_ext    % Deployment- & OS-specific extension for scripts/executables.                                                                                                                                            

        %% GUI Fillers                                                                                                                                                                                                          
        proc_c7_checkbox                                                                                                                                                                                                        
        proc_c7_ref                                                                                                                                                                                                             
        proc_c7_dropdown                                                                                                                                                                                                        
        proc_c7_editfield                                                                                                                                                                                                       
                                                                                                                                                                                                                                
        proc_c8_checkbox                                                                                                                                                                                                        
        proc_c8_ref                                                                                                                                                                                                             
        proc_c8_dropdown                                                                                                                                                                                                        
        proc_c8_editfield                                                                                                                                                                                                       
                                                                                                                                                                                                                                
        proc_c9_checkbox                                                                                                                                                                                                        
        proc_c9_ref                                                                                                                                                                                                             
        proc_c9_dropdown                                                                                                                                                                                                        
        proc_c9_editfield                                                                                                                                                                                                       
                                                                                                                                                                                                                                
        proc_c10_checkbox                                                                                                                                                                                                       
        proc_c10_ref                                                                                                                                                                                                            
        proc_c10_dropdown                                                                                                                                                                                                       
        proc_c10_editfield                                                                                                                                                                                                      
    end                                                                                                                                                                                                                         

    methods (Access = private)                                                                                                                                                                                                  

        %% NeuroPAL ID Functions                                                                                                                                                                                                
        function InitNeuronMarker(app)                                                                                                                                                                                          
            %% Initialize the neuron marker GUI attributes.                                                                                                                                                                     
            % Note: initialization is performed by startupFcn due construction issues.                                                                                                                                          

            app.neuron_marker.shape = 'c';                                                                                                                                                                                      
            app.neuron_marker.color.edge = [0,0,0];                                                                                                                                                                             
        end                                                                                                                                                                                                                     

        function DrawImageData(app)                                                                                                                                                                                             
            %% Draw the annotated image (image volume & neuron markers).                                                                                                                                                        

            % Is there an image?                                                                                                                                                                                                
            if isempty(app.image_data)                                                                                                                                                                                          
                return;                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 

            app.logEvent('Main','Drawing image...', 1);                                                                                                                                                                         

            % Determine the color channel indices.                                                                                                                                                                              
            red = str2double(app.RDropDown.Value);                                                                                                                                                                              
            green = str2double(app.GDropDown.Value);                                                                                                                                                                            
            blue = str2double(app.BDropDown.Value);                                                                                                                                                                             
            white = str2double(app.WDropDown.Value);                                                                                                                                                                            
            dic = str2double(app.DICDropDown.Value);                                                                                                                                                                            
            gfp = str2double(app.GFPDropDown.Value);                                                                                                                                                                            

            % Determine the channel=color assignments for displaying.                                                                                                                                                           
            color_indices = [red, green, blue];                                                                                                                                                                                 

            % Draw the 3 color channels.                                                                                                                                                                                        
            app.image_view = app.image_data(:,:,:,color_indices);                                                                                                                                                               

            % Remove unchecked color channels.                                                                                                                                                                                  
            if ~app.RCheckBox.Value % Red                                                                                                                                                                                       
                app.image_view(:,:,:,1) = 0;                                                                                                                                                                                    
            end                                                                                                                                                                                                                 
            if ~app.GCheckBox.Value % Green                                                                                                                                                                                     
                app.image_view(:,:,:,2) = 0;                                                                                                                                                                                    
            end                                                                                                                                                                                                                 
            if ~app.BCheckBox.Value % Blue                                                                                                                                                                                      
                app.image_view(:,:,:,3) = 0;                                                                                                                                                                                    
            end                                                                                                                                                                                                                 

            % Add in the white channel.                                                                                                                                                                                         
            if app.WCheckBox.Value % White                                                                                                                                                                                      

                % Compute the white channel.                                                                                                                                                                                    
                wchannel = app.image_data(:,:,:,white);                                                                                                                                                                         

                % Adjust the gamma.                                                                                                                                                                                             
                W_i = app.gamma_RGBW_DIC_GFP_index(4); % white channel gamma index                                                                                                                                              
                if length(app.image_gamma) >= W_i && app.image_gamma(W_i) ~= 1                                                                                                                                                  
                    wchannel = imadjustn(wchannel,[],[], app.image_gamma(W_i));                                                                                                                                                 
                end                                                                                                                                                                                                             

                % Add the white channel.                                                                                                                                                                                        
                app.image_view = app.image_view + repmat(wchannel, [1,1,1,3]);                                                                                                                                                  
            end                                                                                                                                                                                                                 

            % Add in the DIC channel.                                                                                                                                                                                           
            if app.DICCheckBox.Value % DIC                                                                                                                                                                                      

                % Compute the DIC channel.                                                                                                                                                                                      
                dic_channel = app.image_data(:,:,:,dic);                                                                                                                                                                        

                % Adjust the gamma.                                                                                                                                                                                             
                DIC_i = app.gamma_RGBW_DIC_GFP_index(5); % DIC channel gamma index                                                                                                                                              
                if length(app.image_gamma) >= DIC_i && app.image_gamma(DIC_i) ~= 1                                                                                                                                              
                    dic_channel = imadjustn(dic_channel,[],[], app.image_gamma(DIC_i));                                                                                                                                         
                end                                                                                                                                                                                                             

                % Add the DIC channel.                                                                                                                                                                                          
                app.image_view = app.image_view + repmat(dic_channel, [1,1,1,3]);                                                                                                                                               
            end                                                                                                                                                                                                                 

            % Add in the GFP channel.                                                                                                                                                                                           
            if app.GFPCheckBox.Value % GFP                                                                                                                                                                                      

                % Compute the GFP channel.                                                                                                                                                                                      
                gfp_color = Program.GUIPreferences.instance().GFP_color;                                                                                                                                                        
                gfp_channel = app.image_data(:,:,:,gfp);                                                                                                                                                                        

                % Adjust the gamma.                                                                                                                                                                                             
                GFP_i = app.gamma_RGBW_DIC_GFP_index(6); % GFP channel gamma index                                                                                                                                              
                if length(app.image_gamma) >= GFP_i && app.image_gamma(GFP_i) ~= 1                                                                                                                                              
                    gfp_channel = imadjustn(gfp_channel,[],[], app.image_gamma(GFP_i));                                                                                                                                         
                end                                                                                                                                                                                                             

                % Add the GFP channel.                                                                                                                                                                                          
                gfp_channel = repmat(gfp_channel, [1,1,1,3]);                                                                                                                                                                   
                gfp_channel(:,:,:,~gfp_color) = 0;                                                                                                                                                                              
                app.image_view = app.image_view + gfp_channel;                                                                                                                                                                  
            end                                                                                                                                                                                                                 

            % Adjust the gamma.                                                                                                                                                                                                 
            % Note: the image only shows RGB. We added the other channels                                                                                                                                                       
            % (W, DIC, GFP) to the RGB in order to show these as well.                                                                                                                                                          
            app.image_view = uint16(double(intmax('uint16')) * ...                                                                                                                                                              
                double(app.image_view)/double(max(app.image_view(:))));                                                                                                                                                         
            for c = 1:size(app.image_view, 4)                                                                                                                                                                                   
                if app.image_gamma(c) ~= 1                                                                                                                                                                                      
                    app.image_view(:,:,:,c) = ...                                                                                                                                                                               
                        imadjustn(squeeze(app.image_view(:,:,:,c)),[],[], ...                                                                                                                                                   
                        app.image_gamma(c));                                                                                                                                                                                    
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 
            app.image_view = double(app.image_view)/double(max(app.image_view(:)));                                                                                                                                             

            % Redraw the max projection.                                                                                                                                                                                        
            % Note: the image only shows RGB. We added the other channels                                                                                                                                                       
            % (W, DIC, GFP) to the RGB in order to show these as well.                                                                                                                                                          
            image(app.MaxProjection, squeeze(max(app.image_view,[],3)));                                                                                                                                                        

            % Redraw the Z-slice.                                                                                                                                                                                               
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);                                                                                                                                                               
        end                                                                                                                                                                                                                     


        function DrawZSlice(app, slider, view, ax)                                                                                                                                                                              
            %% Draw the neurons in this z-slice.                                                                                                                                                                                

            % Sanity check the Z slice value.                                                                                                                                                                                   
            z = round(app.ZSlider.Value);                                                                                                                                                                                       
            app.logEvent('Main',sprintf('Drawing slice %s...', string(z)), 0);                                                                                                                                                  
            app.ZSlider.Value = z;                                                                                                                                                                                              

            % Is there an image?                                                                                                                                                                                                
            if isempty(app.image_data)                                                                                                                                                                                          
                return;                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 

            % Flip the Z-axis.                                                                                                                                                                                                  
            z_num = z;                                                                                                                                                                                                          
            if app.image_prefs.is_Z_flip                                                                                                                                                                                        
                z = size(app.image_data,3) - z + 1;                                                                                                                                                                             
            end                                                                                                                                                                                                                 

            % Where are we in Z?                                                                                                                                                                                                
            background_color = [0.94,0.94,0.94];                                                                                                                                                                                
            LD_color = [0,1,1]; % left or dorsal color                                                                                                                                                                          
            RV_color = [1,0,1]; % right or ventral color                                                                                                                                                                        
            z_center_thresh = 1; % center +/- 1 slice                                                                                                                                                                           
            z_center = app.image_prefs.z_center;                                                                                                                                                                                
            if z_num >= z_center - z_center_thresh && z_num <= z_center + z_center_thresh                                                                                                                                       
                app.XYPanel.BackgroundColor = background_color;                                                                                                                                                                 
                app.ZLeftLabel.BackgroundColor = background_color;                                                                                                                                                              
                app.ZRightLabel.BackgroundColor = background_color;                                                                                                                                                             
            elseif z_num < z_center - z_center_thresh                                                                                                                                                                           
                app.XYPanel.BackgroundColor = LD_color;                                                                                                                                                                         
                app.ZLeftLabel.BackgroundColor = LD_color;                                                                                                                                                                      
                app.ZRightLabel.BackgroundColor = background_color;                                                                                                                                                             
            else % if z > z_center + z_center_thresh                                                                                                                                                                            
                app.XYPanel.BackgroundColor = RV_color;                                                                                                                                                                         
                app.ZLeftLabel.BackgroundColor = background_color;                                                                                                                                                              
                app.ZRightLabel.BackgroundColor = RV_color;                                                                                                                                                                     
            end                                                                                                                                                                                                                 

            % Clear the contents of the axis to draw the new Z-slice.                                                                                                                                                           
            cla(ax);                                                                                                                                                                                                            
            % Create the slice at z for displaying in the axis.                                                                                                                                                                 
            xy = squeeze(view(:,:,z,:,:));                                                                                                                                                                                      
            % Display the current slice in the XY axis.                                                                                                                                                                         
            gui_image = image(xy, 'Parent', ax); hold(ax, 'on');                                                                                                                                                                

            if strcmp(app.TabGroup.SelectedTab.Title, 'Image Processing') & strcmp(app.VolumeDropDown.Value, 'Colormap')                                                                                                        
                image(xy, 'Parent', app.proc_xyAxes);                                                                                                                                                                           
            end                                                                                                                                                                                                                 

            % Add the AddNeuron function as mouse click listener.                                                                                                                                                               
            gui_image.ButtonDownFcn = {@app.ImageClicked};                                                                                                                                                                      

            % Redraw the neurons in this z slice.                                                                                                                                                                               
            if ~isempty(app.image_neurons) && ~isempty(app.image_neurons.neurons)                                                                                                                                               

                % Which neurons belong in this z-slice?                                                                                                                                                                         
                neuron_locations = app.image_neurons.get_positions();                                                                                                                                                           
                neuron_marker_colors = app.image_neurons.get_marker_colors();                                                                                                                                                   
                neuron_marker_sizes = app.image_neurons.get_marker_sizes();                                                                                                                                                     
                neuron_line_size = Program.GUIPreferences.instance().neuron_dot.line;                                                                                                                                           

                % Since z (which is found by MP) can be a continuous value                                                                                                                                                      
                % to find all the neurons in the current slice we find the                                                                                                                                                      
                % ones that lie in the interval [z-z_dot_view, z+z_dot_view]. Finally we                                                                                                                                        
                % change the x and y dimension to fix the inconsistent                                                                                                                                                          
                % behavior of Matlab figures for scatter and image function.                                                                                                                                                    
                z_dot_view = 1.5;                                                                                                                                                                                               
                current_z_indices = neuron_locations(:,3)>z-z_dot_view & neuron_locations(:,3)<z+z_dot_view;                                                                                                                    
                positions = neuron_locations(current_z_indices, 1:2);                                                                                                                                                           

                % Draw the neuron markers.                                                                                                                                                                                      
                neuron_marker_plot = scatter(ax, positions(:, 2), positions(:, 1), ...                                                                                                                                          
                    neuron_marker_sizes(current_z_indices), ...                                                                                                                                                                 
                    neuron_marker_colors(current_z_indices, :), ...                                                                                                                                                             
                    'filled', 'MarkerEdgeColor', app.neuron_marker.color.edge, ...                                                                                                                                              
                    'LineWidth', neuron_line_size);                                                                                                                                                                             

                % Are we showing the neuron annotations?                                                                                                                                                                        
                if app.show_labels                                                                                                                                                                                              

                    % Get the labels.                                                                                                                                                                                           
                    labels = app.image_neurons.get_annotations();                                                                                                                                                               
                    labels = labels(current_z_indices);                                                                                                                                                                         

                    % Get the ON/OFF annotations.                                                                                                                                                                               
                    is_on = app.image_neurons.get_is_annotations_on();                                                                                                                                                          
                    is_on = is_on(current_z_indices);                                                                                                                                                                           

                    % Get the confidences.                                                                                                                                                                                      
                    confidences = app.image_neurons.get_annotation_confidences();                                                                                                                                               
                    confidences = confidences(current_z_indices);                                                                                                                                                               

                    % Get the emphasized neurons.                                                                                                                                                                               
                    is_emphasized = app.image_neurons.get_is_emphasized();                                                                                                                                                      
                    is_emphasized = is_emphasized(current_z_indices);                                                                                                                                                           

                    % Remove empty labels.                                                                                                                                                                                      
                    is_label = ~cellfun('isempty', labels);                                                                                                                                                                     
                    labels = labels(is_label);                                                                                                                                                                                  
                    is_on = is_on(is_label);                                                                                                                                                                                    
                    confidences = confidences(is_label);                                                                                                                                                                        
                    is_emphasized = is_emphasized(is_label);                                                                                                                                                                    

                    % Add ON/OFF & confidence to the labels.                                                                                                                                                                    
                    for i = 1:length(confidences)                                                                                                                                                                               

                        % Is the neuron ON/OFF.                                                                                                                                                                                 
                        switch is_on(i)                                                                                                                                                                                         
                            case false                                                                                                                                                                                          
                                labels{i} = [labels{i} '-OFF'];                                                                                                                                                                 
                            case true                                                                                                                                                                                           
                                labels{i} = [labels{i} '-ON'];                                                                                                                                                                  
                        end                                                                                                                                                                                                     

                        % Is the user uncertain about the ID?                                                                                                                                                                   
                        if confidences(i) <= 0.5                                                                                                                                                                                
                            labels{i} = [labels{i} '?'];                                                                                                                                                                        
                        end                                                                                                                                                                                                     

                        % Is the neuron emphasized?                                                                                                                                                                             
                        if is_emphasized(i)                                                                                                                                                                                     
                            labels{i} = [labels{i} '!'];                                                                                                                                                                        
                        end                                                                                                                                                                                                     
                    end                                                                                                                                                                                                         

                    % Draw the labels.                                                                                                                                                                                          
                    DrawImageLabels(app, positions(is_label,:), labels);                                                                                                                                                        
                end                                                                                                                                                                                                             

                % Setup the mouse-click callback.                                                                                                                                                                               
                neuron_marker_plot.ButtonDownFcn = {@app.NeuronClicked};                                                                                                                                                        
            end                                                                                                                                                                                                                 

            % Draw the color atlas.                                                                                                                                                                                             
            if app.ColorAtlasCheckBox.Value                                                                                                                                                                                     

                % Do we have the atlas info?                                                                                                                                                                                    
                if isempty(app.image_neurons.get_aligned_xyzRGBs())                                                                                                                                                             

                    % Uncheck the atlas.                                                                                                                                                                                        
                    app.ColorAtlasCheckBox.Value = false;                                                                                                                                                                       

                    % Warn the user.                                                                                                                                                                                            
                    uialert(app.CELL_ID, ...                                                                                                                                                                                    
                        'Please press "Auto-ID All" to create the neuron ID atlas!', ...                                                                                                                                        
                        'No Atlas', 'Icon', 'warning');                                                                                                                                                                         

                    % Draw the neuron ID atlas.                                                                                                                                                                                 
                else                                                                                                                                                                                                            
                    Methods.AutoId.instance().visualize(...                                                                                                                                                                     
                        app.image_neurons, app.worm, 'ax', app.XY, 'z', z);                                                                                                                                                     
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     

        function DrawImageLabels(app, positions, labels, varargin)                                                                                                                                                              
            %% Draw text labels on the image.                                                                                                                                                                                   

            % Are the left & right labels pre-determined?                                                                                                                                                                       
            left_i = [];                                                                                                                                                                                                        
            if ~isempty(varargin)                                                                                                                                                                                               
                left_i = varargin{1};                                                                                                                                                                                           
            end                                                                                                                                                                                                                 

            % Determine the left & right labels.                                                                                                                                                                                
            if isempty(left_i)                                                                                                                                                                                                  
                center_y = size(app.image_view, 2) / 2;                                                                                                                                                                         
                left_i = positions(:,2) <= center_y;                                                                                                                                                                            
            end                                                                                                                                                                                                                 
            right_i = ~left_i;                                                                                                                                                                                                  

            % Draw the labels on the left side.                                                                                                                                                                                 
            if sum(left_i) > 0                                                                                                                                                                                                  
                left_pos = positions(left_i,1:2);                                                                                                                                                                               
                left_pos(:,2) = left_pos(:,2) + 4;                                                                                                                                                                              
                left_labels = cellfun(@(x) strcat('\leftarrow', x), labels(left_i), 'UniformOutput', false);                                                                                                                    
                text(app.XY, left_pos(:,2), left_pos(:,1), left_labels, ...                                                                                                                                                     
                    'Color', app.label_color, 'FontSize', app.label_fontsize, ...                                                                                                                                               
                    'FontWeight', app.label_fontweight, 'HorizontalAlignment', 'left');                                                                                                                                         
            end                                                                                                                                                                                                                 

            % Draw the labels on the right side.                                                                                                                                                                                
            if sum(right_i) > 0                                                                                                                                                                                                 
                right_pos = positions(right_i,1:2);                                                                                                                                                                             
                right_pos(:,2) = right_pos(:,2) - 4;                                                                                                                                                                            
                right_labels = cellfun(@(x) strcat(x, '\rightarrow'), labels(right_i), 'UniformOutput', false);                                                                                                                 
                text(app.XY, right_pos(:,2), right_pos(:,1), right_labels, ...                                                                                                                                                  
                    'Color', app.label_color, 'FontSize', app.label_fontsize, ...                                                                                                                                               
                    'FontWeight', app.label_fontweight, 'HorizontalAlignment', 'right');                                                                                                                                        
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function ImageClicked(app, image_neurons, hit)                                                                                                                                                                          
            %% The image was clicked. Add a neuron (on middle or double-click) or delete image artifacts (if selected).                                                                                                         

            double_click = false; % did the user double-click?                                                                                                                                                                  

            switch hit.Button                                                                                                                                                                                                   
                case 1                                                                                                                                                                                                          
                    % Check for a double click.                                                                                                                                                                                 
                    if app.mouse_clicked.click                                                                                                                                                                                  
                        %disp('double click');                                                                                                                                                                                  
                        double_click = true;                                                                                                                                                                                    

                        % Single click.                                                                                                                                                                                         
                    else                                                                                                                                                                                                        
                        app.mouse_clicked.click = true;                                                                                                                                                                         
                        pause(app.mouse_clicked.double_click_delay);                                                                                                                                                            
                        if app.mouse_clicked.click                                                                                                                                                                              
                            %disp('single click');                                                                                                                                                                              
                        end                                                                                                                                                                                                     
                    end                                                                                                                                                                                                         
                    app.mouse_clicked.click = false;                                                                                                                                                                            

                case 2                                                                                                                                                                                                          
                    double_click = true;                                                                                                                                                                                        
            end                                                                                                                                                                                                                 

            % Add a neuron where the user clicked.                                                                                                                                                                              
            if double_click                                                                                                                                                                                                     
                switch app.MouseClickDropDown.Value                                                                                                                                                                             
                    case app.MouseClickDropDown.Items{1} % Add a neuron where the user clicked.                                                                                                                                 
                        app.logEvent('NeuroPAL_ID','Adding neuron...',1);                                                                                                                                                       
                        app.image_neurons.add_meta_data('auto_detect', false);                                                                                                                                                  
                        AddNeuron(app, hit);                                                                                                                                                                                    

                    case app.MouseClickDropDown.Items{2} % Auto detect a neuron where the user clicked.                                                                                                                         
                        app.logEvent('NeuroPAL_ID','Auto-detecting neuron...',1);                                                                                                                                               
                        app.image_neurons.add_meta_data('auto_detect', true);                                                                                                                                                   
                        AddNeuron(app, hit);                                                                                                                                                                                    
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function AddNeuron(app, hit)                                                                                                                                                                                            
            %% Add a neuron to a z-slice.                                                                                                                                                                                       

            % Where did the user click?                                                                                                                                                                                         
            mouse_pos = flip(hit.IntersectionPoint(1:2)) + app.mouse_offset;                                                                                                                                                    

            % Read the z slice from the ZSlider.                                                                                                                                                                                
            z = getZSlice(app, round(app.ZSlider.Value));                                                                                                                                                                       
            point = [mouse_pos, z];                                                                                                                                                                                             

            app.logEvent('Main',sprintf('Adding neuron @ %s...', string(point)), 0);                                                                                                                                            
            % Create & add the neuron.                                                                                                                                                                                          
            app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...                                                                                                  
                point, app.mp_params.hnsz, app.image_um_scale');                                                                                                                                                                

            % Save the updated IDs.                                                                                                                                                                                             
            SaveIDToFile(app);                                                                                                                                                                                                  

            % Redraw the image.                                                                                                                                                                                                 
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);                                                                                                                                                               

            % Update the ID'd neuron count.                                                                                                                                                                                     
            UpdateNeuronLists(app);                                                                                                                                                                                             

            % Enable the neuron GUI.                                                                                                                                                                                            
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0                                                                                                                                               
                Program.GUIHandling.gui_lock(app, 'enable', 'neuron_gui');                                                                                                                                                      
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function NeuronClicked(app, image_neurons, hit)                                                                                                                                                                         
            %% Select or delete neurons where the user clicked.                                                                                                                                                                 

            select_neuron = false; % select the neuron where the user clicked?                                                                                                                                                  
            delete_neuron = false; % delete the neuron where the user clicked?                                                                                                                                                  

            % Where did the user click?                                                                                                                                                                                         
            mouse_pos = flip(hit.IntersectionPoint(1:2));                                                                                                                                                                       

            % Where are we in Z?                                                                                                                                                                                                
            z = getZSlice(app, round(app.ZSlider.Value));                                                                                                                                                                       
            point = [mouse_pos, z];                                                                                                                                                                                             

            % Find the closest neuron to the mouse click.                                                                                                                                                                       
            positions = app.image_neurons.get_positions();                                                                                                                                                                      
            [~, neuron_i] = min(mean((positions-point).^2,2));                                                                                                                                                                  

            switch hit.Button                                                                                                                                                                                                   
                case 1 % left click                                                                                                                                                                                             

                    % Check for a double click.                                                                                                                                                                                 
                    if app.mouse_clicked.click                                                                                                                                                                                  
                        delete_neuron = true;                                                                                                                                                                                   

                        % Single click.                                                                                                                                                                                         
                    else                                                                                                                                                                                                        
                        app.mouse_clicked.click = true;                                                                                                                                                                         
                        pause(app.mouse_clicked.double_click_delay);                                                                                                                                                            
                        if app.mouse_clicked.click                                                                                                                                                                              
                            select_neuron = true;                                                                                                                                                                               
                        end                                                                                                                                                                                                     
                    end                                                                                                                                                                                                         
                    app.mouse_clicked.click = false;                                                                                                                                                                            

                case 3 % right click                                                                                                                                                                                            
                    delete_neuron = true;                                                                                                                                                                                       
            end                                                                                                                                                                                                                 

            % Select the neuron.                                                                                                                                                                                                
            if select_neuron                                                                                                                                                                                                    
                SelectNeuron(app, neuron_i);                                                                                                                                                                                    

                % Delete the neuron from the list.                                                                                                                                                                              
            elseif delete_neuron                                                                                                                                                                                                
                DeleteNeuron(app, neuron_i);                                                                                                                                                                                    
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function drawStimuli(app)                                                                                                                                                                                               
            % Get the axes of the stackedplot                                                                                                                                                                                   
            ax = findobj(app.VolTrace.NodeChildren, "Type", "axes");                                                                                                                                                            
                                                                                                                                                                                                                                
            % Loop over each stimulus                                                                                                                                                                                           
            for i = 1:size(app.stimuli, 1)                                                                                                                                                                                      
                % Get the stimulus time and name                                                                                                                                                                                
                time = app.stimuli{i, 1};                                                                                                                                                                                       
                if size(app.stimuli, 2) == 3                                                                                                                                                                                    
                    name = app.stimuli{i, 3}{1};                                                                                                                                                                                
                    end_time = app.stimuli{i, 2};                                                                                                                                                                               
                else                                                                                                                                                                                                            
                    name = app.stimuli{i, 2}{1};                                                                                                                                                                                

                    if i ~= size(app.stimuli, 1)                                                                                                                                                                                
                        end_time = app.stimuli{i+1, 1};                                                                                                                                                                         
                    else                                                                                                                                                                                                        
                        get(app.VolTrace)                                                                                                                                                                                       
                        end_time = app.VolTrace.XLimits(2);                                                                                                                                                                     
                    end                                                                                                                                                                                                         
                end                                                                                                                                                                                                             
                                                                                                                                                                                                                                
                % If the stimulus is 'CTX', set color to white                                                                                                                                                                  
                if strcmp(name, 'CTX')                                                                                                                                                                                          
                    color = [1 1 1];                                                                                                                                                                                            
                else                                                                                                                                                                                                            
                    % Sum the ASCII values of the name's characters                                                                                                                                                             
                    asciiSum = sum(double(name));                                                                                                                                                                               
                                                                                                                                                                                                                                
                    % Use mod to wrap the sum into a range [0, 1]                                                                                                                                                               
                    hashValue = mod(asciiSum, 256) / 255;                                                                                                                                                                       
                                                                                                                                                                                                                                
                    % Generate a color that is not too similar to white                                                                                                                                                         
                    color = [hashValue, abs(0.5 - hashValue), abs(1 - hashValue)];                                                                                                                                              
                end                                                                                                                                                                                                             
                                                                                                                                                                                                                                
                text_color = color * 0.5;  % darker variant                                                                                                                                                                     
                                                                                                                                                                                                                                
                % Add the stimulus name as text                                                                                                                                                                                 
                text(ax(end), time + (end_time - time) / 2, ax(end).YLim(2), name, 'HorizontalAlignment', 'right', 'VerticalAlignment', 'middle', 'FontSize', 8, 'Color', text_color, 'Rotation', 90);                          
                                                                                                                                                                                                                                
                % Loop over each subplot                                                                                                                                                                                        
                for j = 1:numel(ax)                                                                                                                                                                                             
                    % Draw a rectangle on the subplot using patch                                                                                                                                                               
                    patch(ax(j), 'XData', [time, end_time, end_time, time], 'YData', [ax(j).YLim(1), ax(j).YLim(1), ax(j).YLim(2), ax(j).YLim(2)], 'FaceColor', color, 'EdgeColor', 'none', 'FaceAlpha', 0.6);                  
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 

            for n=1:numel(ax)                                                                                                                                                                                                   
                set(ax(n),'children',flipud(get(ax(n),'children')))                                                                                                                                                             
            end                                                                                                                                                                                                                 

            app.data_flags.('Stimulus File')                                                                                                                                                                                    
        end                                                                                                                                                                                                                     


        function SelectNeuron(app, neuron_i)                                                                                                                                                                                    
            %% Select a neuron from the z-slice image.                                                                                                                                                                          

            % Update the neuron plot marker to show that it's selected.                                                                                                                                                         
            % Find the index of selected neuron in the list of displayed                                                                                                                                                        
            % neurons that are in the current slice. The index of neuron                                                                                                                                                        
            % in the app.image_neurons.neurons (which contains all the neurons) is                                                                                                                                              
            % different from its index in the list of current z neurons.                                                                                                                                                        

            % Unselect the selected neuron.                                                                                                                                                                                     
            if app.selected_neuron == neuron_i                                                                                                                                                                                  
                UnselectNeuron(app);                                                                                                                                                                                            
                return;                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 

            % Unselect the previously selected neuron.                                                                                                                                                                          
            if ~isempty(app.selected_neuron)                                                                                                                                                                                    
                app.image_neurons.neurons(app.selected_neuron).is_selected = false;                                                                                                                                             
            end                                                                                                                                                                                                                 

            % Select the neuron.                                                                                                                                                                                                
            app.selected_neuron = neuron_i;                                                                                                                                                                                     
            app.image_neurons.neurons(app.selected_neuron).is_selected = true;                                                                                                                                                  

            % Flip the Z-axis.                                                                                                                                                                                                  
            z = app.image_neurons.neurons(app.selected_neuron).position(3);                                                                                                                                                     
            z = getZSlice(app, z);                                                                                                                                                                                              

            % Redraw the Z-slice.                                                                                                                                                                                               
            app.ZSlider.Value = z;                                                                                                                                                                                              
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);                                                                                                                                                               

            % If the AutoID is already run and the IDs are present.                                                                                                                                                             
            is_IDEditField_set = false;                                                                                                                                                                                         
            if ~isempty(app.image_neurons.neurons) ...                                                                                                                                                                          
                    && isempty(app.image_neurons.neurons(neuron_i).annotation) ...                                                                                                                                              
                    && ~isempty(app.image_neurons.neurons(neuron_i).deterministic_id)                                                                                                                                           

                % Initialize the IDs & probabilities.                                                                                                                                                                           
                ids = app.image_neurons.neurons(neuron_i).probabilistic_ids;                                                                                                                                                    
                probs = app.image_neurons.neurons(neuron_i).probabilistic_probs();                                                                                                                                              
                probs = round(probs * 100);                                                                                                                                                                                     

                % Remove zero probability choices.                                                                                                                                                                              
                probs_i = probs > 0;                                                                                                                                                                                            
                ids = ids(probs_i);                                                                                                                                                                                             
                probs = probs(probs_i);                                                                                                                                                                                         

                % Concatenate names and probabilities to display them.                                                                                                                                                          
                str = strcat(ids, '=', arrayfun(@(x) num2str(x, '%d%% '), probs, 'UniformOutput', 0));                                                                                                                          

                % Update the GUI.                                                                                                                                                                                               
                app.AutoIDDropDown.Items = str;                                                                                                                                                                                 
                name = app.image_neurons.neurons(neuron_i).deterministic_id;                                                                                                                                                    
                name = upper(name);                                                                                                                                                                                             
                if ~app.AutoNameCheckBox.Value || ...                                                                                                                                                                           
                        strcmp(name, 'ARTIFACT') || strcmp(name, 'NAN')                                                                                                                                                         
                    app.IDEditField.Value = '';                                                                                                                                                                                 
                else                                                                                                                                                                                                            
                    app.IDEditField.Value = name;                                                                                                                                                                               
                end                                                                                                                                                                                                             
                is_IDEditField_set = true;                                                                                                                                                                                      

                % Select the appropriate Auto ID.                                                                                                                                                                               
                neuron_rank = app.image_neurons.neurons(neuron_i).rank;                                                                                                                                                         
                if ~isempty(neuron_rank)                                                                                                                                                                                        
                    app.UserNeuronIDsListBox.Value = neuron_rank;                                                                                                                                                               
                    %app.UserNeuronIDsListBox.Value = app.UserNeuronIDsListBox.Items{neuron_rank};                                                                                                                              
                end                                                                                                                                                                                                             
            else                                                                                                                                                                                                                
                % Select nothing.                                                                                                                                                                                               
                app.AutoIDDropDown.Items = {};                                                                                                                                                                                  
                app.UserNeuronIDsListBox.Value = {};                                                                                                                                                                            
            end                                                                                                                                                                                                                 

            % Show the user annotation.                                                                                                                                                                                         
            user_ID = app.image_neurons.neurons(neuron_i).annotation;                                                                                                                                                           
            if ~isempty(user_ID)                                                                                                                                                                                                

                % Is the neuron ON/OFF?                                                                                                                                                                                         
                switch app.image_neurons.neurons(neuron_i).is_annotation_on                                                                                                                                                     
                    case false                                                                                                                                                                                                  
                        user_ID = [user_ID '-OFF'];                                                                                                                                                                             
                    case true                                                                                                                                                                                                   
                        user_ID = [user_ID '-ON'];                                                                                                                                                                              
                end                                                                                                                                                                                                             

                % Is the user uncertain about the ID?                                                                                                                                                                           
                if app.image_neurons.neurons(neuron_i).annotation_confidence <= 0.5                                                                                                                                             
                    user_ID = [user_ID '?'];                                                                                                                                                                                    
                end                                                                                                                                                                                                             

                % Is the neuron emphasized?                                                                                                                                                                                     
                if app.image_neurons.neurons(neuron_i).is_emphasized                                                                                                                                                            
                    user_ID = [user_ID '!'];                                                                                                                                                                                    
                end                                                                                                                                                                                                             

                % Display the user ID.                                                                                                                                                                                          
                app.IDEditField.Value = user_ID;                                                                                                                                                                                
                is_IDEditField_set = true;                                                                                                                                                                                      
            end                                                                                                                                                                                                                 

            % Empty the ID field.                                                                                                                                                                                               
            if ~is_IDEditField_set                                                                                                                                                                                              
                app.IDEditField.Value = '';                                                                                                                                                                                     
            end                                                                                                                                                                                                                 

            % Enable the input fields.                                                                                                                                                                                          
            app.AutoIDDropDown.Enable = 'on';                                                                                                                                                                                   
            app.IDEditField.Enable = 'on';                                                                                                                                                                                      
            app.AutoIDButton.Enable = 'on';                                                                                                                                                                                     
            app.UserIDButton.Enable = 'on';                                                                                                                                                                                     

            % If the neuron is unID'd, send focus to the ID text field.                                                                                                                                                         
            % Hack: Matlab App Designer sucks, I shouldn't have to do this.                                                                                                                                                     
            if isempty(user_ID)                                                                                                                                                                                                 
                Program.GUIHandling.send_focus(app.IDEditField);                                                                                                                                                                
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function SelectNearestUnIDdNeuron(app, neuron_i)                                                                                                                                                                        
            %% Select the nearest neuron in need of a user ID.                                                                                                                                                                  

            % Select the nearest neuron in need of a user ID by z, then (x,y).                                                                                                                                                  
            [~, neuron_i] = app.image_neurons.nearest_unannotated(neuron_i);                                                                                                                                                    

            % No more neurons to ID.                                                                                                                                                                                            
            if isempty(neuron_i)                                                                                                                                                                                                
                uialert(app.CELL_ID, ...                                                                                                                                                                                        
                    'All neurons have been assigned an ID!',...                                                                                                                                                                 
                    'All Neurons ID''d', 'Icon', 'success');                                                                                                                                                                    
                return;                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 

            % Auto ID the nearest neuron.                                                                                                                                                                                       
            auto_ID = [];                                                                                                                                                                                                       
            if ~isempty(app.image_neurons.neurons) ...                                                                                                                                                                          
                    && ~isempty(app.image_neurons.neurons(neuron_i).deterministic_id)                                                                                                                                           
                auto_ID = app.image_neurons.neurons(neuron_i).deterministic_id;                                                                                                                                                 
            end                                                                                                                                                                                                                 

            % Select the nearest neuron.                                                                                                                                                                                        
            SelectNeuron(app, neuron_i);                                                                                                                                                                                        
            position = app.image_neurons.neurons(neuron_i).position;                                                                                                                                                            
            DrawImageLabels(app, position, {[auto_ID '???']}, 0);                                                                                                                                                               

            % Send focus to the ID text field.                                                                                                                                                                                  
            % Hack: Matlab App Designer sucks, I shouldn't have to do this.                                                                                                                                                     
            %Program.GUIHandling.send_focus(app.IDEditField);                                                                                                                                                                   
        end                                                                                                                                                                                                                     


        function SelectProbabilisticUnIDdNeuron(app, neuron_i)                                                                                                                                                                  
            %% Select the most uncertain neuron in need of a user ID.                                                                                                                                                           

            % No more neurons to ID.                                                                                                                                                                                            
            if app.image_neurons.num_neurons() == app.image_neurons.num_user_id_neurons()                                                                                                                                       
                uialert(app.CELL_ID, ...                                                                                                                                                                                        
                    'All neurons have been assigned an ID!',...                                                                                                                                                                 
                    'All Neurons ID''d', 'Icon', 'success');                                                                                                                                                                    
                return;                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 

            % Store the currently selcted neuron.                                                                                                                                                                               
            old_neuron_i = neuron_i;                                                                                                                                                                                            

            % Select the nearest neuron in need of a user ID by z, then (x,y).                                                                                                                                                  
            [~, neuron_i] = app.image_neurons.find_rank(1);                                                                                                                                                                     

            % Is the neuron already selected?                                                                                                                                                                                   
            if app.image_neurons.num_user_unid_neurons() > 1 && neuron_i == old_neuron_i                                                                                                                                        
                [~, neuron_i] = app.image_neurons.find_rank(2);                                                                                                                                                                 
            end                                                                                                                                                                                                                 

            % No more neurons to ID.                                                                                                                                                                                            
            if isempty(neuron_i)                                                                                                                                                                                                
                uialert(app.CELL_ID, ...                                                                                                                                                                                        
                    'All neurons have been assigned an ID!',...                                                                                                                                                                 
                    'All Neurons ID''d', 'Icon', 'success');                                                                                                                                                                    
                return;                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 

            % Auto ID the most uncertain neuron.                                                                                                                                                                                
            auto_ID = [];                                                                                                                                                                                                       
            if ~isempty(app.image_neurons.neurons) ...                                                                                                                                                                          
                    && ~isempty(app.image_neurons.neurons(neuron_i).deterministic_id)                                                                                                                                           
                auto_ID = app.image_neurons.neurons(neuron_i).deterministic_id;                                                                                                                                                 
            end                                                                                                                                                                                                                 

            % Select the most uncertain neuron.                                                                                                                                                                                 
            SelectNeuron(app, neuron_i);                                                                                                                                                                                        
            position = app.image_neurons.neurons(neuron_i).position;                                                                                                                                                            
            DrawImageLabels(app, position, {[auto_ID '???']}, 0);                                                                                                                                                               

            % Send focus to the ID text field.                                                                                                                                                                                  
            % Hack: Matlab App Designer sucks, I shouldn't have to do this.                                                                                                                                                     
            %Program.GUIHandling.send_focus(app.IDEditField);                                                                                                                                                                   
        end                                                                                                                                                                                                                     


        function UnselectNeuron(app, varargin)                                                                                                                                                                                  
            %% Unselect the currently selected neuron. Optional argument, re-draw the image?                                                                                                                                    
            %% Note: Matlab buffers re-draws so, if you want them to execute in an orderly fashion,                                                                                                                             
            %% you need to only make one call.                                                                                                                                                                                  

            % Unselect the selected neuron.                                                                                                                                                                                     
            if ~isempty(app.selected_neuron) && app.image_neurons.neurons(app.selected_neuron).is_selected == true                                                                                                              

                % Unselect the neuron.                                                                                                                                                                                          
                app.image_neurons.neurons(app.selected_neuron).is_selected = false;                                                                                                                                             
                app.selected_neuron = [];                                                                                                                                                                                       

                % Disable the ID fields.                                                                                                                                                                                        
                app.AutoIDDropDown.Items = {''};                                                                                                                                                                                
                app.AutoIDDropDown.Value = '';                                                                                                                                                                                  
                app.IDEditField.Value = '';                                                                                                                                                                                     

                % Disable the input fields.                                                                                                                                                                                     
                app.AutoIDDropDown.Enable = 'off';                                                                                                                                                                              
                app.IDEditField.Enable = 'off';                                                                                                                                                                                 
                app.AutoIDButton.Enable = 'off';                                                                                                                                                                                
                app.UserIDButton.Enable = 'off';                                                                                                                                                                                

                % Redraw the Z-slice.                                                                                                                                                                                           
                is_redraw = true; % re-draw the image?                                                                                                                                                                          
                if ~isempty(varargin)                                                                                                                                                                                           
                    is_redraw = varargin{1};                                                                                                                                                                                    
                end                                                                                                                                                                                                             
                if is_redraw                                                                                                                                                                                                    

                    % Give the GUI time to update.                                                                                                                                                                              
                    pause(0.2);                                                                                                                                                                                                 

                    % Re-draw.                                                                                                                                                                                                  
                    DrawZSlice(app, app.ZSlider, app.image_view, app.XY);                                                                                                                                                       
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function DeleteNeuron(app, neuron_i)                                                                                                                                                                                    
            %% Delete a neuron from the z-slice image.                                                                                                                                                                          

            % Did the neuron have an ID?                                                                                                                                                                                        
            neuron = app.image_neurons.neurons(neuron_i).annotation;                                                                                                                                                            
            if ~isempty(neuron)                                                                                                                                                                                                 
                answer = uiconfirm(app.CELL_ID, ...                                                                                                                                                                             
                    {['"' neuron '" is ID''d!'], 'Do you want delete the neuron?'}, ...                                                                                                                                         
                    'Neuron ID Deleted', 'Options', {'Yes', 'Cancel'}, ...                                                                                                                                                      
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');                                                                                                                                                  

                % Keep the neuron.                                                                                                                                                                                              
                if strcmpi(answer, 'cancel')                                                                                                                                                                                    
                    return;                                                                                                                                                                                                     
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 

            % Unselect all neurons.                                                                                                                                                                                             
            app.UnselectNeuron(false); % wait for re-draw                                                                                                                                                                       
            %app.UnselectNeuron();                                                                                                                                                                                              

            % NO LONGER USED!!! UPDATEAUTOIDMODEL HANDLES THIS.                                                                                                                                                                 
            % Delete the neuron from the GUI list.                                                                                                                                                                              
            %app.UserNeuronIDsListBox.Value = {};                                                                                                                                                                               
            %uncertain_idx = app.image_neurons.neurons(neuron_i).rank;                                                                                                                                                          
            %if ~isempty(uncertain_idx)                                                                                                                                                                                         
            %    app.UserNeuronIDsListBox.Items = ...                                                                                                                                                                           
            %        {app.UserNeuronIDsListBox.Items{1:(uncertain_idx-1)}, ...                                                                                                                                                  
            %        app.UserNeuronIDsListBox.Items{(uncertain_idx+1):end}};                                                                                                                                                    
            %end                                                                                                                                                                                                                

            % Delete the neuron from the master list.                                                                                                                                                                           
            app.image_neurons.del_neuron(neuron_i);                                                                                                                                                                             

            % Save the updated IDs.                                                                                                                                                                                             
            app.SaveIDToFile();                                                                                                                                                                                                 

            % Redraw the auto ID list.                                                                                                                                                                                          
            app.DrawAutoIDList();                                                                                                                                                                                               

            % Redraw the Z-slice.                                                                                                                                                                                               
            app.DrawZSlice(app.ZSlider, app.image_view, app.XY);                                                                                                                                                                

            % Update the ID'd neuron count.                                                                                                                                                                                     
            app.UpdateNeuronLists();                                                                                                                                                                                            
        end                                                                                                                                                                                                                     

        function DrawAutoIDList(app)                                                                                                                                                                                            
            %% Draw the auto ID neuron uncertainty list.                                                                                                                                                                        

            % Initialize the probabilities.                                                                                                                                                                                     
            prob_IDs = app.image_neurons.get_probabilistic_ids();                                                                                                                                                               
            prob_vals = app.image_neurons.get_probabilistic_probs();                                                                                                                                                            
            ranks = app.image_neurons.get_ranks();                                                                                                                                                                              
            if isempty(ranks)                                                                                                                                                                                                   
                return;                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 

            % Construct the ranked strings.                                                                                                                                                                                     
            neurons = app.image_neurons.neurons;                                                                                                                                                                                
            prob_vals = round(prob_vals * 100);                                                                                                                                                                                 
            prob_strs = [];                                                                                                                                                                                                     
            prob_ranks = [];                                                                                                                                                                                                    
            for i = 1:length(neurons)                                                                                                                                                                                           

                % Is the neuron ranked?                                                                                                                                                                                         
                neuron = neurons(i);                                                                                                                                                                                            
                if neuron.rank > 0 % && isempty(neuron.annotation)                                                                                                                                                              

                    % Display the most probable neuron.                                                                                                                                                                         
                    prob_IDs = neuron.probabilistic_ids;                                                                                                                                                                        
                    prob_vals = round(neuron.probabilistic_probs * 100);                                                                                                                                                        
                    prob_strs{end+1} = sprintf('%s=%d%%', prob_IDs{1}, prob_vals(1));                                                                                                                                           
                    prob_ranks(end+1) = neuron.rank;                                                                                                                                                                            

                    % Display alternative neurons.                                                                                                                                                                              
                    alt_i = find(prob_vals(2:end) > 0) + 1;                                                                                                                                                                     
                    if ~isempty(alt_i)                                                                                                                                                                                          
                        prob_strs{end} = [prob_strs{end}, '   or  '];                                                                                                                                                           
                    end                                                                                                                                                                                                         
                    for j = 1:length(alt_i)                                                                                                                                                                                     
                        k = alt_i(j);                                                                                                                                                                                           
                        if j > 1                                                                                                                                                                                                
                            prob_strs{end} = [prob_strs{end}, ','];                                                                                                                                                             
                        end                                                                                                                                                                                                     
                        prob_strs{end} = [prob_strs{end}, sprintf(' %s=%d%%', ...                                                                                                                                               
                            prob_IDs{k}, prob_vals(k))];                                                                                                                                                                        
                    end                                                                                                                                                                                                         
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 

            % Draw the auto ID neuron uncertainty list.                                                                                                                                                                         
            [~, sort_i] = sort(prob_ranks);                                                                                                                                                                                     
            app.UserNeuronIDsListBox.Items = prob_strs(sort_i);                                                                                                                                                                 
            app.UserNeuronIDsListBox.ItemsData = 1:length(sort_i);                                                                                                                                                              
            app.UserNeuronIDsListBox.Value = {};                                                                                                                                                                                
        end                                                                                                                                                                                                                     


        function UpdateNeuronLists(app)                                                                                                                                                                                         
            %% Update the user ID'd neurons.                                                                                                                                                                                    

            % Show the selected neurons.                                                                                                                                                                                        
            selectedNodes = app.GanglionTree.SelectedNodes;                                                                                                                                                                     
            neurons = {};                                                                                                                                                                                                       
            for i = 1:length(selectedNodes)                                                                                                                                                                                     
                node = selectedNodes(i);                                                                                                                                                                                        
                if isempty(node.Children) % Child node on the tree.                                                                                                                                                             
                    neurons = union(neurons, GetChildNodeNeurons(app, node));                                                                                                                                                   
                else % Parent node on the tree.                                                                                                                                                                                 
                    neurons = union(neurons, GetParentNodeNeurons(app, node));                                                                                                                                                  
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 
            neurons = sort(neurons);                                                                                                                                                                                            

            % None of the neurons have IDs.                                                                                                                                                                                     
            if isempty(app.image_neurons) || isempty(app.image_neurons.neurons)                                                                                                                                                 
                app.UserNeuronIDsListBoxLabel.Text = 'User Neuron IDs';                                                                                                                                                         
                app.IDdLabel.Text = '     ID''d     ';                                                                                                                                                                          
                app.UserNeuronIDsListBox.Items = {};                                                                                                                                                                            
                app.UserNeuronIDsListBox.ItemsData = [];                                                                                                                                                                        
                app.UnIDdLabel.Text = sprintf('Un-ID''d = %d', length(neurons));                                                                                                                                                
                app.UnIDdNeuronsListBox.Items = neurons;                                                                                                                                                                        

                % Which neurons have IDs?                                                                                                                                                                                       
            else                                                                                                                                                                                                                
                % Show the neuron counts.                                                                                                                                                                                       
                user_id_neurons = app.image_neurons.user_id_neurons();                                                                                                                                                          
                app.UserNeuronIDsListBoxLabel.Text = sprintf('User Neuron IDs = %d/%d', ...                                                                                                                                     
                    length(user_id_neurons), app.image_neurons.num_neurons());                                                                                                                                                  

                % Remove emphasized neurons. They're not really ID'd.                                                                                                                                                           
                is_emphasized = vertcat(user_id_neurons.is_emphasized);                                                                                                                                                         
                user_id_neurons = user_id_neurons(~is_emphasized);                                                                                                                                                              

                % Separate the neurons into those with & without user IDs.                                                                                                                                                      
                user_id_names = vertcat({user_id_neurons.annotation});                                                                                                                                                          
                [id_neurons, ~, user_id_i] = intersect(neurons, user_id_names);                                                                                                                                                 
                unid_neurons =  setdiff(neurons, id_neurons);                                                                                                                                                                   

                % Keep a copy of the neuron names.                                                                                                                                                                              
                id_neuron_names = id_neurons;                                                                                                                                                                                   

                % Add ON/OFF & confidences to the neuron names.                                                                                                                                                                 
                for i = 1:length(user_id_i)                                                                                                                                                                                     

                    % Is the neuron ON/OFF?                                                                                                                                                                                     
                    switch user_id_neurons(user_id_i(i)).is_annotation_on                                                                                                                                                       
                        case false                                                                                                                                                                                              
                            id_neurons{i} = [id_neurons{i} '-OFF'];                                                                                                                                                             
                        case true                                                                                                                                                                                               
                            id_neurons{i} = [id_neurons{i} '-ON'];                                                                                                                                                              
                    end                                                                                                                                                                                                         

                    % Is the user uncertain about the ID?                                                                                                                                                                       
                    if user_id_neurons(user_id_i(i)).annotation_confidence <= 0.5                                                                                                                                               
                        id_neurons{i} = [id_neurons{i} ' ?'];                                                                                                                                                                   
                    end                                                                                                                                                                                                         
                end                                                                                                                                                                                                             

                % Add the neuron birth times.                                                                                                                                                                                   
                if Program.GUIPreferences.instance().is_show_birth_times                                                                                                                                                        

                    % Initialize the neuron info.                                                                                                                                                                               
                    neuron_data = app.hermaphrodite_neurons;                                                                                                                                                                    
                    if contains(lower(app.worm.sex), 'xo')                                                                                                                                                                      
                        neuron_data = app.male_neurons;                                                                                                                                                                         
                    end                                                                                                                                                                                                         

                    % Add birth times to the ID'd neurons.                                                                                                                                                                      
                    [~, id_i, ~] = intersect(neuron_data.names, id_neuron_names);                                                                                                                                               
                    if ~isempty(id_i)                                                                                                                                                                                           
                        id_stages = neuron_data.birth_stages(id_i);                                                                                                                                                             
                        id_neurons = cellfun(@(x,y) ...                                                                                                                                                                         
                            sprintf('%s (%s)', x, y), ...                                                                                                                                                                       
                            id_neurons, id_stages, ...                                                                                                                                                                          
                            'UniformOutput', false);                                                                                                                                                                            
                    end                                                                                                                                                                                                         

                    % Add birth times to the ID'd neurons.                                                                                                                                                                      
                    [~, unid_i, ~] = intersect(neuron_data.names, unid_neurons);                                                                                                                                                
                    if ~isempty(unid_i)                                                                                                                                                                                         
                        unid_stages = neuron_data.birth_stages(unid_i);                                                                                                                                                         
                        unid_neurons = cellfun(@(x,y) ...                                                                                                                                                                       
                            sprintf('%s (%s)', x, y), ...                                                                                                                                                                       
                            unid_neurons, unid_stages, ...                                                                                                                                                                      
                            'UniformOutput', false);                                                                                                                                                                            
                    end                                                                                                                                                                                                         
                end                                                                                                                                                                                                             

                % Update the GUI.                                                                                                                                                                                               
                black = [0,0,0];                                                                                                                                                                                                
                gray = [0.95,0.95,0.95];                                                                                                                                                                                        
                app.IDdLabel.BackgroundColor = gray;                                                                                                                                                                            
                app.IDdLabel.FontColor = black;                                                                                                                                                                                 
                app.UnIDdLabel.BackgroundColor = gray;                                                                                                                                                                          
                app.UnIDdLabel.FontColor = black;                                                                                                                                                                               
                app.IDdLabel.Text = sprintf('ID''d = %d', length(id_neurons));                                                                                                                                                  
                app.IDdNeuronsListBox.Items = id_neurons;                                                                                                                                                                       
                app.UnIDdLabel.Text = sprintf('Un-ID''d = %d', length(unid_neurons));                                                                                                                                           
                app.UnIDdNeuronsListBox.Items = unid_neurons;                                                                                                                                                                   
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function UpdateSexNeuronLists(app)                                                                                                                                                                                      
            %% Update the neuron lists.                                                                                                                                                                                         
                                                                                                                                                                                                                                
            % Is the worm a male?                                                                                                                                                                                               
            is_male = contains(lower(app.worm.sex), 'xo');                                                                                                                                                                      

            % Find the male tail node.                                                                                                                                                                                          
            tree = app.GanglionTree;                                                                                                                                                                                            
            names = {tree.Children.Text};                                                                                                                                                                                       
            male_i = find(strcmp(names,'Male Tail'));                                                                                                                                                                           

            % Add the male tail.                                                                                                                                                                                                
            if is_male && isempty(male_i)                                                                                                                                                                                       

                % Unselect all ganglia & neurons.                                                                                                                                                                               
                app.GanglionTree.SelectedNodes = [];                                                                                                                                                                            
                UnselectNeuron(app);                                                                                                                                                                                            

                % Remove the hermaphrodite tail.                                                                                                                                                                                
                hermaphrodite_i = find(strcmp(names,'Tail'));                                                                                                                                                                   
                if ~isempty(hermaphrodite_i)                                                                                                                                                                                    
                    tree.Children(hermaphrodite_i).delete();                                                                                                                                                                    
                end                                                                                                                                                                                                             

                % Add the male tail.                                                                                                                                                                                            
                app.neuron_info = Neurons.Male;                                                                                                                                                                                 
                ganglia = uitreenode(tree,'Text','Male Tail');                                                                                                                                                                  
                uitreenode(ganglia,'Text','Pre-Anal (L)');                                                                                                                                                                      
                uitreenode(ganglia,'Text','Pre-Anal (R)');                                                                                                                                                                      
                uitreenode(ganglia,'Text','Rays (L)');                                                                                                                                                                          
                uitreenode(ganglia,'Text','Rays (R)');                                                                                                                                                                          
                uitreenode(ganglia,'Text','Dorso-Rectal');                                                                                                                                                                      
                uitreenode(ganglia,'Text','Cloacal (L)');                                                                                                                                                                       
                uitreenode(ganglia,'Text','Cloacal (R)');                                                                                                                                                                       
                uitreenode(ganglia,'Text','Lumbar (L)');                                                                                                                                                                        
                uitreenode(ganglia,'Text','Lumbar (R)');                                                                                                                                                                        
                UpdateNeuronLists(app);                                                                                                                                                                                         

                % Remove the male tail.                                                                                                                                                                                         
            elseif ~is_male && ~isempty(male_i)                                                                                                                                                                                 

                % Unselect all ganglia & neurons.                                                                                                                                                                               
                app.GanglionTree.SelectedNodes = [];                                                                                                                                                                            
                UnselectNeuron(app);                                                                                                                                                                                            

                % Remove the male tail.                                                                                                                                                                                         
                tree.Children(male_i).delete();                                                                                                                                                                                 
                UpdateNeuronLists(app);                                                                                                                                                                                         

                % Add the hermaphrodite tail.                                                                                                                                                                                   
                app.neuron_info = Neurons.Hermaphrodite;                                                                                                                                                                        
                ganglia = uitreenode(tree,'Text','Tail');                                                                                                                                                                       
                uitreenode(ganglia,'Text','Pre-Anal');                                                                                                                                                                          
                uitreenode(ganglia,'Text','Dorso-Rectal');                                                                                                                                                                      
                uitreenode(ganglia,'Text','Lumbar (L)');                                                                                                                                                                        
                uitreenode(ganglia,'Text','Lumbar (R)');                                                                                                                                                                        
                UpdateNeuronLists(app);                                                                                                                                                                                         
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function neurons = GetParentNodeNeurons(app, node)                                                                                                                                                                      
            %% Get the neurons associated with this parent node.                                                                                                                                                                

            neurons = [];                                                                                                                                                                                                       
            switch node.Text                                                                                                                                                                                                    
                case 'Pharynx'                                                                                                                                                                                                  
                    neurons = app.neuron_info.getAnteriorPharynx();                                                                                                                                                             
                    neurons = union(neurons, app.neuron_info.getPosteriorPharynx());                                                                                                                                            
                case 'Head'                                                                                                                                                                                                     
                    neurons = app.neuron_info.getLeftAnteriorGanglion();                                                                                                                                                        
                    neurons = union(neurons, app.neuron_info.getRightAnteriorGanglion());                                                                                                                                       
                    neurons = union(neurons, app.neuron_info.getDorsalGanglion());                                                                                                                                              
                    neurons = union(neurons, app.neuron_info.getLeftLateralGanglion());                                                                                                                                         
                    neurons = union(neurons, app.neuron_info.getRightLateralGanglion());                                                                                                                                        
                    neurons = union(neurons, app.neuron_info.getVentralGanglion());                                                                                                                                             
                    neurons = union(neurons, app.neuron_info.getRetroVesicularGanglion());                                                                                                                                      
                case 'Midbody'                                                                                                                                                                                                  
                    neurons = app.neuron_info.getAnteriorMidbody();                                                                                                                                                             
                    neurons = union(neurons, app.neuron_info.getCentralMidbody());                                                                                                                                              
                    neurons = union(neurons, app.neuron_info.getPosteriorMidbody());                                                                                                                                            
                    neurons = union(neurons, app.neuron_info.getVentralNerveCord());                                                                                                                                            
                case 'Tail'                                                                                                                                                                                                     
                    neurons = app.neuron_info.getPreAnalGanglion();                                                                                                                                                             
                    neurons = union(neurons, app.neuron_info.getDorsoRectalGanglion());                                                                                                                                         
                    neurons = union(neurons, app.neuron_info.getLeftLumbarGanglion());                                                                                                                                          
                    neurons = union(neurons, app.neuron_info.getRightLumbarGanglion());                                                                                                                                         
                case 'Male Tail'                                                                                                                                                                                                
                    neurons = app.neuron_info.getLeftPreAnalGanglion();                                                                                                                                                         
                    neurons = union(neurons, app.neuron_info.getRightPreAnalGanglion());                                                                                                                                        
                    neurons = union(neurons, app.neuron_info.getLeftRays());                                                                                                                                                    
                    neurons = union(neurons, app.neuron_info.getRightRays());                                                                                                                                                   
                    neurons = union(neurons, app.neuron_info.getDorsoRectalGanglion());                                                                                                                                         
                    neurons = union(neurons, app.neuron_info.getLeftCloacalGanglion());                                                                                                                                         
                    neurons = union(neurons, app.neuron_info.getRightCloacalGanglion());                                                                                                                                        
                    neurons = union(neurons, app.neuron_info.getLeftLumbarGanglion());                                                                                                                                          
                    neurons = union(neurons, app.neuron_info.getRightLumbarGanglion());                                                                                                                                         
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function neurons = GetChildNodeNeurons(app, node)                                                                                                                                                                       
            %% Get the neurons associated with this child node.                                                                                                                                                                 

            neurons = [];                                                                                                                                                                                                       
            switch node.Parent.Text                                                                                                                                                                                             
                case 'Pharynx'                                                                                                                                                                                                  
                    switch node.Text                                                                                                                                                                                            
                        case 'Anterior'                                                                                                                                                                                         
                            neurons = app.neuron_info.getAnteriorPharynx();                                                                                                                                                     
                        case 'Posterior'                                                                                                                                                                                        
                            neurons = app.neuron_info.getPosteriorPharynx();                                                                                                                                                    
                    end                                                                                                                                                                                                         
                case 'Head'                                                                                                                                                                                                     
                    switch node.Text                                                                                                                                                                                            
                        case 'Anterior (L)'                                                                                                                                                                                     
                            neurons = app.neuron_info.getLeftAnteriorGanglion();                                                                                                                                                
                        case 'Anterior (R)'                                                                                                                                                                                     
                            neurons = app.neuron_info.getRightAnteriorGanglion();                                                                                                                                               
                        case 'Dorsal'                                                                                                                                                                                           
                            neurons = app.neuron_info.getDorsalGanglion();                                                                                                                                                      
                        case 'Lateral (L)'                                                                                                                                                                                      
                            neurons = app.neuron_info.getLeftLateralGanglion();                                                                                                                                                 
                        case 'Lateral (R)'                                                                                                                                                                                      
                            neurons = app.neuron_info.getRightLateralGanglion();                                                                                                                                                
                        case 'Ventral'                                                                                                                                                                                          
                            neurons = app.neuron_info.getVentralGanglion();                                                                                                                                                     
                        case 'Retro-Vesicular'                                                                                                                                                                                  
                            neurons = app.neuron_info.getRetroVesicularGanglion();                                                                                                                                              
                    end                                                                                                                                                                                                         
                case 'Midbody'                                                                                                                                                                                                  
                    switch node.Text                                                                                                                                                                                            
                        case 'Anterior'                                                                                                                                                                                         
                            neurons = app.neuron_info.getAnteriorMidbody();                                                                                                                                                     
                        case 'Central'                                                                                                                                                                                          
                            neurons = app.neuron_info.getCentralMidbody();                                                                                                                                                      
                        case 'Posterior'                                                                                                                                                                                        
                            neurons = app.neuron_info.getPosteriorMidbody();                                                                                                                                                    
                        case 'Ventral Cord'                                                                                                                                                                                     
                            neurons = app.neuron_info.getVentralNerveCord();                                                                                                                                                    
                    end                                                                                                                                                                                                         
                case 'Tail'                                                                                                                                                                                                     
                    switch node.Text                                                                                                                                                                                            
                        case 'Pre-Anal'                                                                                                                                                                                         
                            neurons = app.neuron_info.getPreAnalGanglion();                                                                                                                                                     
                        case 'Dorso-Rectal'                                                                                                                                                                                     
                            neurons = app.neuron_info.getDorsoRectalGanglion();                                                                                                                                                 
                        case 'Lumbar (L)'                                                                                                                                                                                       
                            neurons = app.neuron_info.getLeftLumbarGanglion();                                                                                                                                                  
                        case 'Lumbar (R)'                                                                                                                                                                                       
                            neurons = app.neuron_info.getRightLumbarGanglion();                                                                                                                                                 
                    end                                                                                                                                                                                                         
                case 'Male Tail'                                                                                                                                                                                                
                    switch node.Text                                                                                                                                                                                            
                        case 'Pre-Anal (L)'                                                                                                                                                                                     
                            neurons = app.neuron_info.getLeftPreAnalGanglion();                                                                                                                                                 
                        case 'Pre-Anal (R)'                                                                                                                                                                                     
                            neurons = app.neuron_info.getRightPreAnalGanglion();                                                                                                                                                
                        case 'Rays (L)'                                                                                                                                                                                         
                            neurons = app.neuron_info.getLeftRays();                                                                                                                                                            
                        case 'Rays (R)'                                                                                                                                                                                         
                            neurons = app.neuron_info.getRightRays();                                                                                                                                                           
                        case 'Dorso-Rectal'                                                                                                                                                                                     
                            neurons = app.neuron_info.getDorsoRectalGanglion();                                                                                                                                                 
                        case 'Cloacal (L)'                                                                                                                                                                                      
                            neurons = app.neuron_info.getLeftCloacalGanglion();                                                                                                                                                 
                        case 'Cloacal (R)'                                                                                                                                                                                      
                            neurons = app.neuron_info.getRightCloacalGanglion();                                                                                                                                                
                        case 'Lumbar (L)'                                                                                                                                                                                       
                            neurons = app.neuron_info.getLeftLumbarGanglion();                                                                                                                                                  
                        case 'Lumbar (R)'                                                                                                                                                                                       
                            neurons = app.neuron_info.getRightLumbarGanglion();                                                                                                                                                 
                    end                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     

        function SaveUserID(app, user_ID, neuron_i)                                                                                                                                                                             
            %% Save the user ID for a neuron.                                                                                                                                                                                   

            % Depending on the question mark set the annotation confidence and the neuron name.                                                                                                                                 
            % The marker colors will change accordingly -> see Neuron.m                                                                                                                                                         
            %   Examples:                                                                                                                                                                                                       
            %       'AVR?' -> 50% confidence if it's AVR or not.                                                                                                                                                                
            %       '?' -> 0% confidence about the neuron.                                                                                                                                                                      
            %       'AVR' -> 100% confidence that it's AVR.                                                                                                                                                                     

            % Are we already saving an ID?                                                                                                                                                                                      
            if app.is_saving_ID                                                                                                                                                                                                 
                return;                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 
            app.is_saving_ID = true;                                                                                                                                                                                            

            % Are any neurons selected?                                                                                                                                                                                         
            if ~exist("neuron_i","var")                                                                                                                                                                                         
                neuron_i = app.selected_neuron;                                                                                                                                                                                 
            end                                                                                                                                                                                                                 

            app.logEvent('Main',sprintf('Saving ID %s for neuron %s...', user_ID, string(neuron_i)), 1);                                                                                                                        

            if isempty(neuron_i)                                                                                                                                                                                                
                app.is_saving_ID = false;                                                                                                                                                                                       
                return;                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 

            % Get rid of the whitespace and uppercase the ID.                                                                                                                                                                   
            user_ID = upper(strtrim(user_ID));                                                                                                                                                                                  

            % Did the user input an empty ID (e.g., "")?                                                                                                                                                                        
            if isempty(user_ID)                                                                                                                                                                                                 

                % Are we deleting the neuron's existing ID?                                                                                                                                                                     
                neuron = app.image_neurons.neurons(neuron_i).annotation;                                                                                                                                                        
                if ~isempty(neuron)                                                                                                                                                                                             
                    answer = uiconfirm(app.CELL_ID, ...                                                                                                                                                                         
                        {['"' neuron '" is ID''d!'], 'Do you want delete the ID?'}, ...                                                                                                                                         
                        'Neuron ID Deleted', 'Options', {'Yes', 'Cancel'}, ...                                                                                                                                                  
                        'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');                                                                                                                                              

                    % Keep the ID.                                                                                                                                                                                              
                    if strcmpi(answer, 'cancel')                                                                                                                                                                                
                        UnselectNeuron(app);                                                                                                                                                                                    
                        app.is_saving_ID = false;                                                                                                                                                                               
                        return;                                                                                                                                                                                                 
                    end                                                                                                                                                                                                         

                    % Delete the ID.                                                                                                                                                                                            
                    app.image_neurons.neurons(neuron_i).annotate('', 0, nan);                                                                                                                                                   
                end                                                                                                                                                                                                             


                % Save the updated IDs.                                                                                                                                                                                         
                SaveIDToFile(app);                                                                                                                                                                                              

                % Update the ID'd neuron count.                                                                                                                                                                                 
                UpdateNeuronLists(app);                                                                                                                                                                                         

                % Clear the ID field.                                                                                                                                                                                           
                UnselectNeuron(app);                                                                                                                                                                                            
                app.is_saving_ID = false;                                                                                                                                                                                       

                % Select the next neuron in need of a user ID.                                                                                                                                                                  
                if strcmp(app.NextNeuronDropDown.Value, 'Nearest')                                                                                                                                                              
                    app.SelectNearestUnIDdNeuron(neuron_i);                                                                                                                                                                     
                else                                                                                                                                                                                                            
                    app.SelectProbabilisticUnIDdNeuron(neuron_i);                                                                                                                                                               
                end                                                                                                                                                                                                             

                % Done.                                                                                                                                                                                                         
                return;                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 

            % Is the user emphasizing this neuron?                                                                                                                                                                              
            % Note: as one example, neurons may be emphasized in mutants.                                                                                                                                                       
            neuron = user_ID;                                                                                                                                                                                                   
            is_emphasized = false;                                                                                                                                                                                              
            if neuron(end) == '!'                                                                                                                                                                                               
                is_emphasized = true;                                                                                                                                                                                           
                neuron = neuron(1:(end-1));                                                                                                                                                                                     
            end                                                                                                                                                                                                                 

            % Is the user uncertain about the ID?                                                                                                                                                                               
            is_uncertain = false;                                                                                                                                                                                               
            if neuron(end) == '?'                                                                                                                                                                                               
                is_uncertain = true;                                                                                                                                                                                            
                neuron = neuron(1:(end-1));                                                                                                                                                                                     
            end                                                                                                                                                                                                                 

            % Is the neuron ON/OFF?                                                                                                                                                                                             
            [neuron, is_on] = app.neuron_info.stripOnOff(neuron);                                                                                                                                                               

            % Did the user input a correct neuron name?                                                                                                                                                                         
            % Note: emphasized neurons can exist as duplicates.                                                                                                                                                                 
            if is_emphasized                                                                                                                                                                                                    
                if ~Neurons.Hermaphrodite.isCell(neuron) && ...                                                                                                                                                                 
                        ~Neurons.Male.isCell(neuron)                                                                                                                                                                            
                    uiconfirm(app.CELL_ID, ...                                                                                                                                                                                  
                        {['"' neuron '" is not a cell type!'], ...                                                                                                                                                              
                        ['Did you forget the "L" or "R"?']}, ...                                                                                                                                                                
                        'Neuron Name Error', 'Options', {'Ok'}, ...                                                                                                                                                             
                        'DefaultOption', 1, 'CancelOption', 1, ...                                                                                                                                                              
                        'Icon', 'error');                                                                                                                                                                                       
                    Program.GUIHandling.send_focus(app.IDEditField);                                                                                                                                                            
                    app.is_saving_ID = false;                                                                                                                                                                                   
                    return;                                                                                                                                                                                                     
                    %else                                                                                                                                                                                                       
                    % Add the emphasis back onto the neuron.                                                                                                                                                                    
                    % Note: this has 2 side effects                                                                                                                                                                             
                    % 1. We can assign the neuron name more than once.                                                                                                                                                          
                    % 2. The neuron assignment will not affect our predictions.                                                                                                                                                 
                    %neuron = [neuron '!'];                                                                                                                                                                                     
                end                                                                                                                                                                                                             
            elseif ~app.neuron_info.isCell(neuron)                                                                                                                                                                              
                uiconfirm(app.CELL_ID, ...                                                                                                                                                                                      
                    {['"' neuron '" is not a neuron in ' app.worm.sex ' animals!'], ...                                                                                                                                         
                    ['Did you forget the "L" or "R", or is the sex incorrect?']}, ...                                                                                                                                           
                    'Neuron Name Error', 'Options', {'Ok'}, ...                                                                                                                                                                 
                    'DefaultOption', 1, 'CancelOption', 1, ...                                                                                                                                                                  
                    'Icon', 'error');                                                                                                                                                                                           
                Program.GUIHandling.send_focus(app.IDEditField);                                                                                                                                                                
                app.is_saving_ID = false;                                                                                                                                                                                       
                return;                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 

            % Has the neuron already been assigned?                                                                                                                                                                             
            prev_assigned_neuron = app.image_neurons.find_user_id_neuron(neuron);                                                                                                                                               
            if ~isempty(prev_assigned_neuron) && ~is_emphasized && ...                                                                                                                                                          
                    app.image_neurons.neurons(neuron_i) ~= prev_assigned_neuron                                                                                                                                                 

                % Are we re-assigning the ID?                                                                                                                                                                                   
                answer = uiconfirm(app.CELL_ID, ...                                                                                                                                                                             
                    {['"' neuron '" was assigned to a different neuron!'], ...                                                                                                                                                  
                    'Do you want delete the previously assigned ID?'}, ...                                                                                                                                                      
                    'Neuron ID Re-Assigned', 'Options', {'Yes', 'Cancel'}, ...                                                                                                                                                  
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');                                                                                                                                                  

                % Cancel.                                                                                                                                                                                                       
                if strcmpi(answer, 'cancel')                                                                                                                                                                                    
                    Program.GUIHandling.send_focus(app.IDEditField);                                                                                                                                                            
                    app.is_saving_ID = false;                                                                                                                                                                                   
                    return;                                                                                                                                                                                                     
                end                                                                                                                                                                                                             

                % Delete the previous ID.                                                                                                                                                                                       
                prev_assigned_neuron.annotate('', 0, nan);                                                                                                                                                                      
            end                                                                                                                                                                                                                 

            % What did the user enter?                                                                                                                                                                                          
            app.image_neurons.neurons(neuron_i).annotation = neuron;                                                                                                                                                            
            app.image_neurons.neurons(neuron_i).is_annotation_on = is_on;                                                                                                                                                       
            app.image_neurons.neurons(neuron_i).is_emphasized = is_emphasized;                                                                                                                                                  
            if is_uncertain                                                                                                                                                                                                     
                app.image_neurons.neurons(neuron_i).annotation_confidence = 0.5;                                                                                                                                                
            else                                                                                                                                                                                                                
                app.image_neurons.neurons(neuron_i).annotation_confidence = 1;                                                                                                                                                  
            end                                                                                                                                                                                                                 
            if ~isempty(app.image_neurons.neurons(neuron_i).probabilistic_probs)                                                                                                                                                
                app.image_neurons.neurons(neuron_i).deterministic_id = neuron;                                                                                                                                                  
                app.image_neurons.neurons(neuron_i).probabilistic_ids{1} = neuron;                                                                                                                                              
                app.image_neurons.neurons(neuron_i).probabilistic_probs(1) = 1;                                                                                                                                                 
                app.image_neurons.neurons(neuron_i).probabilistic_probs(2:end) = 0;                                                                                                                                             
            end                                                                                                                                                                                                                 
            if ~isempty(app.image_neurons.neurons(neuron_i).rank)                                                                                                                                                               
                app.image_neurons.rank_neuron_max(neuron_i);                                                                                                                                                                    
            end                                                                                                                                                                                                                 

            % Update the auto-ID model.                                                                                                                                                                                         
            UpdateAutoIDModel(app);                                                                                                                                                                                             

            % Update ID confidences                                                                                                                                                                                             
            UpdateNeuronIDConfidence(app);                                                                                                                                                                                      

            % Save the updated IDs.                                                                                                                                                                                             
            SaveIDToFile(app);                                                                                                                                                                                                  

            % Update the ID'd neuron count.                                                                                                                                                                                     
            UpdateNeuronLists(app);                                                                                                                                                                                             

            % Done.                                                                                                                                                                                                             
            UnselectNeuron(app);                                                                                                                                                                                                
            app.is_saving_ID = false;                                                                                                                                                                                           

            % Select the next neuron in need of a user ID.                                                                                                                                                                      
            if strcmp(app.NextNeuronDropDown.Value, 'Nearest')                                                                                                                                                                  
                app.SelectNearestUnIDdNeuron(neuron_i);                                                                                                                                                                         
            else                                                                                                                                                                                                                
                app.SelectProbabilisticUnIDdNeuron(neuron_i);                                                                                                                                                                   
            end                                                                                                                                                                                                                 

            % Show the annotated neuron's ID.                                                                                                                                                                                   
            position = app.image_neurons.neurons(neuron_i).position;                                                                                                                                                            
            DrawImageLabels(app, position, {user_ID}, 1);                                                                                                                                                                       
        end                                                                                                                                                                                                                     


        function SaveAutoID(app, neuron)                                                                                                                                                                                        
            %% Save the auto ID for a neuron.                                                                                                                                                                                   

            % Are we already saving an ID?                                                                                                                                                                                      
            if app.is_saving_ID                                                                                                                                                                                                 
                return;                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 
            app.is_saving_ID = true;                                                                                                                                                                                            

            % Are any neurons selected?                                                                                                                                                                                         
            neuron_i = app.selected_neuron;                                                                                                                                                                                     
            if isempty(neuron_i)                                                                                                                                                                                                
                app.is_saving_ID = false;                                                                                                                                                                                       
                return;                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 

            % Has the neuron already been assigned?                                                                                                                                                                             
            prev_assigned_neuron = app.image_neurons.find_user_id_neuron(neuron);                                                                                                                                               
            if ~isempty(prev_assigned_neuron) && app.image_neurons.neurons(neuron_i) ~= prev_assigned_neuron                                                                                                                    

                % Are we re-assigning the ID?                                                                                                                                                                                   
                answer = uiconfirm(app.CELL_ID, ...                                                                                                                                                                             
                    {['"' neuron '" was assigned to a different neuron!'], ...                                                                                                                                                  
                    'Do you want delete the previously assigned ID?'}, ...                                                                                                                                                      
                    'Neuron ID Re-Assigned', 'Options', {'Yes', 'Cancel'}, ...                                                                                                                                                  
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');                                                                                                                                                  

                % Cancel.                                                                                                                                                                                                       
                if strcmpi(answer, 'cancel')                                                                                                                                                                                    
                    Program.GUIHandling.send_focus(app.IDEditField);                                                                                                                                                            
                    app.is_saving_ID = false;                                                                                                                                                                                   
                    return;                                                                                                                                                                                                     
                end                                                                                                                                                                                                             

                % Delete the previous ID.                                                                                                                                                                                       
                prev_assigned_neuron.annotate('', 0, nan);                                                                                                                                                                      
            end                                                                                                                                                                                                                 

            app.logEvent('Main',sprintf('Saving automatic ID %s for neuron %s...', neuron, string(neuron_i)), 1);                                                                                                               

            % Annotate the neuron.                                                                                                                                                                                              
            app.image_neurons.neurons(neuron_i).annotation = neuron;                                                                                                                                                            
            app.image_neurons.neurons(neuron_i).is_annotation_on = nan;                                                                                                                                                         
            app.image_neurons.neurons(neuron_i).annotation_confidence = 1;                                                                                                                                                      
            app.image_neurons.neurons(neuron_i).is_emphasized = false;                                                                                                                                                          
            if ~isempty(app.image_neurons.neurons(neuron_i).probabilistic_probs)                                                                                                                                                
                app.image_neurons.neurons(neuron_i).deterministic_id = neuron;                                                                                                                                                  
                app.image_neurons.neurons(neuron_i).probabilistic_ids{1} = neuron;                                                                                                                                              
                app.image_neurons.neurons(neuron_i).probabilistic_probs(1) = 1;                                                                                                                                                 
                app.image_neurons.neurons(neuron_i).probabilistic_probs(2:end) = 0;                                                                                                                                             
            end                                                                                                                                                                                                                 
            if ~isempty(app.image_neurons.neurons(neuron_i).rank)                                                                                                                                                               
                app.image_neurons.rank_neuron_max(neuron_i);                                                                                                                                                                    
            end                                                                                                                                                                                                                 

            % Update the auto-ID model.                                                                                                                                                                                         
            UpdateAutoIDModel(app);                                                                                                                                                                                             

            % Save the updated IDs.                                                                                                                                                                                             
            SaveIDToFile(app);                                                                                                                                                                                                  

            % Update the ID'd neuron count.                                                                                                                                                                                     
            UpdateNeuronLists(app);                                                                                                                                                                                             

            % Done.                                                                                                                                                                                                             
            UnselectNeuron(app);                                                                                                                                                                                                
            app.is_saving_ID = false;                                                                                                                                                                                           

            % Select the next neuron in need of a user ID.                                                                                                                                                                      
            if strcmp(app.NextNeuronDropDown.Value, 'Nearest')                                                                                                                                                                  
                app.SelectNearestUnIDdNeuron(neuron_i);                                                                                                                                                                         
            else                                                                                                                                                                                                                
                app.SelectProbabilisticUnIDdNeuron(neuron_i);                                                                                                                                                                   
            end                                                                                                                                                                                                                 

            % Show the annotated neuron's ID.                                                                                                                                                                                   
            position = app.image_neurons.neurons(neuron_i).position;                                                                                                                                                            
            DrawImageLabels(app, position, {neuron}, 1);                                                                                                                                                                        
        end                                                                                                                                                                                                                     


        function UpdateAutoIDModel(app)                                                                                                                                                                                         
            %% Update the model for neuron ID.                                                                                                                                                                                  

            % Update the log_likelihood, uncertainty, and neuron ranks,                                                                                                                                                         
            % then update the list of neurons accordingly.                                                                                                                                                                      
            worm = app.worm;                                                                                                                                                                                                    
            if Program.GUIPreferences.instance().is_autoID_updates && ...                                                                                                                                                       
                    Methods.AutoId.isBodyID(worm.sex, worm.body)                                                                                                                                                                
                Methods.AutoId.instance().update_id(...                                                                                                                                                                         
                    app.image_neurons, worm);                                                                                                                                                                                   
            end                                                                                                                                                                                                                 
            DrawAutoIDList(app);                                                                                                                                                                                                
        end                                                                                                                                                                                                                     


        function UpdateNeuronIDConfidence(app)                                                                                                                                                                                  
            %% Update the neuorn ID confidences.                                                                                                                                                                                

            % Update the confidence computations that each labeled neuron                                                                                                                                                       
            % is correctly identified.                                                                                                                                                                                          
            worm = app.worm;                                                                                                                                                                                                    
            if ~Methods.AutoId.isBodyID(worm.sex, worm.body)                                                                                                                                                                    
                return;                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 
            Methods.AutoId.instance().update_confidences(...                                                                                                                                                                    
                app.image_neurons, worm);                                                                                                                                                                                       
        end                                                                                                                                                                                                                     

                                                                                                                                                                                                                                
        function SaveIDToFile(app)                                                                                                                                                                                              
            %% Save the IDs to a file.                                                                                                                                                                                          

            version = Program.ProgramInfo.version;                                                                                                                                                                              
            mp_params = app.mp_params;                                                                                                                                                                                          
            mp_params.k = length(app.image_neurons.neurons);                                                                                                                                                                    
            neurons = app.image_neurons;                                                                                                                                                                                        
            save(app.id_file, 'version', 'neurons', 'mp_params');                                                                                                                                                               
            %sp = app.image_neurons.to_superpixel();                                                                                                                                                                            
            %save(app.id_file, 'version', 'sp', 'mp_params');                                                                                                                                                                   
        end                                                                                                                                                                                                                     


        function z = getZSlice(app, z)                                                                                                                                                                                          
            %% Get the z slice (correcting for the flipped axis).                                                                                                                                                               

            if app.image_prefs.is_Z_flip                                                                                                                                                                                        
                z = size(app.image_data,3) - z + 1;                                                                                                                                                                             
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function lMoving(~, source)                                                                                                                                                                                             
            % Function that responds to the movement of the time sliders                                                                                                                                                        

            % Always keep the position rounded to 1 decimal place                                                                                                                                                               
            source.Position(1,1) = round(source.Position(1,1),1);                                                                                                                                                               
            source.Position(2,1) = round(source.Position(2,1),1);                                                                                                                                                               
            % Add any other action here triggered by the movement of the sliders                                                                                                                                                
        end                                                                                                                                                                                                                     


        function PickNeuron(app,caller,tag)                                                                                                                                                                                     
            if ~exist('tag','var')                                                                                                                                                                                              
                tag = 'selector';                                                                                                                                                                                               
                g_parent = app.CELL_ID;                                                                                                                                                                                         
                parent = caller.Parent;                                                                                                                                                                                         
            elseif strcmp(tag,'col') | strcmp(tag,'row')                                                                                                                                                                        
                'tbd';                                                                                                                                                                                                          
            else                                                                                                                                                                                                                
                tag = 'add_graph';                                                                                                                                                                                              
                g_parent = caller.Parent;                                                                                                                                                                                       
                parent = caller.Parent.Parent;                                                                                                                                                                                  
            end                                                                                                                                                                                                                 

            set(caller,'Visible','Off');                                                                                                                                                                                        

            % Create DropDown                                                                                                                                                                                                   
            DropDown = uidropdown(g_parent);                                                                                                                                                                                    
            DropDown.Parent = parent;                                                                                                                                                                                           
            DropDown.Position = caller.Position;                                                                                                                                                                                
            DropDown.Items = {'Sample Neuron #1','Sample Neuron #2','Sample Neuron #3'};                                                                                                                                        
            DropDown.Placeholder = 'Select Neuron';                                                                                                                                                                             
            DropDown.Tag = tag;                                                                                                                                                                                                 
            DropDown.ValueChangedFcn = createCallbackFcn(app, @DispNeuron, true);                                                                                                                                               
        end                                                                                                                                                                                                                     


        function LoadNeuron(app,caller,event,tag)                                                                                                                                                                               

            if ~exist('tag','var')                                                                                                                                                                                              
                % Create new                                                                                                                                                                                                    
                new_grid = uigridlayout(caller.Parent.Parent);                                                                                                                                                                  
                delete(caller.Parent)                                                                                                                                                                                           
                new_grid.ColumnWidth = {'1x', '1x', '1x', '1x', '1x'};                                                                                                                                                          
                new_grid.RowHeight = {'1x', '1x', '1x', '1x', '1x', '1x'};                                                                                                                                                      
                                                                                                                                                                                                                                
                % Create UIAxes                                                                                                                                                                                                 
                new_axes = uiaxes(new_grid);                                                                                                                                                                                    
                title(new_axes, event.Value)                                                                                                                                                                                    
                xlabel(new_axes, 't')                                                                                                                                                                                           
                ylabel(new_axes, 'Ca+')                                                                                                                                                                                         
                zlabel(new_axes, 'Z')                                                                                                                                                                                           
                new_axes.Layout.Row = [1 6];                                                                                                                                                                                    
                new_axes.Layout.Column = [1 5];                                                                                                                                                                                 
                                                                                                                                                                                                                                
                % Create AddNeuronButton                                                                                                                                                                                        
                new_button = uibutton(new_grid, 'push');                                                                                                                                                                        
                new_button.Layout.Row = 2;                                                                                                                                                                                      
                new_button.Layout.Column = 5;                                                                                                                                                                                   
                new_button.Text = 'Add Neuron';                                                                                                                                                                                 
                new_button.Tag = 'in_graph';                                                                                                                                                                                    
                new_button.ButtonPushedFcn = createCallbackFcn(app, @DispNeuron, true);                                                                                                                                         
            else                                                                                                                                                                                                                
                targAx = caller.Parent.Children(2).Children(1);                                                                                                                                                                 
                title(targAx, [targAx.Title.String,', ', event.Value])                                                                                                                                                          
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function CreateNeuron(app,caller,event)                                                                                                                                                                                 
            % Create TL_Panel                                                                                                                                                                                                   
            new_panel = uipanel(app.VolTraceHelperGrid);                                                                                                                                                                        

            if grid_space(1) == max(size(app.VolTraceHelperGrid.ColumnWidth))                                                                                                                                                   
                new_panel.Layout.Column = grid_space(2)+1;                                                                                                                                                                      
            else                                                                                                                                                                                                                
                new_panel.Layout.Column = grid_space;                                                                                                                                                                           
            end                                                                                                                                                                                                                 

            if grid_space(2) == max(size(app.VolTraceHelperGrid.RowHeight))                                                                                                                                                     
                new_panel.Layout.Row = grid_space(1)+1;                                                                                                                                                                         
            end                                                                                                                                                                                                                 

            % Create TL_Grid                                                                                                                                                                                                    
            new_grid = uigridlayout(app.TL_Panel);                                                                                                                                                                              
            new_grid.ColumnWidth = {'1x'};                                                                                                                                                                                      
            new_grid.RowHeight = {'1x'};                                                                                                                                                                                        

            % Create TL_Button                                                                                                                                                                                                  
            new_button = uibutton(app.TL_Grid, 'push');                                                                                                                                                                         
            new_button.ButtonPushedFcn = createCallbackFcn(app, @AddNeuron, true);                                                                                                                                              
            new_button.FontWeight = 'bold';                                                                                                                                                                                     
            new_button.Layout.Row = 1;                                                                                                                                                                                          
            new_button.Layout.Column = 1;                                                                                                                                                                                       
            new_button.Text = 'Add Neuron';                                                                                                                                                                                     
        end                                                                                                                                                                                                                     
                                                                                                                                                                                                                                

        function initBrowser(app, neuron)                                                                                                                                                                                       
            if ~exist('neuron','var')                                                                                                                                                                                           
                % Create the 1D axes                                                                                                                                                                                            
                targ_neuron = {['null']};                                                                                                                                                                                       
            else                                                                                                                                                                                                                
                targ_neuron = {[neuron]};                                                                                                                                                                                       
            end                                                                                                                                                                                                                 


            if size(properties(app.activity_module), 1) == 2                                                                                                                                                                    
                if all(size(app.activity_module.timestamps) > 0)                                                                                                                                                                
                    app.VolTrace = stackedplot(app.VolTraceHelperGrid,app.activity_table,targ_neuron, 'XVariable', 'timestamps');                                                                                               
                else                                                                                                                                                                                                            
                    app.VolTrace = stackedplot(app.VolTraceHelperGrid,app.activity_table,targ_neuron);                                                                                                                          
                end                                                                                                                                                                                                             
            else                                                                                                                                                                                                                
                app.VolTrace = stackedplot(app.VolTraceHelperGrid,app.activity_table,targ_neuron);                                                                                                                              
            end                                                                                                                                                                                                                 

            drawnow                                                                                                                                                                                                             
            app.formatStack()                                                                                                                                                                                                   

        end                                                                                                                                                                                                                     


        function formatStack(app)                                                                                                                                                                                               
            sample_neuron = keys(app.neuron_activity_by_name);                                                                                                                                                                  
            length = max(size(app.neuron_activity_by_name(sample_neuron{1})));                                                                                                                                                  

            app.VolTrace.GridVisible = 'on';                                                                                                                                                                                    
            app.VolTrace.XLabel = 't';                                                                                                                                                                                          
            app.VolTrace.Layout.Row = [1, size(app.VolTraceHelperGrid.RowHeight,2)];                                                                                                                                            
            app.VolTrace.Layout.Column = [1,2];                                                                                                                                                                                 

            if ~isempty(app.framerate)                                                                                                                                                                                          
                % Add a listener for changes in 'XLim' property                                                                                                                                                                 
                stAxes = findobj(app.VolTrace.NodeChildren, 'Type','Axes');                                                                                                                                                     
                addlistener(stAxes, 'XLim', 'PostSet', @(src, event) updateXTicks(app, length));                                                                                                                                
                                                                                                                                                                                                                                
                % Initial setup                                                                                                                                                                                                 
                updateXTicks(app, app.framerate);                                                                                                                                                                               
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function updateXTicks(app, framerate)                                                                                                                                                                                   
            % Get the x-axis data                                                                                                                                                                                               
            xData = app.VolTrace.XData;                                                                                                                                                                                         
                                                                                                                                                                                                                                
            % Determine the type of x-axis data                                                                                                                                                                                 
            if all(xData >= 1e9) % Assuming Unix timestamps                                                                                                                                                                     
                timeInSeconds = (xData - xData(1)) / 1000; % Convert to seconds from milliseconds                                                                                                                               
            elseif max(xData) <= length(xData) % Assuming frame counts                                                                                                                                                          
                timeInSeconds = xData / framerate; % Convert to seconds using framerate                                                                                                                                         
            else % Assuming seconds                                                                                                                                                                                             
                timeInSeconds = xData;                                                                                                                                                                                          
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            % Convert to MM:SS format                                                                                                                                                                                           
            minutes = floor(timeInSeconds / 60);                                                                                                                                                                                
            seconds = mod(timeInSeconds, 60);                                                                                                                                                                                   
            tickLabels = arrayfun(@(m, s) sprintf('%02d:%02d', m, s), minutes, seconds, 'UniformOutput', false);                                                                                                                
                                                                                                                                                                                                                                
            % Find the underlying axes and set the tick values and labels                                                                                                                                                       
            stAxes = findobj(app.VolTrace.NodeChildren, 'Type','Axes');                                                                                                                                                         
            set(stAxes, 'XTick', xData, 'XTickLabel', tickLabels);                                                                                                                                                              
        end                                                                                                                                                                                                                     
                                                                                                                                                                                                                                

        function updateBrowser(app, neuron, subplot)                                                                                                                                                                            
            try                                                                                                                                                                                                                 
                if ~any(contains(app.VolTrace.DisplayVariables,neuron))                                                                                                                                                         
                    if ~exist('subplot','var')                                                                                                                                                                                  
                        subplot = 1;                                                                                                                                                                                            
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                    old_xlim = app.VolTrace.XLimits;                                                                                                                                                                            
                                                                                                                                                                                                                                
                    if ~isempty(app.VolTrace.DisplayVariables)                                                                                                                                                                  
                        subplot_vars = app.VolTrace.DisplayVariables(subplot);                                                                                                                                                  
                        new_vars = [];                                                                                                                                                                                          
                        for n=1:size(app.VolTrace.DisplayVariables(subplot),2)                                                                                                                                                  
                            if ~strcmp(subplot_vars{n},'null')                                                                                                                                                                  
                                new_vars = [string(new_vars),string(subplot_vars{n})];                                                                                                                                          
                            end                                                                                                                                                                                                 
                        end                                                                                                                                                                                                     
                        new_vars = [new_vars,string(neuron)];                                                                                                                                                                   
                        app.VolTrace.DisplayVariables(subplot) = {new_vars};                                                                                                                                                    
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                    subplot_idx = size(app.VolTrace.DisplayVariables,2)-(subplot-1);                                                                                                                                            
                    subplot_idx = max(1,size(app.VolTrace.LineProperties,1)-(subplot_idx-1));                                                                                                                                   
                    target_color = rgb2hsv(app.neuron_info.getNeuronColor(neuron));                                                                                                                                             
                                                                                                                                                                                                                                
                    if strcmp(neuron(end),'L')                                                                                                                                                                                  
                        target_color(2) = target_color(2)*0.6;                                                                                                                                                                  
                        target_style = '--';                                                                                                                                                                                    
                    else                                                                                                                                                                                                        
                        target_color(3) = target_color(3)*0.6;                                                                                                                                                                  
                        target_style = '-';                                                                                                                                                                                     
                    end                                                                                                                                                                                                         
                    target_color = hsv2rgb(target_color);                                                                                                                                                                       
                                                                                                                                                                                                                                
                    if isempty(app.color_tracker) || length(app.color_tracker) < subplot_idx || isempty(app.color_tracker{subplot_idx})                                                                                         
                        app.color_tracker{subplot_idx} = [target_color];                                                                                                                                                        
                        app.style_tracker{subplot_idx} = [{target_style}];                                                                                                                                                      
                    else                                                                                                                                                                                                        
                        app.color_tracker{subplot_idx} = [app.color_tracker{subplot_idx}; target_color];                                                                                                                        
                        app.style_tracker{subplot_idx} = [app.style_tracker{subplot_idx}, target_style];                                                                                                                        
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                    app.VolTrace.LineProperties(subplot_idx).LineStyle = app.style_tracker{subplot_idx};                                                                                                                        
                    app.VolTrace.LineProperties(subplot_idx).Color = app.color_tracker{subplot_idx};                                                                                                                            
                    app.VolTrace.XLimits = old_xlim;                                                                                                                                                                            
                    drawnow;                                                                                                                                                                                                    
                                                                                                                                                                                                                                
                    app.drawStimuli();                                                                                                                                                                                          
                else                                                                                                                                                                                                            
                    uiconfirm(app.CELL_ID, "Neuron already plotted.", "Error!");                                                                                                                                                
                end                                                                                                                                                                                                             
            catch                                                                                                                                                                                                               
                uiconfirm(app.CELL_ID, sprintf("Neuron %s has no activity to load.", neuron), "Error!")                                                                                                                         
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        %% Processing Tab Functions                                                                                                                                                                                             

        function drawProcImage(app)                                                                                                                                                                                             
            %Program.GUIHandling.histogram_handler(app, 'reset');                                                                                                                                                               

            Program.Handlers.loading.start('Rendering volume...');                                                                                                                                                              
            Program.Routines.Processing.render();                                                                                                                                                                               
                                                                                                                                                                                                                                
            if app.ProcPreviewZslowCheckBox.Value                                                                                                                                                                               
                Program.Handlers.loading.start('Drawing cursor...');                                                                                                                                                            
                app.DrawProcLines(x, y, z, nz);                                                                                                                                                                                 
            end                                                                                                                                                                                                                 

            Program.Handlers.loading.done();                                                                                                                                                                                    
        end                                                                                                                                                                                                                     

        function [x, y, z, nx, ny, nz, nc, target_frame] = ProcLoadVideo(app)                                                                                                                                                   

            x = min(max(round(app.video_info.ny-app.ySlider.Value), 1), app.ySlider.Limits(2));                                                                                                                                 
            y = min(max(round(app.proc_xSlider.Value), 1), app.proc_xSlider.Limits(2));                                                                                                                                         
            z = min(max(round(app.proc_hor_zSlider.Value), 1), app.proc_hor_zSlider.Limits(2));                                                                                                                                 
            t = round(app.proc_tSlider.Value);                                                                                                                                                                                  

            nx = app.video_info.nx;                                                                                                                                                                                             
            ny = app.video_info.ny;                                                                                                                                                                                             
            nz = app.video_info.nz;                                                                                                                                                                                             
            nc = app.video_info.nc;                                                                                                                                                                                             

            if t > app.video_info.nt || t < 0                                                                                                                                                                                   
                uiconfirm(app.CELL_ID,'Target frame exceeds video frame count.','Error');                                                                                                                                       
                return                                                                                                                                                                                                          
            elseif z > app.video_info.nz || z < 0                                                                                                                                                                               
                uiconfirm(app.CELL_ID,'Target slice exceeds video slice count.','Error');                                                                                                                                       
                return                                                                                                                                                                                                          
            end                                                                                                                                                                                                                 

            target_frame = app.retrieve_frame(t);                                                                                                                                                                               
            target_frame(target_frame < app.ProcNoiseThresholdKnob.Value) = 0;                                                                                                                                                  

            for c=1:nc                                                                                                                                                                                                          
                channel_gamma = sprintf("%s_GammaEditField", Program.GUIHandling.pos_prefixes{c});                                                                                                                              
                target_frame(:, :, :, c, :) = imadjustn(target_frame(:, :, :, c, :), [], [], app.(channel_gamma).Value);                                                                                                        
            end                                                                                                                                                                                                                 

            actions = fieldnames(app.flags);                                                                                                                                                                                    
            for a=1:length(actions)                                                                                                                                                                                             
                action = actions{a};                                                                                                                                                                                            
                if app.flags.(action) == 1                                                                                                                                                                                      
                    target_frame = Methods.ChunkyMethods.apply_vol(app, action, target_frame);                                                                                                                                  
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 

            if any(ismember(actions, {'crop', 'ds'}))                                                                                                                                                                           
                nx = size(target_frame, 2);                                                                                                                                                                                     
                ny = size(target_frame, 1);                                                                                                                                                                                     
                nz = size(target_frame, 3);                                                                                                                                                                                     
            end                                                                                                                                                                                                                 

            target_frame = app.ApplyHistSliders(target_frame, nc);                                                                                                                                                              

            if app.ProcShowMIPCheckBox.Value                                                                                                                                                                                    
                xy = squeeze(max(target_frame,[],3));                                                                                                                                                                           
            else                                                                                                                                                                                                                
                xy = squeeze(target_frame(:,:,z,:,:));                                                                                                                                                                          
            end                                                                                                                                                                                                                 

            image(xy, 'Parent', app.proc_xyAxes);                                                                                                                                                                               

            if app.ProcPreviewZslowCheckBox.Value                                                                                                                                                                               
                xz = squeeze(target_frame(:,y,:,:,:));                                                                                                                                                                          
                yz = squeeze(target_frame(x,:,:,:,:));                                                                                                                                                                          

                image(flipud(rot90(yz)), 'Parent', app.proc_xzAxes);                                                                                                                                                            
                image(xz, 'Parent', app.proc_yzAxes);                                                                                                                                                                           
            end                                                                                                                                                                                                                 

        end                                                                                                                                                                                                                     

        function [x, y, z, nx, ny, nz, nc, image_data] = ProcLoadVol(app)                                                                                                                                                       
            vol_size = size(app.proc_image, 'data');                                                                                                                                                                            
            x = min(max(round(vol_size(1)-app.proc_ySlider.Value)), app.proc_ySlider.Limits(2));                                                                                                                                
            y = min(max(round(app.proc_xSlider.Value)), app.proc_xSlider.Limits(2));                                                                                                                                            
            z = min(max(round(app.proc_hor_zSlider.Value)), app.proc_hor_zSlider.Limits(2));                                                                                                                                    

            if app.ProcShowMIPCheckBox.Value || app.ProcPreviewZslowCheckBox.Value                                                                                                                                              
                image_data = app.proc_image.data(:, :, :, :);                                                                                                                                                                   
            else                                                                                                                                                                                                                
                image_data = app.proc_image.data(:, :, z, :);                                                                                                                                                                   
            end                                                                                                                                                                                                                 

            nx = size(image_data, 2);                                                                                                                                                                                           
            ny = size(image_data, 1);                                                                                                                                                                                           
            nz = size(image_data, 3);                                                                                                                                                                                           
            nc = size(image_data, 4);                                                                                                                                                                                           

            image_data = app.ApplyHistSliders(image_data, nc);                                                                                                                                                                  

            actions = fieldnames(app.flags);                                                                                                                                                                                    
            for a=1:length(actions)                                                                                                                                                                                             
                action = actions{a};                                                                                                                                                                                            
                if app.flags.(action) == 1                                                                                                                                                                                      
                    image_data = Methods.ChunkyMethods.apply_vol(app, action, image_data);                                                                                                                                      
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 

            [image_data, image_view] = app.DrawProcData(image_data);                                                                                                                                                            
            threshold_value = (app.ProcNoiseThresholdKnob.Value/double(max(image_data, [], 'all')))*double(max(image_view, [], 'all'));                                                                                         
            image_data(image_data < app.ProcNoiseThresholdKnob.Value) = 0;                                                                                                                                                      
            image_view(image_view < threshold_value) = 0;                                                                                                                                                                       

            if any(ismember(actions, {'crop', 'ds'}))                                                                                                                                                                           
                nx = size(image_view, 2);                                                                                                                                                                                       
                ny = size(image_view, 1);                                                                                                                                                                                       
                nz = size(image_view, 3);                                                                                                                                                                                       
            end                                                                                                                                                                                                                 

            if app.ProcShowMIPCheckBox.Value                                                                                                                                                                                    
                xy = squeeze(max(image_view,[],3));                                                                                                                                                                             
            else                                                                                                                                                                                                                
                if size(image_view, 3)>1                                                                                                                                                                                        
                    xy = squeeze(image_view(:,:,z,:,:));                                                                                                                                                                        
                else                                                                                                                                                                                                            
                    xy = squeeze(image_view);                                                                                                                                                                                   
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 

            image(xy, 'Parent', app.proc_xyAxes);                                                                                                                                                                               

            if app.ProcPreviewZslowCheckBox.Value                                                                                                                                                                               
                xz = squeeze(image_view(:,y,:,:,:));                                                                                                                                                                            
                yz = squeeze(image_view(x,:,:,:,:));                                                                                                                                                                            

                image(flipud(rot90(yz)), 'Parent', app.proc_xzAxes);                                                                                                                                                            
                image(xz, 'Parent', app.proc_yzAxes);                                                                                                                                                                           
            end                                                                                                                                                                                                                 

        end                                                                                                                                                                                                                     

        function target_frame = ApplyHistSliders(app, target_frame, nc)                                                                                                                                                         
            for n=1:nc                                                                                                                                                                                                          
                channel_slider = sprintf("%s_hist_slider", Program.GUIHandling.pos_prefixes{n});                                                                                                                                
                target_frame = app.SetChanLimit(n, app.(channel_slider), target_frame);                                                                                                                                         
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     

        function newFrame = SetChanLimit(app, ch_idx, slider, oldFrame)                                                                                                                                                         
            if ((slider.Value(1) ~= 0) | (slider.Value(2) ~= app.ProcNoiseThresholdKnob.Limits(2)))                                                                                                                             
                newFrame = oldFrame;                                                                                                                                                                                            
                channel = oldFrame(:, :, :, ch_idx);                                                                                                                                                                            
                channel(channel<slider.Value(1)) = slider.Value(1);                                                                                                                                                             
                channel(channel>slider.Value(2)) = slider.Value(2);                                                                                                                                                             
                newFrame(:, :, :, ch_idx) = channel;                                                                                                                                                                            
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     

        function DrawProcLines(app, x, y, z, nz)                                                                                                                                                                                
            app.proc_xy_yline = yline(app.proc_xyAxes, x, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);                                                                                                                          
            app.proc_yz_yline = yline(app.proc_yzAxes, x, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);                                                                                                                          
            app.proc_xz_yline = yline(app.proc_xzAxes, app.proc_xzAxes.YLim(2)*(z/nz), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);                                                                                             

            app.proc_xy_xline = xline(app.proc_xyAxes, y, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);                                                                                                                          
            app.proc_yz_xline = xline(app.proc_yzAxes, app.proc_yzAxes.XLim(2)*(z/nz), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);                                                                                             
            app.proc_xz_xline = xline(app.proc_xzAxes, y, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);                                                                                                                          
        end                                                                                                                                                                                                                     

    end                                                                                                                                                                                                                         
                                                                                                                                                                                                                                
    methods (Access = public)                                                                                                                                                                                                   
                                                                                                                                                                                                                                
        %% NeuroPAL ID Functions                                                                                                                                                                                                
        function UpdateFromChild(app, image_data, image_neurons, image_gamma)                                                                                                                                                   
                                                                                                                                                                                                                                
            if ~exist('image_data','var')                                                                                                                                                                                       
                image_data = app.image_data;                                                                                                                                                                                    
            end                                                                                                                                                                                                                 

            if ~exist('image_neurons','var')                                                                                                                                                                                    
                image_neurons = app.image_neurons;                                                                                                                                                                              
            end                                                                                                                                                                                                                 

            if ~exist('image_gamma','var')                                                                                                                                                                                      
                image_gamma = app.image_gamma;                                                                                                                                                                                  
            end                                                                                                                                                                                                                 

            if size(app.image_data,3) ~= size(image_data,3)                                                                                                                                                                     

                num_z_slices = size(image_data, 3);                                                                                                                                                                             

                if num_z_slices <= 1                                                                                                                                                                                            
                    uialert(app.CELL_ID, 'The image is not a volume!', ...                                                                                                                                                      
                        'Image Not a Volume', 'Icon', 'error');                                                                                                                                                                 
                    return;                                                                                                                                                                                                     
                end                                                                                                                                                                                                             

                Program.Helpers.configure_main_zslider(app, num_z_slices);                                                                                                                                                      
                                                                                                                                                                                                                                
                %{                                                                                                                                                                                                              
                % Change the neuron properties (mean and covariance) according                                                                                                                                                  
                % to user selected parameters.                                                                                                                                                                                  
                for neuron=1:length(app.image_neurons.neurons)                                                                                                                                                                  
                    app.image_neurons.neurons(neuron).position = ...                                                                                                                                                            
                        app.image_neurons.neurons(neuron).position/( app.image_neurons.neurons(neuron).position/(size(app.image_data,3)/size(image_data,3)) );                                                                  
                    app.image_neurons.neurons(neuron).covariance = ...                                                                                                                                                          
                        app.image_neurons.neurons(neuron).covariance/( app.image_neurons.neurons(neuron).position/(size(app.image_data,3)/size(image_data,3)) );                                                                
                end                                                                                                                                                                                                             
                %}                                                                                                                                                                                                              

            else                                                                                                                                                                                                                
                app.image_neurons = image_neurons;                                                                                                                                                                              
            end                                                                                                                                                                                                                 

            app.image_data = image_data;                                                                                                                                                                                        
            app.image_gamma = image_gamma;                                                                                                                                                                                      
                                                                                                                                                                                                                                
            % Save the image & neurons.                                                                                                                                                                                         
            data = app.image_data;                                                                                                                                                                                              
            info = app.image_info;                                                                                                                                                                                              
            info.scale = app.image_um_scale;                                                                                                                                                                                    
            save(app.image_file, 'data', 'info', '-append');                                                                                                                                                                    
            SaveIDToFile(app);                                                                                                                                                                                                  

            % Draw the neurons.                                                                                                                                                                                                 
            app.ZSlider.Value = round(app.ZSlider.Limits(2)/2);                                                                                                                                                                 
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);                                                                                                                                                               
            app.DrawImageData();                                                                                                                                                                                                
        end                                                                                                                                                                                                                     


        function ImpNeurons(app,auto_neurons)                                                                                                                                                                                   
            for r = 1:size(auto_neurons, 1)                                                                                                                                                                                     
                app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...                                                                                              
                [auto_neurons(r,1) auto_neurons(r,2) auto_neurons(r,3)], app.mp_params.hnsz, app.image_um_scale');                                                                                                              
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function eyedrop(app, Red, Green, Blue, Background, White, GFP, Lyso, Gut)                                                                                                                                              
                                                                                                                                                                                                                                
            color_struct = struct('red',Red,'green',Green,'blue',Blue,'background',Background,'white',White,'GFP',GFP,'Lyso',Lyso,'Gut',Gut);                                                                                   
            imwrite(squeeze(max(app.image_view,[],3)), 'cache.png');                                                                                                                                                            
            im = imread('cache.png');                                                                                                                                                                                           
            color_roi = drawpoint(app.MaxProjection);                                                                                                                                                                           
            pos = round(color_roi.Position);                                                                                                                                                                                    
                                                                                                                                                                                                                                
            for color = fieldnames(color_struct)'                                                                                                                                                                               
                if color_struct.(color{1})                                                                                                                                                                                      
                    uiwait(msgbox(sprintf('In the maximum projection, click on the region that best represents %s.', color{1})))                                                                                                
                    color_values = impixel(im,pos(2), pos(1));                                                                                                                                                                  
                    color_struct.(color{1}) = color_values;                                                                                                                                                                     
                    delete(color_roi)                                                                                                                                                                                           
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            delete('cache.png')                                                                                                                                                                                                 
                                                                                                                                                                                                                                
        end                                                                                                                                                                                                                     


        % Check if drag & drop ended on target component.                                                                                                                                                                       
        function target = grab_land(app, figure, pos, parent_class, parent_tag, class, tag)                                                                                                                                     
            try                                                                                                                                                                                                                 
                comp_array = findobj(figure, '-depth', inf,'-function','Position', @(x) (pos(1)>x(1)&pos(1)<(x(1)+x(3))&pos(2)>x(2)&pos(2)<(x(2)+x(4))));                                                                       
                mid_idx = find(arrayfun(@(y) isa(y, parent_class)&strcmp(y.Tag, parent_tag), comp_array), 5);                                                                                                                   

                if ~isempty(mid_idx)                                                                                                                                                                                            
                    middleman = comp_array(mid_idx);                                                                                                                                                                            
                    pos(1) = pos(1)-middleman.Position(1);                                                                                                                                                                      
                    pos(2) = pos(2)-middleman.Position(2)-5;                                                                                                                                                                    

                    deep_array = findobj(middleman.Children.Children, '-depth', inf,'-function','Position', @(x) (pos(1)>x(1)&pos(1)<(x(1)+x(3))&pos(2)>x(2)&pos(2)<(x(2)+x(4))));                                              
                    target_idx = find(arrayfun(@(y) isa(y, class)&strcmp(y.Tag, tag), deep_array), 5);                                                                                                                          

                    if ~isempty(target_idx)                                                                                                                                                                                     
                        target = deep_array(target_idx);                                                                                                                                                                        
                    else                                                                                                                                                                                                        
                        target = [];                                                                                                                                                                                            
                    end                                                                                                                                                                                                         
                else                                                                                                                                                                                                            
                    target = [];                                                                                                                                                                                                
                end                                                                                                                                                                                                             
            catch                                                                                                                                                                                                               
                target = [];                                                                                                                                                                                                    
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        % Manage click & drag events.                                                                                                                                                                                           
        function DragManager (app, mode, event)                                                                                                                                                                                 
            if app.DisplayNeuronActivityMenu.Checked == 1                                                                                                                                                                       
                pos = get(app.CELL_ID, 'CurrentPoint');                                                                                                                                                                         
                switch mode                                                                                                                                                                                                     
                    case 'down'                                                                                                                                                                                                 
                        target = app.grab_land(app.NeuroPALIDTab, pos, 'matlab.ui.container.Panel', 'side-panel', 'matlab.ui.control.ListBox', 'neuron-selector');                                                              
                        if ~isempty(target)                                                                                                                                                                                     
                            app.HoverLabel.Position = [pos(1)-app.HoverLabel.Position(3)/2 pos(2)+1 app.HoverLabel.Position(3) app.HoverLabel.Position(4)];                                                                     
                            app.HoverLabel.Text = char(target.Value);                                                                                                                                                           
                            app.HoverLabel.Position(3) = app.HoverLabel.FontSize*size(target.Value,2);                                                                                                                          
                            app.CELL_ID.WindowButtonMotionFcn = @(src, event) app.DragManager('move', event);                                                                                                                   
                            app.HoverLabel.Visible = "on";                                                                                                                                                                      
                        end                                                                                                                                                                                                     
                    case 'move'                                                                                                                                                                                                 
                        app.HoverLabel.Position = [pos(1)-app.HoverLabel.Position(3)/2 pos(2)+1 app.HoverLabel.Position(3) app.HoverLabel.Position(4)];                                                                         
                    case 'up'                                                                                                                                                                                                   
                        if strcmp(app.HoverLabel.Visible,'on')                                                                                                                                                                  
                            app.CELL_ID.WindowButtonMotionFcn = @(src, event) 1+1;                                                                                                                                              
                            set(app.HoverLabel, 'Visible', 'off');                                                                                                                                                              
                                                                                                                                                                                                                                
                            target = app.grab_land(app.NeuroPALIDTab, pos, 'matlab.ui.container.Tab', 'neuron-activity-tab', 'matlab.ui.container.GridLayout', 'browser_trace');                                                
                            if ~isempty(target)                                                                                                                                                                                 
                                target.Children(1).Units = 'pixels';                                                                                                                                                            
                                total_x = target.Children(1).InnerPosition;                                                                                                                                                     
                                num_plots = size(target.Children(1).DisplayVariables,2);                                                                                                                                        
                                                                                                                                                                                                                                
                                selected_plot = 0;                                                                                                                                                                              
                                y_divs = total_x(4) / num_plots;                                                                                                                                                                
                                for n=1:num_plots                                                                                                                                                                               
                                    x = total_x;                                                                                                                                                                                
                                    y = y_divs * (n-1);                                                                                                                                                                         
                                    x(2) = x(2) + y;                                                                                                                                                                            
                                    x(4) = y_divs;                                                                                                                                                                              
                                    % sprintf('Cursor y: %d\nSubplot #%d y: %.2f through %.2f', pos(2), n, x(2), x(2) + x(4))                                                                                                   
                                    if (pos(1)>x(1)&pos(1)<(x(1)+x(3))&pos(2)>x(2)&pos(2)<(x(2)+x(4)))                                                                                                                          
                                        selected_plot = num_plots - (n-1);                                                                                                                                                      
                                        break                                                                                                                                                                                   
                                    end                                                                                                                                                                                         
                                end                                                                                                                                                                                             

                                % Strip all non-alphanumeric characters from HoverLabel.Text                                                                                                                                    
                                cleanText = regexprep(app.HoverLabel.Text, '[^a-zA-Z0-9]', '');                                                                                                                                 
                                                                                                                                                                                                                                
                                % Pass the cleaned text to updateBrowser                                                                                                                                                        
                                app.updateBrowser(cleanText, selected_plot);                                                                                                                                                    
                            end                                                                                                                                                                                                 
                        end                                                                                                                                                                                                     
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function fullPath = checkFileExistence(app, fileName)                                                                                                                                                                   
            if exist(fileName, 'file') == 2                                                                                                                                                                                     
                fullPath = fileName;                                                                                                                                                                                            
            else                                                                                                                                                                                                                
                fullPath = 'False';                                                                                                                                                                                             
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     

        function load_neurons_from_nwb(app, nwb_data)                                                                                                                                                                           
                d = uiprogressdlg(app.CELL_ID,'Title','Importing neurons from NWB file...','Indeterminate','off');                                                                                                              
                app.logEvent('Main','Placing neuron dots...', 1)                                                                                                                                                                
                if any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.keys, 'NeuroPALSegmentation'))                                                                                                             
                    neurons = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').voxel_mask.data.load();                                                 
                    neuron_ids = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.get('ID_labels').data.load();                             
                    if size(neuron_ids,1) == size(neurons.x, 1)                                                                                                                                                                 
                        full_ids = 1;                                                                                                                                                                                           
                    else                                                                                                                                                                                                        

                        if any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.keys, 'ID_labels_index'))                          
                            id_indices = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.get('ID_labels_index').data.load();               
                        elseif any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.keys, 'ROI_IDs'))                              
                            id_indices = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.get('ROI_IDs').data.load();                       
                        end                                                                                                                                                                                                     
                        full_ids = max(cellfun(@(c) numel(c), neuron_ids)) > 1;                                                                                                                                                 
                        id_indices(find(id_indices == 0)) = 1;                                                                                                                                                                  
                    end                                                                                                                                                                                                         
                elseif any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.keys, 'VolumeSegmentation'))                                                                                                               
                    neurons = nwb_data.processing.get('NeuroPAL').dynamictable.get('VolumeSegmentation').voxel_mask.data.load();                                                                                                
                    full_ids = 2;                                                                                                                                                                                               
                elseif any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.keys, 'NeuroPALNeurons'))                                                                                                                  
                    neurons = nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').voxel_mask.data.load();                                                                                                   
                    neuron_ids = nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.get('ID_labels').data.load();                                                                               
                    if any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.keys, 'ID_labels_index'))                                                                                
                        id_indices = nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.get('ID_labels_index').data.load();                                                                     
                    elseif any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.keys, 'ROI_IDs'))                                                                                    
                        id_indices = nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.get('ROI_IDs').data.load();                                                                             
                    end                                                                                                                                                                                                         
                    full_ids = 1;                                                                                                                                                                                               
                elseif any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.keys, 'ImageSegmentation'))                                                                                                            
                    neurons = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('ImageSegmentation').planesegmentation.get('PlaneSegmentation').voxel_mask.data.load();                                                  
                    neurons = struct('x', neurons(:, 2), 'y', neurons(:, 1), 'z', neurons(:, 3));                                                                                                                               
                    neuron_ids = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('SegmentationLabels').labels.load();                                                                                                  
                    full_ids = 1;                                                                                                                                                                                               
                else                                                                                                                                                                                                            
                    uiconfirm(app.CELL_ID, "No neurons found. You shouldn't be seeing this message!");                                                                                                                          
                end                                                                                                                                                                                                             
                                                                                                                                                                                                                                
                % Iterate over each row                                                                                                                                                                                         
                for i = 1:length(neurons.x)                                                                                                                                                                                     
                    d.Value = i / length(neurons.x);                                                                                                                                                                            
                    app.logEvent('Main',sprintf('Loading neuron #%s...', string(i)), 0);                                                                                                                                        
                                                                                                                                                                                                                                
                    if full_ids == 1                                                                                                                                                                                            
                        name = neuron_ids{i};                                                                                                                                                                                   
                    elseif full_ids == 2                                                                                                                                                                                        
                        name = neurons.ID{i};                                                                                                                                                                                   
                    else                                                                                                                                                                                                        
                        if i ~= 1                                                                                                                                                                                               
                            last_name = id_indices(i-1)+1;                                                                                                                                                                      
                        else                                                                                                                                                                                                    
                            last_name = 1;                                                                                                                                                                                      
                        end                                                                                                                                                                                                     
                                                                                                                                                                                                                                
                        if last_name == id_indices(i) && i > 1                                                                                                                                                                  
                            name = '';                                                                                                                                                                                          
                        else                                                                                                                                                                                                    
                            name = join(neuron_ids(last_name:id_indices(i)));                                                                                                                                                   
                            name = name{1};                                                                                                                                                                                     
                        end                                                                                                                                                                                                     
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                    name = upper(erase(name," "));                                                                                                                                                                              
                    x = double(neurons.x(i));                                                                                                                                                                                   
                    y = double(neurons.y(i));                                                                                                                                                                                   
                    z = double(neurons.z(i));                                                                                                                                                                                   
                                                                                                                                                                                                                                
                    app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...                                                                                          
                        [y x z], [13, 13, 3], app.image_um_scale');                                                                                                                                                             
                                                                                                                                                                                                                                
                    if app.neuron_info.isCell(name)                                                                                                                                                                             
                        app.logEvent('Main',sprintf('-Neuron #%s is %s...', string(i), name), 0);                                                                                                                               

                        app.is_saving_ID = true;                                                                                                                                                                                
                        [neuron, is_on] = app.neuron_info.stripOnOff(name);                                                                                                                                                     
                        app.image_neurons.neurons(i).annotation = char(neuron);                                                                                                                                                 
                        app.image_neurons.neurons(i).is_annotation_on = is_on;                                                                                                                                                  

                        if name(end) == '!' | name(end) == '?'                                                                                                                                                                  
                            app.image_neurons.neurons(i).is_emphasized = true;                                                                                                                                                  
                            app.image_neurons.neurons(i).annotation_confidence = 0.5;                                                                                                                                           
                        else                                                                                                                                                                                                    
                            app.image_neurons.neurons(i).annotation_confidence = 1;                                                                                                                                             
                        end                                                                                                                                                                                                     
                                                                                                                                                                                                                                
                        %{                                                                                                                                                                                                      
                        if ~isempty(app.image_neurons.neurons(i).probabilistic_probs)                                                                                                                                           
                            app.image_neurons.neurons(i).deterministic_id = neuron;                                                                                                                                             
                            app.image_neurons.neurons(i).probabilistic_ids{1} = neuron;                                                                                                                                         
                            app.image_neurons.neurons(i).probabilistic_probs(1) = 1;                                                                                                                                            
                            app.image_neurons.neurons(i).probabilistic_probs(2:end) = 0;                                                                                                                                        
                        end                                                                                                                                                                                                     
                        %}                                                                                                                                                                                                      
                                                                                                                                                                                                                                
                        if ~isempty(app.image_neurons.neurons(i).rank)                                                                                                                                                          
                            app.image_neurons.rank_neuron_max(i);                                                                                                                                                               
                        end                                                                                                                                                                                                     
                                                                                                                                                                                                                                
                        app.is_saving_ID = false;                                                                                                                                                                               
                    else                                                                                                                                                                                                        
                        fprintf('%s (#%.f) is not a valid neuron.\n', name, i)                                                                                                                                                  
                    end                                                                                                                                                                                                         
                end                                                                                                                                                                                                             
                                                                                                                                                                                                                                
                % Update the auto-ID model.                                                                                                                                                                                     
                UpdateAutoIDModel(app);                                                                                                                                                                                         
                                                                                                                                                                                                                                
                % Update the ID'd neuron count.                                                                                                                                                                                 
                UpdateNeuronLists(app);                                                                                                                                                                                         
                                                                                                                                                                                                                                
                % Save the updated IDs.                                                                                                                                                                                         
                SaveIDToFile(app);                                                                                                                                                                                              
                                                                                                                                                                                                                                
                close(d)                                                                                                                                                                                                        
        end                                                                                                                                                                                                                     


        function checkedNodes = dependencyChecker(app, mode, checkedNodes, dependency, node)                                                                                                                                    
            switch mode                                                                                                                                                                                                         
                case 'python'                                                                                                                                                                                                   
                    pip_check = system(['pip show',dependency]);                                                                                                                                                                
                    if ~pip_check                                                                                                                                                                                               
                        checkedNodes{end+1} = node;                                                                                                                                                                             
                    end                                                                                                                                                                                                         
                case 'toolbox'                                                                                                                                                                                                  
                    toolboxes = ver;                                                                                                                                                                                            
                    toolboxes = toolboxes.Name;                                                                                                                                                                                 
                    if contains(toolboxes, dependency)                                                                                                                                                                          
                        checkedNodes{end+1} = node;                                                                                                                                                                             
                    end                                                                                                                                                                                                         
                case 'folder'                                                                                                                                                                                                   
                    if isfolder(dependency)                                                                                                                                                                                     
                        checkedNodes{end+1} = node;                                                                                                                                                                             
                    end                                                                                                                                                                                                         
                case 'var'                                                                                                                                                                                                      
                    if ~isempty(dependency)                                                                                                                                                                                     
                        checkedNodes{end+1} = node;                                                                                                                                                                             
                    end                                                                                                                                                                                                         
                case 'file'                                                                                                                                                                                                     
                    if isfile(dependency)                                                                                                                                                                                       
                        checkedNodes{end+1} = node;                                                                                                                                                                             
                    end                                                                                                                                                                                                         
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     

        %% Tracking Functions                                                                                                                                                                                                   
                                                                                                                                                                                                                                
        function [struct, arr] = load_h5(app, path)                                                                                                                                                                             
            info = h5info(path);                                                                                                                                                                                                

            if ~any(strcmp({info.Datasets.Name}, 'data')) && ~any(strcmp({info.Datasets.Name}, 'times'))                                                                                                                        
                uiconfirm(app.CELL_ID,'.h5 file structure is invalid. Please refer to documentation.','Error');                                                                                                                 
                return                                                                                                                                                                                                          
            end                                                                                                                                                                                                                 

            size_info = h5info(path,'/data');                                                                                                                                                                                   
            struct = {};                                                                                                                                                                                                        
            struct.file = size_info.Filename;           % Path to file                                                                                                                                                          
            struct.nx = size_info.Dataspace.Size(2);    % Height                                                                                                                                                                
            struct.ny = size_info.Dataspace.Size(1);    % Width                                                                                                                                                                 
            struct.nz = size_info.Dataspace.Size(3);    % Slice count                                                                                                                                                           
            struct.nc = size_info.Dataspace.Size(4);    % Channel count                                                                                                                                                         
            struct.nt = size_info.Dataspace.Size(5);  % Frame count                                                                                                                                                             
            struct.aspect_ratio = struct.ny/struct.nx;  % Aspect Ratio                                                                                                                                                          
            struct.ChunkSize = size_info.ChunkSize;     % Chunk Size                                                                                                                                                            
            struct.cached = 1;                          % Frames cached                                                                                                                                                         
            struct.bitDepth = h5read(path,'/data',[1 1 1 1 1], size_info.ChunkSize);                                                                                                                                            

            app.video_info = struct;                                                                                                                                                                                            

            if struct.nt < 10                                                                                                                                                                                                   
                uiconfirm(app.CELL_ID,'Your video contains too few frames for filters to be applied during activity extraction. ZephIR will skip these when you run the extraction, which may affect your results.','Warning!');
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function load_nwb(app, path)                                                                                                                                                                                            
            app.nwb_file = nwbRead(path);                                                                                                                                                                                       

            if any(ismember(app.nwb_file.acquisition.keys, 'CalciumImageSeries'))                                                                                                                                               
                app.nwb_mod = app.nwb_file.acquisition.get('CalciumImageSeries').data;                                                                                                                                          
            elseif any(ismember(app.nwb_file.processing.keys,'GCaMPVideo'))                                                                                                                                                     
                app.nwb_mod = app.nwb_file.processing.get('GCaMPVideo').nwbdatainterface.get('CalciumImageSeries').data;                                                                                                        
            else                                                                                                                                                                                                                
                uiconfirm(app.NeuronActivityTracingUIFigure, sprintf('Could not find video in %s', path), 'Error');                                                                                                             
                delete(app);                                                                                                                                                                                                    
                return                                                                                                                                                                                                          
            end                                                                                                                                                                                                                 

            data_pipe = app.nwb_mod.internal;                                                                                                                                                                                   
            dims = data_pipe.dims;                                                                                                                                                                                              

            struct = {};                                                                                                                                                                                                        
            struct.file = path;                         % Path to file                                                                                                                                                          
            struct.nx = dims(4);                        % Height                                                                                                                                                                
            struct.ny = dims(3);                        % Width                                                                                                                                                                 
            struct.nz = dims(2);                        % Slice count                                                                                                                                                           
            struct.nc = dims(1);                        % Channel count                                                                                                                                                         
            struct.nt = dims(5);                        % Frame count                                                                                                                                                           
            struct.aspect_ratio = struct.ny/struct.nx;  % Aspect Ratio                                                                                                                                                          
            struct.cached = 1;                          % Frames cached                                                                                                                                                         
            struct.bitDepth = app.nwb_mod.internal.config.dataType;                                                                                                                                                             

            app.video_info = struct;                                                                                                                                                                                            

            if struct.nt < 10                                                                                                                                                                                                   
                uiconfirm(app.CELL_ID,'Your video contains too few frames for filters to be applied during activity extraction. ZephIR will skip these when you run the extraction, which may affect your results.','Warning!');
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     
                                                                                                                                                                                                                                

        function load_nd2(app, path)                                                                                                                                                                                            
            app.nd2_file = BioformatsImage(path);                                                                                                                                                                               

            struct = {};                                                                                                                                                                                                        
            struct.file = path;                         % Path to file                                                                                                                                                          
            struct.ny = app.nd2_file.height;            % Height                                                                                                                                                                
            struct.nx = app.nd2_file.width;             % Width                                                                                                                                                                 
            struct.nz = app.nd2_file.sizeZ;             % Slice count                                                                                                                                                           
            struct.nc = app.nd2_file.sizeC;             % Channel count                                                                                                                                                         
            struct.nt = app.nd2_file.sizeT;             % Frame count                                                                                                                                                           
            struct.aspect_ratio = struct.ny/struct.nx;  % Aspect Ratio                                                                                                                                                          
            struct.cached = 1;                          % Frames cached                                                                                                                                                         

            app.video_info = struct;                                                                                                                                                                                            

            if struct.nt < 10                                                                                                                                                                                                   
                uiconfirm(app.CELL_ID,'Your video contains too few frames for filters to be applied during activity extraction. ZephIR will skip these when you run the extraction, which may affect your results.','Warning!');
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     

        function load_tif(app, path)                                                                                                                                                                                            
            app.tif_file = BioformatsImage(path);                                                                                                                                                                               

            struct = {};                                                                                                                                                                                                        
            struct.file = path;                         % Path to file                                                                                                                                                          
            struct.ny = app.tif_file.height;            % Height                                                                                                                                                                
            struct.nx = app.tif_file.width;             % Width                                                                                                                                                                 
            struct.nz = app.tif_file.sizeZ;             % Slice count                                                                                                                                                           
            struct.nc = app.tif_file.sizeC;             % Channel count                                                                                                                                                         
            struct.nt = app.tif_file.sizeT;             % Frame count                                                                                                                                                           
            struct.aspect_ratio = struct.ny/struct.nx;  % Aspect Ratio                                                                                                                                                          
            struct.cached = 1;                          % Frames cached                                                                                                                                                         

            app.video_info = struct;                                                                                                                                                                                            

            if struct.nt < 10                                                                                                                                                                                                   
                uiconfirm(app.CELL_ID,'Your video contains too few frames for filters to be applied during activity extraction. ZephIR will skip these when you run the extraction, which may affect your results.','Warning!');
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function convert_HDF5(app, path)                                                                                                                                                                                        
            [filepath, name, format] = fileparts(path);                                                                                                                                                                         

            title = sprintf('Building data structure from %s...', path);                                                                                                                                                        
            d = uiprogressdlg(app.CELL_ID,'Title',title,'Indeterminate','off');                                                                                                                                                 

            switch format                                                                                                                                                                                                       
                case '.h5'                                                                                                                                                                                                      
                    % This should never happen, but we're thorough.                                                                                                                                                             
                    check = uiconfirm(app.CELL_ID,"File is already in HDF5 format. If you're seeing this, something has gone very wrong.","Error!","Options", ["OK"]);                                                          
                case '.nwb'                                                                                                                                                                                                     
                    % Read NWB file                                                                                                                                                                                             
                    whole_file = nwbRead(path);                                                                                                                                                                                 

                    if any(ismember(whole_file.acquisition.keys, 'CalciumImageSeries'))                                                                                                                                         
                        gc_data = whole_file.acquisition.get('CalciumImageSeries').data;                                                                                                                                        
                    elseif any(ismember(whole_file.processing.keys,'GCaMPVideo'))                                                                                                                                               
                        gc_data = whole_file.processing.get('GCaMPVideo').nwbdatainterface.get('CalciumImageSeries').data;                                                                                                      
                    else                                                                                                                                                                                                        
                        uiconfirm(app.CELL_ID, sprintf('Could not find video in %s', path), 'Error');                                                                                                                           
                        delete(app);                                                                                                                                                                                            
                        return                                                                                                                                                                                                  

                        %{                                                                                                                                                                                                      
                        check = uiconfirm(app.CELL_ID, sprintf('Could not find video in %s', path), 'Error','Options',["Manually specify volume","Cancel"]);                                                                    
                                                                                                                                                                                                                                
                        switch check                                                                                                                                                                                            
                            case 'Cancel'                                                                                                                                                                                       
                                delete(app);                                                                                                                                                                                    
                                return                                                                                                                                                                                          
                            otherwise                                                                                                                                                                                           
                                gc_data = app.searchNWB(whole_file);                                                                                                                                                            
                        end                                                                                                                                                                                                     
                        %}                                                                                                                                                                                                      
                    end                                                                                                                                                                                                         

                    data_pipe = gc_data.internal;                                                                                                                                                                               

                    % Get dimensions                                                                                                                                                                                            
                    bitDepth = str2num(data_pipe.dataType(5:end));                                                                                                                                                              
                    dims = data_pipe.dims;                                                                                                                                                                                      
                    nt = dims(5);                                                                                                                                                                                               
                    nc = dims(1);                                                                                                                                                                                               
                    nz = dims(2);                                                                                                                                                                                               
                    ny = dims(3);                                                                                                                                                                                               
                    nx = dims(4);                                                                                                                                                                                               

                    % Create the HDF5 dataset                                                                                                                                                                                   
                    h5create(fullfile(filepath, 'data.h5'), '/data', [ny nx nz nc nt], 'ChunkSize', [ny nx nz nc 1], 'Datatype', 'uint8');                                                                                      
                    h5create(fullfile(filepath, 'data.h5'), '/times', nt, 'Datatype', 'double');                                                                                                                                

                    switch bitDepth                                                                                                                                                                                             
                        case {8, 16, 32, 64}                                                                                                                                                                                    
                            scale_factor = double(intmax('uint8')) / double(intmax(sprintf('uint%.f', bitDepth)));                                                                                                              
                        case 12                                                                                                                                                                                                 
                            scale_factor = double(intmax('uint8')) / 4096;                                                                                                                                                      
                        case 20                                                                                                                                                                                                 
                            scale_factor = double(intmax('uint8')) / 1048576;                                                                                                                                                   
                        case 24                                                                                                                                                                                                 
                            scale_factor = double(intmax('uint8')) / 16777216;                                                                                                                                                  
                        otherwise                                                                                                                                                                                               
                            error('Bit depth %s failed conversion to uint8.', data_pipe.dataType);                                                                                                                              
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                    d.Message = 'Processing frames...';                                                                                                                                                                         
                    d.Value = 0;                                                                                                                                                                                                

                    for t = 1:nt                                                                                                                                                                                                
                        d.Value = t / nt;                                                                                                                                                                                       

                        % Read chunk                                                                                                                                                                                            
                        %chunk = uint8(gc_data(1:nc, 1:nz, 1:ny, 1:nx, t) * scale_factor);                                                                                                                                      
                        chunk = uint8(gc_data(1:nc, 1:nz, 1:ny, 1:nx, t));                                                                                                                                                      
                        chunk = permute(chunk, [3 4 2 1 5]);                                                                                                                                                                    

                        % Write chunk to HDF5                                                                                                                                                                                   
                        h5write(fullfile(filepath, 'data.h5'), '/data', chunk, [1, 1, 1, 1, t], [ny nx nz nc 1]);                                                                                                               

                        if d.CancelRequested                                                                                                                                                                                    
                            break;                                                                                                                                                                                              
                        end                                                                                                                                                                                                     
                    end                                                                                                                                                                                                         
                case '.nd2'                                                                                                                                                                                                     
                    bfImg = BioformatsImage(path);                                                                                                                                                                              
                    nt = bfImg.sizeT;                                                                                                                                                                                           
                    nc = bfImg.sizeC;                                                                                                                                                                                           
                    nz = bfImg.sizeZ;                                                                                                                                                                                           
                    ny = bfImg.height;                                                                                                                                                                                          
                    nx = bfImg.width;                                                                                                                                                                                           

                    % Create the HDF5 dataset                                                                                                                                                                                   
                    h5create(fullfile(filepath, 'data.h5'), '/data', [ny nx nz nc nt], 'ChunkSize', [ny nx nz nc 1], 'Datatype', 'uint8');                                                                                      
                    h5create(fullfile(filepath, 'data.h5'), '/times', nt, 'Datatype', 'double');                                                                                                                                
                                                                                                                                                                                                                                
                    % Initialize the entire dataset array with correct dimension order                                                                                                                                          
                    all_data = zeros(ny, nx, nz, nc, nt, 'uint8');                                                                                                                                                              
                                                                                                                                                                                                                                
                    % Collect data for all time points                                                                                                                                                                          
                    channelNames = bfImg.channelNames;                                                                                                                                                                          

                    switch bfImg.bitDepth                                                                                                                                                                                       
                        case {8, 16, 32, 64}                                                                                                                                                                                    
                            scale_factor = double(intmax('uint8')) / double(intmax(sprintf('uint%.f', bfImg.bitDepth)));                                                                                                        
                        case 12                                                                                                                                                                                                 
                            scale_factor = double(intmax('uint8')) / 4096;                                                                                                                                                      
                        case 20                                                                                                                                                                                                 
                            scale_factor = double(intmax('uint8')) / 1048576;                                                                                                                                                   
                        case 24                                                                                                                                                                                                 
                            scale_factor = double(intmax('uint8')) / 16777216;                                                                                                                                                  
                        otherwise                                                                                                                                                                                               
                            error('Bit depth %.f failed conversion to uint8.', bfImg.bitDepth);                                                                                                                                 
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                    d.Message = 'Processing frames...';                                                                                                                                                                         
                    d.Value = 0;                                                                                                                                                                                                

                    for t = 1:nt                                                                                                                                                                                                
                        d.Value = t / nt;                                                                                                                                                                                       
                        for c = 1:nc                                                                                                                                                                                            
                            channel = channelNames{c};                                                                                                                                                                          
                            for z = 1:nz                                                                                                                                                                                        
                                % Read and scale the data                                                                                                                                                                       
                                plane_data = getPlane(bfImg, z, channel, t);                                                                                                                                                    
                                all_data(:, :, z, c, t) = uint8(plane_data * scale_factor);                                                                                                                                     
                            end                                                                                                                                                                                                 
                        end                                                                                                                                                                                                     
                                                                                                                                                                                                                                
                        if d.CancelRequested                                                                                                                                                                                    
                            break;                                                                                                                                                                                              
                        end                                                                                                                                                                                                     
                    end                                                                                                                                                                                                         

                    % Write the entire dataset at once to HDF5 with corrected dimension order                                                                                                                                   
                    h5write(fullfile(filepath, 'data.h5'), '/data', all_data);                                                                                                                                                  
            end                                                                                                                                                                                                                 
            close(d)                                                                                                                                                                                                            

            app.video_path = fullfile(filepath, 'data.h5');                                                                                                                                                                     
            [app.video_info, app.video_array] = app.load_h5(app.video_path);                                                                                                                                                    
        end                                                                                                                                                                                                                     


        function load_annotations(app)                                                                                                                                                                                          
            d = uiprogressdlg(app.CELL_ID,'Title','Processing results...','Indeterminate','on');                                                                                                                                
                                                                                                                                                                                                                                
            if isempty(app.video_neurons)                                                                                                                                                                                       
                app.video_neurons = struct('provenance', {}, 'worldline', {}, 'rois', {});                                                                                                                                      
            end                                                                                                                                                                                                                 

            [path, ~, ~] = fileparts(app.video_path);                                                                                                                                                                           
            results_info = h5info(fullfile(path, 'annotations.h5'));                                                                                                                                                            

            frame_results = h5read(fullfile(path, 'annotations.h5'), '/t_idx');                                                                                                                                                 
            prov_results = h5read(fullfile(path, 'annotations.h5'), '/provenance');                                                                                                                                             
            wlid_results = h5read(fullfile(path, 'annotations.h5'), '/worldline_id');                                                                                                                                           
            x_results = h5read(fullfile(path, 'annotations.h5'), '/x');                                                                                                                                                         
            y_results = h5read(fullfile(path, 'annotations.h5'), '/y');                                                                                                                                                         
            z_results = h5read(fullfile(path, 'annotations.h5'), '/z');                                                                                                                                                         
                                                                                                                                                                                                                                
            wl_idx = h5read(fullfile(path, 'worldlines.h5'), '/id');                                                                                                                                                            
            wl_name = h5read(fullfile(path, 'worldlines.h5'), '/name');                                                                                                                                                         
            wl_col = h5read(fullfile(path, 'worldlines.h5'), '/color');                                                                                                                                                         
                                                                                                                                                                                                                                
            for n=1:results_info.Datasets(1).Dataspace.Size                                                                                                                                                                     
                worldline = struct('name', {{}}, 'node', {{}}, 'color', {{}}, 'style', {{}}, 'id', {{}});                                                                                                                       
                rois = struct();                                                                                                                                                                                                
                idx = size(app.video_neurons, 2) + 1;                                                                                                                                                                           
                existing_worldline = 0;                                                                                                                                                                                         
                                                                                                                                                                                                                                
                target_wl = wl_idx(wl_idx == wlid_results(n)) + 1;                                                                                                                                                              
                target_frame = frame_results(n)+1;                                                                                                                                                                              
                target_x = round(x_results(n) * app.video_info.nx);                                                                                                                                                             
                target_y = round(y_results(n) * app.video_info.ny);                                                                                                                                                             
                target_z = round(z_results(n));                                                                                                                                                                                 
                                                                                                                                                                                                                                
                worldline.name = wl_name(target_wl);                                                                                                                                                                            
                worldline.color = wl_col(target_wl);                                                                                                                                                                            

                if isstring(worldline.color)                                                                                                                                                                                    
                    worldline.color = [0.8, 0.8, 0.8];                                                                                                                                                                          
                end                                                                                                                                                                                                             
                                                                                                                                                                                                                                
                rois(target_frame).x_slice = target_x;                                                                                                                                                                          
                rois(target_frame).y_slice = target_y;                                                                                                                                                                          
                rois(target_frame).z_slice = target_z;                                                                                                                                                                          
                                                                                                                                                                                                                                
                rois(target_frame).xy_pos = [target_x target_y];                                                                                                                                                                
                rois(target_frame).xz_pos = [target_x target_z];                                                                                                                                                                
                rois(target_frame).yz_pos = [target_z target_y];                                                                                                                                                                
                                                                                                                                                                                                                                
                for i=1:size(app.video_neurons, 2)                                                                                                                                                                              
                    if strcmp(app.video_neurons(i).worldline.name, worldline.name)                                                                                                                                              
                        idx = i;                                                                                                                                                                                                
                        existing_worldline = 1;                                                                                                                                                                                 
                        break                                                                                                                                                                                                   
                    end                                                                                                                                                                                                         
                end                                                                                                                                                                                                             
                                                                                                                                                                                                                                
                if existing_worldline                                                                                                                                                                                           
                    app.video_neurons(idx).rois(target_frame) = rois(target_frame);                                                                                                                                             
                else                                                                                                                                                                                                            
                    provenance = prov_results(n);                                                                                                                                                                               
                    worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);                                                                                                                                         
                    worldline.style = uistyle("FontColor", worldline.color);                                                                                                                                                    
                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);                                                                                                                                       
                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);                                                                                                                      
                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);                                                                                                                                 
                    app.video_neurons(end+1) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});                                                                                                    
                end                                                                                                                                                                                                             

                if ~any(app.id_frames == app.tSlider.Value)                                                                                                                                                                     
                    app.id_frames = [app.id_frames; target_frame];                                                                                                                                                              
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 

            app.visual_composer();                                                                                                                                                                                              
            app.data_flags.('Tracking_ROIs') = 1;                                                                                                                                                                               
            app.video_info.annotations = [path, 'annotations.h5'];                                                                                                                                                              
            close(d)                                                                                                                                                                                                            
        end                                                                                                                                                                                                                     


        function create_worldlines(app)                                                                                                                                                                                         
        end                                                                                                                                                                                                                     


        function create_metadata(app)                                                                                                                                                                                           
            % Extract necessary information from the video_info structure                                                                                                                                                       
            metadata.shape_t = app.video_info.nt;                                                                                                                                                                               
            metadata.shape_c = app.video_info.nc;                                                                                                                                                                               
            metadata.shape_z = app.video_info.nz;                                                                                                                                                                               
            metadata.shape_y = app.video_info.ny;                                                                                                                                                                               
            metadata.shape_x = app.video_info.nx;                                                                                                                                                                               
                                                                                                                                                                                                                                
            % Convert the MATLAB structure to JSON text                                                                                                                                                                         
            jsonText = jsonencode(metadata);                                                                                                                                                                                    
                                                                                                                                                                                                                                
            [path, name, ext] = fileparts(app.video_path);                                                                                                                                                                      

            % Write the JSON text to a file                                                                                                                                                                                     
            fileId = fopen(fullfile(path, 'metadata.json'), 'w');                                                                                                                                                               
            if fileId == -1                                                                                                                                                                                                     
                error('Failed to open file for writing.');                                                                                                                                                                      
            end                                                                                                                                                                                                                 
            fprintf(fileId, '%s', jsonText);                                                                                                                                                                                    
            fclose(fileId);                                                                                                                                                                                                     
        end                                                                                                                                                                                                                     

        function arg_string = compile_arguments(app)                                                                                                                                                                            
            app.arguments = struct( ...                                                                                                                                                                                         
                'dataset', app.video_path, ...                                                                                                                                                                                  
                'load_checkpoint', 0, ...                                                                                                                                                                                       
                'load_args', 1, ...                                                                                                                                                                                             
                'allow_rotation', app.AllowRotationsSwitch.Value, ...                                                                                                                                                           
                'clip_grad', app.clip_grad.Value, ...                                                                                                                                                                           
                'cuda', app.UseGPUSwitch.Value, ...                                                                                                                                                                             
                'dimmer_ratio', app.dimmer_ratio.Value, ...                                                                                                                                                                     
                'exclude_self', 1, ...                                                                                                                                                                                          
                'fovea_sigma', app.fovea_sigma.Value, ...                                                                                                                                                                       
                'gamma', 2, ...                                                                                                                                                                                                 
                'grid_shape', app.grid_shape.Value, ...                                                                                                                                                                         
                'include_all', 1, ...                                                                                                                                                                                           
                'lambda_d', app.l_d.Value, ...                                                                                                                                                                                  
                'lambda_n', app.l_n.Value, ...                                                                                                                                                                                  
                'lambda_n_mode', app.l_n_mode.Value, ...                                                                                                                                                                        
                'lambda_t', app.l_t.Value, ...                                                                                                                                                                                  
                'load_nn', 1, ...                                                                                                                                                                                               
                'lr_ceiling', app.lr_ceiling.Value, ...                                                                                                                                                                         
                'lr_coef', app.lr_coeff.Value, ...                                                                                                                                                                              
                'lr_floor', app.lr_floor.Value, ...                                                                                                                                                                             
                'motion_predict', app.MotionPredictionSwitch.Value, ...                                                                                                                                                         
                'n_chunks', app.n_chunks.Value, ...                                                                                                                                                                             
                'n_epoch', app.n_epoch.Value, ...                                                                                                                                                                               
                'n_epoch_d', app.n_epoch_d.Value, ...                                                                                                                                                                           
                'n_frame', 1, ...                                                                                                                                                                                               
                'nn_max', app.nn_max.Value, ...                                                                                                                                                                                 
                'save_mode', 'o', ...                                                                                                                                                                                           
                'sort_mode', app.sort_mode.Value, ...                                                                                                                                                                           
                'z_compensator', app.z_comp.Value);                                                                                                                                                                             


            % Initialize an empty string for the command line arguments                                                                                                                                                         
            arg_string = '';                                                                                                                                                                                                    
                                                                                                                                                                                                                                
            % Retrieve all field names from the struct                                                                                                                                                                          
            fields = fieldnames(app.arguments);                                                                                                                                                                                 
                                                                                                                                                                                                                                
            % Iterate through each field                                                                                                                                                                                        
            for i = 1:numel(fields)                                                                                                                                                                                             
                field = fields{i}; % Current field name                                                                                                                                                                         
                value = app.arguments.(field); % Value of the current field                                                                                                                                                     
                                                                                                                                                                                                                                
                % Check if the value is a numeric type                                                                                                                                                                          
                if isnumeric(value)                                                                                                                                                                                             
                    valueStr = num2str(value);                                                                                                                                                                                  
                elseif islogical(value)                                                                                                                                                                                         
                    valueStr = num2str(value * 1);                                                                                                                                                                              
                else                                                                                                                                                                                                            
                    valueStr = value;                                                                                                                                                                                           
                end                                                                                                                                                                                                             
                                                                                                                                                                                                                                
                % Append the current argument to the command line string                                                                                                                                                        
                if ischar(value) || isstring(value)                                                                                                                                                                             
                    arg_string = [arg_string, ' --', field, ' "', valueStr, '"'];                                                                                                                                               
                else                                                                                                                                                                                                            
                    arg_string = [arg_string, ' --', field, '=', valueStr];                                                                                                                                                     
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            % Remove the leading space                                                                                                                                                                                          
            if ~isempty(arg_string)                                                                                                                                                                                             
                arg_string = strtrim(arg_string);                                                                                                                                                                               
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function visual_composer(app, t, z, x, y)                                                                                                                                                                               

            if ~exist ('t', 'var')                                                                                                                                                                                              
                t = round(app.tSlider.Value);                                                                                                                                                                                   
            end                                                                                                                                                                                                                 

            if app.OverlaylastIDdframeCheckBox_2.Value                                                                                                                                                                          
                earlier_frames = app.id_frames(app.id_frames < app.tSlider.Value);                                                                                                                                              
                t = max(earlier_frames);                                                                                                                                                                                        
            end                                                                                                                                                                                                                 

            if ~exist ('z', 'var')                                                                                                                                                                                              
                z = round(app.hor_zSlider.Value);                                                                                                                                                                               
            end                                                                                                                                                                                                                 

            if ~exist('y', 'var')                                                                                                                                                                                               
                y = round(app.xSlider.Value);                                                                                                                                                                                   
            end                                                                                                                                                                                                                 

            if ~exist ('x', 'var')                                                                                                                                                                                              
                x = round(app.video_info.ny-app.ySlider.Value);                                                                                                                                                                 
            end                                                                                                                                                                                                                 

            %fprintf("Loading Frame %.f with Slice %.f where x = %.f and y = %.f.\n", t, z, y, x)                                                                                                                               

            Program.Validation.frame_in_bounds(t);                                                                                                                                                                              
            Program.Validation.slice_in_bounds(z);                                                                                                                                                                              

            target_frame = app.retrieve_frame(t);                                                                                                                                                                               

            if app.video_info.nc < 3                                                                                                                                                                                            
                n_xyz = [app.video_info.ny, app.video_info.nx, app.video_info.nz];                                                                                                                                              
                target_frame = cat(4, target_frame, zeros(n_xyz));                                                                                                                                                              
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            if app.OverlayFrameMIPCheckBox.Value                                                                                                                                                                                
                render = struct( ...                                                                                                                                                                                            
                    'xy', {max(target_frame, [], 3)}, ...                                                                                                                                                                       
                    'xz', {max(target_frame(:, :, :, :), [], 2)}, ...                                                                                                                                                           
                    'yz', {max(target_frame(:, :, :, :), [], 1)});                                                                                                                                                              
                                                                                                                                                                                                                                
            else                                                                                                                                                                                                                
                render = struct( ...                                                                                                                                                                                            
                    'xy', {target_frame(:, :, z, :)}, ...                                                                                                                                                                       
                    'xz', {target_frame(:, y, :, :)}, ...                                                                                                                                                                       
                    'yz', {target_frame(x, :, :, :)});                                                                                                                                                                          
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            proj = fieldnames(render);                                                                                                                                                                                          
            for p=1:length(proj)                                                                                                                                                                                                
                projection = proj{p};                                                                                                                                                                                           
                arr = render.(projection);                                                                                                                                                                                      
                arr(:, :, :, 1) = arr(:, :, :, 1) * app.RSlider.Value;                                                                                                                                                          
                arr(:, :, :, 2) = arr(:, :, :, 2) * app.GSlider.Value;                                                                                                                                                          
                arr(:, :, :, 3) = arr(:, :, :, 3) * app.BSlider.Value;                                                                                                                                                          
                arr = squeeze(arr);                                                                                                                                                                                             
                                                                                                                                                                                                                                
                if strcmp(projection, 'yz')                                                                                                                                                                                     
                    arr = permute(arr, [2, 1, 3]);                                                                                                                                                                              
                end                                                                                                                                                                                                             

                render.(projection) = arr;                                                                                                                                                                                      
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            xy_img = image(app.xyAxes, render.xy);                                                                                                                                                                              
            xz_img = image(app.xzAxes, render.yz);                                                                                                                                                                              
            yz_img = image(app.yzAxes, render.xz);                                                                                                                                                                              
                                                                                                                                                                                                                                
            Program.Helpers.sl_sync();                                                                                                                                                                                          
                                                                                                                                                                                                                                
            xy_img.ButtonDownFcn = {@app.ImageClicked};                                                                                                                                                                         
            xz_img.ButtonDownFcn = {@app.ImageClicked};                                                                                                                                                                         
            yz_img.ButtonDownFcn = {@app.ImageClicked};                                                                                                                                                                         
                                                                                                                                                                                                                                
            app.xyAxes.XLim = [1, size(render.xy, 2)];                                                                                                                                                                          
            app.xyAxes.YLim = [1, size(render.xy, 1)];                                                                                                                                                                          
            app.xzAxes.XLim = [1, size(render.yz, 2)];                                                                                                                                                                          
            app.xzAxes.YLim = [1, size(render.yz, 1)];                                                                                                                                                                          
            app.yzAxes.XLim = [1, size(render.xz, 2)];                                                                                                                                                                          
            app.yzAxes.YLim = [1, size(render.xz, 1)];                                                                                                                                                                          
                                                                                                                                                                                                                                
            Program.Helpers.draw_video_cursor(x, y, z);                                                                                                                                                                         

            delete(findobj(app.xyAxes, 'Type', 'images.roi.Point'));                                                                                                                                                            
            delete(findobj(app.yzAxes, 'Type', 'images.roi.Point'));                                                                                                                                                            
            delete(findobj(app.xzAxes, 'Type', 'images.roi.Point'));                                                                                                                                                            

            if any(app.id_frames == app.tSlider.Value)                                                                                                                                                                          
                app.roi_draw(app.xSlider.Value, app.ySlider.Value, z, t)                                                                                                                                                        
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function roi_draw(app, x, y, z, t)                                                                                                                                                                                      
            if app.ShowallneuronsCheckBox.Value                                                                                                                                                                                 
                threshold = 999;                                                                                                                                                                                                
            else                                                                                                                                                                                                                
                threshold = 2;                                                                                                                                                                                                  
            end                                                                                                                                                                                                                 

            for n=1:size(app.video_neurons, 2)                                                                                                                                                                                  
                if t <= size(app.video_neurons(n).rois, 2)                                                                                                                                                                      
                    try                                                                                                                                                                                                         
                        if z <= app.video_neurons(n).rois(t).z_slice + threshold && z >= app.video_neurons(n).rois(t).z_slice - threshold                                                                                       
                            app.video_neurons(n).rois(t).xy = drawpoint(app.xyAxes, 'Position', app.video_neurons(n).rois(t).xy_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', app.video_neurons(n).worldline.color);  
                            addlistener(app.video_neurons(n).rois(t).xy, 'ROIClicked', @(src, evt)app.edit_worldline(n, 'select', 0));                                                                                          
                            addlistener(app.video_neurons(n).rois(t).xy, 'ROIMoved', @(src, evt)app.roi_moved(src, n, evt));                                                                                                    
                        end                                                                                                                                                                                                     
                                                                                                                                                                                                                                
                        if y <= app.video_neurons(n).rois(t).y_slice + threshold && y >= app.video_neurons(n).rois(t).y_slice - threshold                                                                                       
                            app.video_neurons(n).rois(t).xz = drawpoint(app.xzAxes, 'Position', app.video_neurons(n).rois(t).xz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', app.video_neurons(n).worldline.color);  
                            addlistener(app.video_neurons(n).rois(t).xz, 'ROIClicked', @(src, evt)app.edit_worldline(n, 'select', 0));                                                                                          
                            addlistener(app.video_neurons(n).rois(t).xz, 'ROIMoved', @(src, evt)app.roi_moved(src, n, evt));                                                                                                    
                        end                                                                                                                                                                                                     
                                                                                                                                                                                                                                
                        if x <= app.video_neurons(n).rois(t).x_slice + threshold && x >= app.video_neurons(n).rois(t).x_slice - threshold                                                                                       
                            app.video_neurons(n).rois(t).yz = drawpoint(app.yzAxes, 'Position', app.video_neurons(n).rois(t).yz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', app.video_neurons(n).worldline.color);  
                            addlistener(app.video_neurons(n).rois(t).yz, 'ROIClicked', @(src, evt)app.edit_worldline(n, 'select', 0));                                                                                          
                            addlistener(app.video_neurons(n).rois(t).yz, 'ROIMoved', @(src, evt)app.roi_moved(src, n, evt));                                                                                                    
                        end                                                                                                                                                                                                     
                    catch                                                                                                                                                                                                       
                        %fprintf("\n Problem neuron (#%s @ t=%s):", num2str(n), num2str(t))                                                                                                                                     
                        %app.video_neurons(n).rois(t)                                                                                                                                                                           
                    end                                                                                                                                                                                                         
                end                                                                                                                                                                                                             

            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function add_worldline(app, neuron)                                                                                                                                                                                     
            if isempty(app.video_neurons)                                                                                                                                                                                       
                app.video_neurons = struct('provenance', {}, 'worldline', {}, 'rois', {});                                                                                                                                      
            end                                                                                                                                                                                                                 

            provenance = 'NPAL';                                                                                                                                                                                                
            worldline = struct('name', {{}}, 'node', {{}}, 'color', {{}}, 'style', {{}}, 'id', {{}});                                                                                                                           
            rois = struct();                                                                                                                                                                                                    
            idx = size(app.video_neurons, 2) + 1;                                                                                                                                                                               
            existing_worldline = 0;                                                                                                                                                                                             


            % Generate or extract worldline name                                                                                                                                                                                
            if ~exist('neuron','var')                                                                                                                                                                                           
                worldline.name = string(size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1)+1);                                                                                                                         
            else                                                                                                                                                                                                                
                if ~isempty(neuron.annotation)                                                                                                                                                                                  
                    worldline.name = neuron.annotation;                                                                                                                                                                         
                else                                                                                                                                                                                                            
                    worldline.name = string(size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1)+1);                                                                                                                     
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 

            % Check if this worldline already exists                                                                                                                                                                            
            for n=1:size(app.video_neurons, 2)                                                                                                                                                                                  
                if strcmp(app.video_neurons(n).worldline.name, worldline.name)                                                                                                                                                  
                    idx = n;                                                                                                                                                                                                    
                    existing_worldline = 1;                                                                                                                                                                                     
                    break                                                                                                                                                                                                       
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 

            % Populate worldline info                                                                                                                                                                                           
            if ~exist('neuron','var')                                                                                                                                                                                           

                rois(app.tSlider.Value).x_slice = app.xSlider.Value;                                                                                                                                                            
                rois(app.tSlider.Value).y_slice = app.ySlider.Value;                                                                                                                                                            
                rois(app.tSlider.Value).z_slice = app.hor_zSlider.Value;                                                                                                                                                        
                                                                                                                                                                                                                                
                rois(app.tSlider.Value).xy_pos = [app.xSlider.Value app.video_info.ny-app.ySlider.Value];                                                                                                                       
                rois(app.tSlider.Value).xz_pos = [app.xSlider.Value app.hor_zSlider.Value];                                                                                                                                     
                rois(app.tSlider.Value).yz_pos = [app.hor_zSlider.Value app.video_info.ny-app.ySlider.Value];                                                                                                                   
                                                                                                                                                                                                                                
                rois(app.tSlider.Value).xy = drawpoint(app.xyAxes, 'Position', [app.xSlider.Value app.video_info.ny-app.ySlider.Value], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);                    
                rois(app.tSlider.Value).xz = drawpoint(app.xzAxes, 'Position', [app.xSlider.Value app.hor_zSlider.Value], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);                                  
                rois(app.tSlider.Value).yz = drawpoint(app.yzAxes, 'Position', [app.hor_zSlider.Value app.video_info.ny-app.ySlider.Value], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);                

                if existing_worldline                                                                                                                                                                                           
                    app.video_neurons(idx).rois(app.tSlider.Value) = rois(app.tSlider.Value);                                                                                                                                   
                else                                                                                                                                                                                                            
                    worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);                                                                                                                                         
                    worldline.color = [0.8, 0.8, 0.8];                                                                                                                                                                          
                    worldline.style = uistyle("FontColor", worldline.color);                                                                                                                                                    
                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);                                                                                                                                       
                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);                                                                                                                      
                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);                                                                                                                                 
                    app.video_neurons(idx) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});                                                                                                      
                end                                                                                                                                                                                                             

                if app.AutojumponIDCheckBox.Value                                                                                                                                                                               
                    app.jumpToUnIDd('frame');                                                                                                                                                                                   
                else                                                                                                                                                                                                            
                    app.edit_worldline(size(app.video_neurons, 2), 'select');                                                                                                                                                   
                end                                                                                                                                                                                                             
            else                                                                                                                                                                                                                
                % Extract neuron color. If invalid, use color_readout.                                                                                                                                                          
                % If also invalid, use default worldline color (grey).                                                                                                                                                          
                if ~isnan(neuron.color(1)) && max(neuron.color) <= 1 && min(neuron.color) >= 0                                                                                                                                  
                    worldline.color = neuron.color(1:3);                                                                                                                                                                        
                elseif ~isnan(neuron.color_readout(1)) && max(neuron.color_readout) <= 1 && min(neuron.color_readout) >= 0                                                                                                      
                    worldline.color = neuron.color_readout(1:3);                                                                                                                                                                
                else                                                                                                                                                                                                            
                    worldline.color = [0.8, 0.8, 0.8];                                                                                                                                                                          
                end                                                                                                                                                                                                             

                rois(app.tSlider.Value).x_slice = neuron.position(2);                                                                                                                                                           
                rois(app.tSlider.Value).y_slice = neuron.position(1);                                                                                                                                                           
                rois(app.tSlider.Value).z_slice = neuron.position(3);                                                                                                                                                           
                                                                                                                                                                                                                                
                rois(app.tSlider.Value).xy_pos = [neuron.position(2) neuron.position(1)];                                                                                                                                       
                rois(app.tSlider.Value).xz_pos = [neuron.position(2) neuron.position(3)];                                                                                                                                       
                rois(app.tSlider.Value).yz_pos = [neuron.position(3) neuron.position(1)];                                                                                                                                       
                                                                                                                                                                                                                                
                rois(app.tSlider.Value).xy = drawpoint(app.xyAxes, 'Position', rois(app.tSlider.Value).xy_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);                                             
                rois(app.tSlider.Value).xz = drawpoint(app.xzAxes, 'Position', rois(app.tSlider.Value).xz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);                                             
                rois(app.tSlider.Value).yz = drawpoint(app.yzAxes, 'Position', rois(app.tSlider.Value).yz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);                                             
                                                                                                                                                                                                                                
                if existing_worldline                                                                                                                                                                                           
                    app.video_neurons(idx).rois(app.tSlider.Value) = rois(app.tSlider.Value);                                                                                                                                   
                else                                                                                                                                                                                                            
                    if Neurons.Hermaphrodite.isCell(worldline.name)                                                                                                                                                             
                        worldline.node = uitreenode(app.IDdNode, "Text", worldline.name);                                                                                                                                       
                    else                                                                                                                                                                                                        
                        worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);                                                                                                                                     
                    end                                                                                                                                                                                                         

                    worldline.style = uistyle("FontColor", worldline.color);                                                                                                                                                    
                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);                                                                                                                                       
                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);                                                                                                                      
                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);                                                                                                                                 
                    app.video_neurons(idx) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});                                                                                                      
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 

            if ~any(app.id_frames == app.tSlider.Value)                                                                                                                                                                         
                app.id_frames = [app.id_frames; app.tSlider.Value];                                                                                                                                                             
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function edit_worldline(app, idx, mode, new_val)                                                                                                                                                                        
            app.visual_composer();                                                                                                                                                                                              

            switch mode                                                                                                                                                                                                         
                case 'select'                                                                                                                                                                                                   
                    app.NameEditField.Value = app.video_neurons(idx).worldline.name;                                                                                                                                            
                    app.XCoordinateEditField.Value = app.video_neurons(idx).rois(app.tSlider.Value).xy_pos(1);                                                                                                                  
                    app.YCoordinateEditField.Value = app.video_neurons(idx).rois(app.tSlider.Value).xy_pos(2);                                                                                                                  
                    app.ZCoordinateEditField.Value = app.video_neurons(idx).rois(app.tSlider.Value).z_slice;                                                                                                                    

                    app.xSlider.Value = app.XCoordinateEditField.Value;                                                                                                                                                         
                    app.ySlider.Value = app.video_info.ny - app.YCoordinateEditField.Value;                                                                                                                                     
                    app.hor_zSlider.Value = app.ZCoordinateEditField.Value;                                                                                                                                                     

                    app.WorldlineIDEditField.Value = idx;                                                                                                                                                                       
                    app.ColorButton.BackgroundColor = app.video_neurons(idx).worldline.color;                                                                                                                                   
                    app.ProvenanceEditField.Value = app.video_neurons(idx).provenance;                                                                                                                                          
                case 'save'                                                                                                                                                                                                     
                    d = uiprogressdlg(app.CELL_ID,'Title','Saving worldline...','Indeterminate','on');                                                                                                                          
                    existing_idx = -1;                                                                                                                                                                                          

                    for i = 1:length(app.video_neurons)                                                                                                                                                                         
                        if strcmp(app.video_neurons(i).worldline.name, app.NameEditField.Value)                                                                                                                                 
                            existing_idx = i;                                                                                                                                                                                   
                            break;                                                                                                                                                                                              
                        end                                                                                                                                                                                                     
                    end                                                                                                                                                                                                         

                    if existing_idx == -1                                                                                                                                                                                       
                        app.video_neurons(idx).worldline.name = app.NameEditField.Value;                                                                                                                                        
                        app.video_neurons(idx).worldline.node.Text = app.video_neurons(idx).worldline.name;                                                                                                                     
                        app.video_neurons(idx).provenance = app.ProvenanceEditField.Value;                                                                                                                                      
                        app.edit_worldline(idx, 'position', [app.XCoordinateEditField.Value app.YCoordinateEditField.Value app.ZCoordinateEditField.Value])                                                                     

                        if Neurons.Hermaphrodite.isCell(app.video_neurons(idx).worldline.name)                                                                                                                                  
                            move(app.video_neurons(idx).worldline.node, app.WorldlineTree.Children(1).Children(end), 'after');                                                                                                  
                        else                                                                                                                                                                                                    
                            move(app.video_neurons(idx).worldline.node, app.WorldlineTree.Children(2).Children(end), 'after');                                                                                                  
                        end                                                                                                                                                                                                     
                    else                                                                                                                                                                                                        
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).x_slice = app.video_neurons(idx).rois(app.tSlider.Value).x_slice;                                                                               
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).y_slice = app.video_neurons(idx).rois(app.tSlider.Value).y_slice;                                                                               
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).z_slice = app.video_neurons(idx).rois(app.tSlider.Value).z_slice;                                                                               
                                                                                                                                                                                                                                
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).xy_pos = app.video_neurons(idx).rois(app.tSlider.Value).xy_pos;                                                                                 
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).xz_pos = app.video_neurons(idx).rois(app.tSlider.Value).xz_pos;                                                                                 
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).yz_pos = app.video_neurons(idx).rois(app.tSlider.Value).yz_pos;                                                                                 

                        delete(app.video_neurons(idx).worldline.node);                                                                                                                                                          
                        app.video_neurons(idx) = [];                                                                                                                                                                            
                    end                                                                                                                                                                                                         
                    close(d)                                                                                                                                                                                                    
                case 'color'                                                                                                                                                                                                    
                    app.video_neurons(idx).worldline.color = new_val;                                                                                                                                                           
                    app.video_neurons(idx).worldline.style = uistyle("FontColor", app.video_neurons(idx).worldline.color);                                                                                                      
                    addStyle(app.WorldlineTree, app.video_neurons(idx).worldline.style, "node", app.video_neurons(idx).worldline.node);                                                                                         
                case 'name'                                                                                                                                                                                                     
                    app.video_neurons(idx).worldline.name = new_val;                                                                                                                                                            
                    app.video_neurons(idx).worldline.node.Text = new_val;                                                                                                                                                       
                case 'position'                                                                                                                                                                                                 
                    app.video_neurons(idx).rois(app.tSlider.Value).x_slice = new_val(1);                                                                                                                                        
                    app.video_neurons(idx).rois(app.tSlider.Value).y_slice = new_val(2);                                                                                                                                        
                    app.video_neurons(idx).rois(app.tSlider.Value).z_slice = new_val(3);                                                                                                                                        
                                                                                                                                                                                                                                
                    app.video_neurons(idx).rois(app.tSlider.Value).xy_pos = [new_val(1) new_val(2)];                                                                                                                            
                    app.video_neurons(idx).rois(app.tSlider.Value).xz_pos = [new_val(1) new_val(3)];                                                                                                                            
                    app.video_neurons(idx).rois(app.tSlider.Value).yz_pos = [new_val(3) new_val(2)];                                                                                                                            
                case 'delete'                                                                                                                                                                                                   
                    app.video_neurons(idx) = [];                                                                                                                                                                                
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            app.visual_composer();                                                                                                                                                                                              
        end                                                                                                                                                                                                                     

        function import_annotations(app, pos_arr, labels)                                                                                                                                                                       
            d = uiprogressdlg(app.CELL_ID,"Title","NeuroPAL ID","Message","Importing tracks...",'Indeterminate','off');                                                                                                         
            app.video_neurons = struct('provenance', {}, 'worldline', {}, 'rois', {});                                                                                                                                          

            % Check for converted coordinates.                                                                                                                                                                                  
            if max(pos_arr(:, 2)) <= app.video_info.nx/10                                                                                                                                                                       
                %pos_arr(:, 2) = max(floor(pos_arr(:, 2)*app.video_info.nx- 1e-6), 0);                                                                                                                                          
                pos_arr(:, 2) = (pos_arr(:, 2) * app.video_info.nx);                                                                                                                                                            
            end                                                                                                                                                                                                                 

            if max(pos_arr(:, 3)) <= app.video_info.ny/10                                                                                                                                                                       
                %pos_arr(:, 3) = max(floor(pos_arr(:, 3)*app.video_info.ny- 1e-6), 0);                                                                                                                                          
                pos_arr(:, 3) = (pos_arr(:, 3) * app.video_info.ny);                                                                                                                                                            
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            if mean(pos_arr(:, 4)) <= app.video_info.nz/5                                                                                                                                                                       
                %pos_arr(:, 4) = max(floor(pos_arr(:, 4)*app.video_info.nz- 1e-6), 0);                                                                                                                                          
                pos_arr(:, 4) = (pos_arr(:, 4) * app.video_info.nz);                                                                                                                                                            
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            pos_arr(:, 1) = round(pos_arr(:, 1));                                                                                                                                                                               

            %if min(pos_arr(:, 4)) == 0                                                                                                                                                                                         
            %    pos_arr(:, 4) = pos_arr(:, 4) + 1;                                                                                                                                                                             
            %end                                                                                                                                                                                                                

            for n=1:size(labels, 2)                                                                                                                                                                                             
                d.Value = n/size(pos_arr, 1);                                                                                                                                                                                   
                idx = size(app.video_neurons, 2) + 1;                                                                                                                                                                           
                existing_worldline = 0;                                                                                                                                                                                         

                label = deblank(labels{n});                                                                                                                                                                                     
                coords = pos_arr(n, :);                                                                                                                                                                                         
                frame = coords(1);                                                                                                                                                                                              
                                                                                                                                                                                                                                
                provenance = 'TKMT';                                                                                                                                                                                            
                worldline = struct('name', {{}}, 'node', {{}}, 'color', {{}}, 'style', {{}}, 'id', {{}});                                                                                                                       
                rois = struct();                                                                                                                                                                                                
                                                                                                                                                                                                                                
                worldline.name = label;                                                                                                                                                                                         
                                                                                                                                                                                                                                
                % Check if this worldline already exists                                                                                                                                                                        
                for i=1:size(app.video_neurons, 2)                                                                                                                                                                              
                    if strcmp(app.video_neurons(i).worldline.name, worldline.name)                                                                                                                                              
                        idx = i;                                                                                                                                                                                                
                        existing_worldline = 1;                                                                                                                                                                                 
                        break                                                                                                                                                                                                   
                    end                                                                                                                                                                                                         
                end                                                                                                                                                                                                             

                rois(frame).x_slice = coords(2);                                                                                                                                                                                
                rois(frame).y_slice = coords(3);                                                                                                                                                                                
                rois(frame).z_slice = coords(4);                                                                                                                                                                                
                rois(frame).xy_pos = [coords(2) coords(3)];                                                                                                                                                                     
                rois(frame).xz_pos = [coords(2) coords(4)];                                                                                                                                                                     
                rois(frame).yz_pos = [coords(4) coords(3)];                                                                                                                                                                     

                if existing_worldline                                                                                                                                                                                           
                    app.video_neurons(idx).rois(frame) = rois(frame);                                                                                                                                                           
                else                                                                                                                                                                                                            
                    if Neurons.Hermaphrodite.isCell(worldline.name)                                                                                                                                                             
                        worldline.node = uitreenode(app.IDdNode, "Text", worldline.name);                                                                                                                                       
                    else                                                                                                                                                                                                        
                        worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);                                                                                                                                     
                    end                                                                                                                                                                                                         

                    worldline.color = [0.8, 0.8, 0.8];                                                                                                                                                                          
                    worldline.style = uistyle("FontColor", worldline.color);                                                                                                                                                    
                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);                                                                                                                                       
                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);                                                                                                                      
                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);                                                                                                                                 
                    app.video_neurons(idx) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});                                                                                                      
                end                                                                                                                                                                                                             
                                                                                                                                                                                                                                
                if ~any(app.id_frames == frame)                                                                                                                                                                                 
                    app.id_frames = [app.id_frames; frame];                                                                                                                                                                     
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 

            app.visual_composer();                                                                                                                                                                                              
            close(d)                                                                                                                                                                                                            
        end                                                                                                                                                                                                                     


        function roi_moved(app, src, n, evt)                                                                                                                                                                                    
            switch src                                                                                                                                                                                                          
                case app.xyAxes                                                                                                                                                                                                 
                    app.edit_worldline(n, 'position', [evt.CurrentPosition(1), evt.CurrentPosition(2), app.video_neurons(n).rois(app.tSlider.Value).z_slice]);                                                                  
                case app.xzAxes                                                                                                                                                                                                 
                    app.edit_worldline(n, 'position', [evt.CurrentPosition(1), app.video_neurons(n).rois(app.tSlider.Value).y_slice, evt.CurrentPosition(2)]);                                                                  
                case app.yzAxes                                                                                                                                                                                                 
                    app.edit_worldline(n, 'position', [app.video_neurons(n).rois(app.tSlider.Value).x_slice, evt.CurrentPosition(2), evt.CurrentPosition(1)]);                                                                  
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function jumpToUnIDd(app)                                                                                                                                                                                               
            switch mode                                                                                                                                                                                                         
                case 'frame'                                                                                                                                                                                                    
                case 'tree'                                                                                                                                                                                                     
                case 'video'                                                                                                                                                                                                    
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     

        function export_arguments(app, filename)                                                                                                                                                                                
            % Compile arguments.                                                                                                                                                                                                
            app.compile_arguments();                                                                                                                                                                                            

            % Convert the app.arguments struct to a JSON string                                                                                                                                                                 
            jsonStr = jsonencode(app.arguments);                                                                                                                                                                                
                                                                                                                                                                                                                                
            % Open a file for writing.                                                                                                                                                                                          
            fileId = fopen(filename, 'wt');                                                                                                                                                                                     
                                                                                                                                                                                                                                
            % Check if the file was opened successfully                                                                                                                                                                         
            if fileId == -1                                                                                                                                                                                                     
                error('Failed to open file for writing: %s', filename);                                                                                                                                                         
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            % Write the JSON string to the file                                                                                                                                                                                 
            fwrite(fileId, jsonStr, 'char');                                                                                                                                                                                    
                                                                                                                                                                                                                                
            % Close the file                                                                                                                                                                                                    
            fclose(fileId);                                                                                                                                                                                                     
        end                                                                                                                                                                                                                     


        function import_arguments(app, json)                                                                                                                                                                                    
            % Read the entire content of the JSON file into a string                                                                                                                                                            
            jsonStr = fileread(json);                                                                                                                                                                                           
                                                                                                                                                                                                                                
            % Check if the JSON string is empty                                                                                                                                                                                 
            if isempty(jsonStr)                                                                                                                                                                                                 
                error('The JSON file is empty: %s', json);                                                                                                                                                                      
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            % Convert the JSON string back into a MATLAB struct                                                                                                                                                                 
            app.arguments = jsondecode(jsonStr);                                                                                                                                                                                
        end                                                                                                                                                                                                                     


        function saveButtonPushed(app, src, fig, colorButton, targetNode)                                                                                                                                                       
            newStyle = uistyle("FontColor",colorButton.BackgroundColor);                                                                                                                                                        
            addStyle(app.WorldlineTree,newStyle,"node", app.WorldlineTree.SelectedNodes);                                                                                                                                       
            delete(fig)                                                                                                                                                                                                         
        end                                                                                                                                                                                                                     

                                                                                                                                                                                                                                
        function cancelButtonPushed(app, src, fig)                                                                                                                                                                              
            delete(fig)                                                                                                                                                                                                         
        end                                                                                                                                                                                                                     


        %% Log Functions                                                                                                                                                                                                        
        function initLog(app)                                                                                                                                                                                                   
            %INITLOG This function initializes the log tab.                                                                                                                                                                     
            %                                                                                                                                                                                                                   
            %   Inputs:                                                                                                                                                                                                         
            %   - app: Running app instance.                                                                                                                                                                                    

            % Grab System Information                                                                                                                                                                                           
            [status, app.OperatingSystemEditField.Value] = ...                                                                                                                                                                  
                system('ver'); %COMPATWARN                                                                                                                                                                                      

            % Populate the Matlab runtime edit field with the version of                                                                                                                                                        
            % Matlab that we're running.                                                                                                                                                                                        
            app.MATLABRuntimeEditField.Value = version;                                                                                                                                                                         

            % Populate the Python executable edit field with the version of                                                                                                                                                     
            % python that is running in the current environment.                                                                                                                                                                
            app.PythonExecutableEditField.Value = pyenv().Version;                                                                                                                                                              

            % Populate the NeuroPAL_ID edit field with the version of the                                                                                                                                                       
            % program being run.                                                                                                                                                                                                
            app.NeuroPALEditField.Value = string( ...                                                                                                                                                                           
                Program.ProgramInfo.version);                                                                                                                                                                                   
        end                                                                                                                                                                                                                     


        function logEvent(app, source, event, notify)                                                                                                                                                                           
            %LOGEVENT This function adds an event to the program log.                                                                                                                                                           
            %                                                                                                                                                                                                                   
            %   Inputs:                                                                                                                                                                                                         
            %   - app: Running app instance.                                                                                                                                                                                    
            %   - source: A string/char label describing the routine that                                                                                                                                                       
            %       triggered this event.                                                                                                                                                                                       
            %   - event: A string/char label describing the event.                                                                                                                                                              
            %   - notify: A boolean describing whether to pop up a log                                                                                                                                                          
            %       notification (true) or not (false). This essentially                                                                                                                                                        
            %       displays a small text box in the top right of the                                                                                                                                                           
            %       that contains the logged string, and which disappears                                                                                                                                                       
            %       after 3 seconds.                                                                                                                                                                                            

            % Check whether the log is enabled.                                                                                                                                                                                 
            if app.EnablelogCheckBox.Value                                                                                                                                                                                      
                if ~exist('notify','var')                                                                                                                                                                                       
                    % If no notify flag was passed, set notify to 0.                                                                                                                                                            
                    notify = 0;                                                                                                                                                                                                 
                end                                                                                                                                                                                                             
                                                                                                                                                                                                                                
                % Get the current time.                                                                                                                                                                                         
                time = datetime("now");                                                                                                                                                                                         

                % Assemble the string to be added to the log. This is                                                                                                                                                           
                % effectively the current time, the source of the event,                                                                                                                                                        
                % and an event description.                                                                                                                                                                                     
                log_string = sprintf("%s (%s): %s", time, source, event);                                                                                                                                                       
                                                                                                                                                                                                                                
                % Append the log string to the log.                                                                                                                                                                             
                app.Log.Value = [app.Log.Value(:); log_string(:)];                                                                                                                                                              
                                                                                                                                                                                                                                
                % If we're on windows, update the log file. For mac, we'll                                                                                                                                                      
                % need to add a workaround that bypasses the security                                                                                                                                                           
                % issues we encountered during debugging.                                                                                                                                                                       
                if ~ismac                                                                                                                                                                                                       
                    log_file = fopen([pwd,'/log.txt'], 'a+'); % COMPATWARN                                                                                                                                                      
                    fclose(log_file);                                                                                                                                                                                           
                end                                                                                                                                                                                                             
                                                                                                                                                                                                                                
                % If the notify flag is set to true and the user has opted                                                                                                                                                      
                % to enable log notifications...                                                                                                                                                                                
                if notify && app.EnablelognotificationsCheckBox.Value                                                                                                                                                           
                    % Create a ui label.                                                                                                                                                                                        
                    hLabel = uilabel('Parent', app.CELL_ID, ...                                                                                                                                                                 
                        'Text', log_string, ...                                                                                                                                                                                 
                        'Position', [app.CELL_ID.Position(3)-500, ...                                                                                                                                                           
                        app.TabGroup.Position(4)-23, 500, 23], ...                                                                                                                                                              
                        'FontColor', [0 0 0], ...                                                                                                                                                                               
                        'HorizontalAlignment', 'right');                                                                                                                                                                        
                                                                                                                                                                                                                                
                    % Start a timer and attach to it a function that                                                                                                                                                            
                    % will fade out the ui label over the course of 3                                                                                                                                                           
                    % seconds.                                                                                                                                                                                                  
                    t = timer('TimerFcn', @(myTimerObj, thisEvent) app.fadeLog(myTimerObj, hLabel), ...                                                                                                                         
                              'Period', 0.1, ...                                                                                                                                                                                
                              'ExecutionMode', 'fixedRate', ...                                                                                                                                                                 
                              'TasksToExecute', 50);                                                                                                                                                                            
                                                                                                                                                                                                                                
                    % Start the timer.                                                                                                                                                                                          
                    start(t);                                                                                                                                                                                                   
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     


        function fadeLog(app, t, hLabel)                                                                                                                                                                                        
            %FADELOG This function fades out a given log notification                                                                                                                                                           
            % over the course of 3 seconds.                                                                                                                                                                                     
            %                                                                                                                                                                                                                   
            %   Inputs:                                                                                                                                                                                                         
            %   - app: Running app instance.                                                                                                                                                                                    
            %   - t: The timer object.                                                                                                                                                                                          
            %   - hLabel: The log notification label.                                                                                                                                                                           

            % Get the current color of the label.                                                                                                                                                                               
            currentColor = hLabel.FontColor;                                                                                                                                                                                    

            % Add 0.02 to every RGB value of the label's font color.                                                                                                                                                            
            % If this causes it to pass the GUI's background color,                                                                                                                                                             
            % go with the GUI background color instead.                                                                                                                                                                         
            newColor = min(currentColor + [0.02 0.02 0.02], [0.9 0.9 0.9]);                                                                                                                                                     
            hLabel.FontColor = newColor;                                                                                                                                                                                        
                                                                                                                                                                                                                                
            % If the new color equal to the GUI's background color...                                                                                                                                                           
            if all(newColor == [0.9 0.9 0.9])                                                                                                                                                                                   
                % Delete the label.                                                                                                                                                                                             
                delete(hLabel);                                                                                                                                                                                                 

                % Stop the timer.                                                                                                                                                                                               
                stop(t);                                                                                                                                                                                                        

                % Delete the timer.                                                                                                                                                                                             
                delete(t);                                                                                                                                                                                                      
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     

                                                                                                                                                                                                                                
        % Helper function that passes nwbsave.mlapp data back to CELL_ID                                                                                                                                                        
        % POLISHWARN                                                                                                                                                                                                            
        function process_nwb_data(app, input)                                                                                                                                                                                   
            app.nwb_data = input;                                                                                                                                                                                               
        end                                                                                                                                                                                                                     
                                                                                                                                                                                                                                
        function chan_array = channel_idx_sync(app)                                                                                                                                                                             
            chan_array = [app.RDropDown.Value, app.GDropDown.Value, app.BDropDown.Value, app.WDropDown.Value, app.DICDropDown.Value, app.GFPDropDown.Value];                                                                    

            % Get the unique values and their indices                                                                                                                                                                           
            [uniqueValues, ~, idx] = unique(chan_array);                                                                                                                                                                        
                                                                                                                                                                                                                                
            % Get the counts of each unique value                                                                                                                                                                               
            counts = histc(idx, 1:length(uniqueValues));                                                                                                                                                                        
                                                                                                                                                                                                                                
            % Find values that occur more than once                                                                                                                                                                             
            duplicateValues = uniqueValues(counts > 1);                                                                                                                                                                         
                                                                                                                                                                                                                                
            % Initialize cell array to hold indices of duplicate values                                                                                                                                                         
            duplicateIndices = cell(length(duplicateValues), 1);                                                                                                                                                                
                                                                                                                                                                                                                                
            % Find indices of each duplicate value                                                                                                                                                                              
            for i = 1:length(duplicateValues)                                                                                                                                                                                   
                duplicateIndices{i} = find(chan_array == duplicateValues(i));                                                                                                                                                   
            end                                                                                                                                                                                                                 

            if ~isempty(duplicateIndices)                                                                                                                                                                                       
                chan_array(max(duplicateIndices)) = 0;                                                                                                                                                                          
                uiconfirm(app.CELL_ID, sprintf("Duplicate channel indices detected. Resetting channels %.f.", string(duplicateIndices)), "Warning!");                                                                           
            end                                                                                                                                                                                                                 

            app.ProcRDropDown.Value = chan_array(1);                                                                                                                                                                            
            app.ProcGDropDown.Value = chan_array(2);                                                                                                                                                                            
            app.ProcBDropDown.Value = chan_array(3);                                                                                                                                                                            
            app.ProcWDropDown.Value = chan_array(4);                                                                                                                                                                            
            app.ProcDICDropDown.Value = chan_array(5);                                                                                                                                                                          
            app.ProcGFPDropDown.Value = chan_array(6);                                                                                                                                                                          
        end                                                                                                                                                                                                                     
                                                                                                                                                                                                                                
        function create_backup(app, path)                                                                                                                                                                                       
            if strcmp(app.VolumeDropDown.Value, 'Colormap') & isempty(app.proc_image)                                                                                                                                           
                if ~isempty(app.image_file)                                                                                                                                                                                     
                    mat_file = fullfile(app.image_file);                                                                                                                                                                        
                end                                                                                                                                                                                                             
                                                                                                                                                                                                                                
                if ~isfile(mat_file)                                                                                                                                                                                            
                    [app.image_data, app.image_info, app.image_prefs, ~, ~, ~, ~, ~] = DataHandling.NeuroPALImage.open(path);                                                                                                   
                end                                                                                                                                                                                                             
                                                                                                                                                                                                                                
                app.proc_image = matfile(mat_file);                                                                                                                                                                             
            elseif strcmp(app.VolumeDropDown.Value, 'Video') & isempty(app.video_info)                                                                                                                                          
                app.proc_load('video', path);                                                                                                                                                                                   
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     

        function retrieve_backup(app)                                                                                                                                                                                           
            switch app.VolumeDropDown.Value                                                                                                                                                                                     
                case 'Colormap'                                                                                                                                                                                                 
                    app.proc_image = matfile(app.proc_image); % This will need to be adjusted once zephir optimization are merged in.                                                                                           
                case 'Video'                                                                                                                                                                                                    
                    app.proc_swap_video(app.video_info.file);                                                                                                                                                                   
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            app.volume_crop_roi = [];                                                                                                                                                                                           
            app.video_crop_roi = [];                                                                                                                                                                                            
        end                                                                                                                                                                                                                     
                                                                                                                                                                                                                                
        function proc_code = proc_check(app, mode, path)                                                                                                                                                                        
            %PROC_CHECK Check whether a given file or volume should trigger                                                                                                                                                     
            % the preprocessing routine.                                                                                                                                                                                        
            %                                                                                                                                                                                                                   
            %   Inputs:                                                                                                                                                                                                         
            %   - app: Running app instance.                                                                                                                                                                                    
            %   - mode: String/char describing whether we're dealing with a                                                                                                                                                     
            %       color stack or a video.                                                                                                                                                                                     
            %   - path: Path to the file to be analyzed.                                                                                                                                                                        
            %                                                                                                                                                                                                                   
            %   Outputs:                                                                                                                                                                                                        
            %   - proc_code: Either true (indicating that the file                                                                                                                                                              
            %       triggered our preprocessing routine) or the file path                                                                                                                                                       
            %       itself.                                                                                                                                                                                                     
            %                                                                                                                                                                                                                   
            %   Note that this function is rendered obsolete by the                                                                                                                                                             
            %   agnostic volume reader update.                                                                                                                                                                                  

            %if app.EnableautoprofilingMenu.Checked                                                                                                                                                                             
            %    profile on                                                                                                                                                                                                     
            %end                                                                                                                                                                                                                

            % Initialize check as "No".                                                                                                                                                                                         
            check = "No";                                                                                                                                                                                                       

            % If the passed path is empty, set proc_code to false and                                                                                                                                                           
            % return.                                                                                                                                                                                                           
            if isempty(path)                                                                                                                                                                                                    
                proc_code = 0;                                                                                                                                                                                                  
                return                                                                                                                                                                                                          
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            % Call dir() on the path to obtain the total bytes.                                                                                                                                                                 
            cont = dir(path);                                                                                                                                                                                                   

            % Calculate 90% of the maximum possible array size the                                                                                                                                                              
            % system's memory can accommodate.                                                                                                                                                                                  
            if ispc                                                                                                                                                                                                             
                % If we're on windows, we can leverage memory().                                                                                                                                                                
                max_arr = memory().MaxPossibleArrayBytes * 0.90;                                                                                                                                                                
            else                                                                                                                                                                                                                
                % On mac, memory() isn't supported, so we have to make                                                                                                                                                          
                % a system call and parse the output.                                                                                                                                                                           
                [~, max_arr] = system('sysctl hw.memsize | awk ''{print $2}''');                                                                                                                                                
                max_arr = str2double(max_arr) * 0.90;                                                                                                                                                                           
            end                                                                                                                                                                                                                 

            % Get the file type we're working with.                                                                                                                                                                             
            [filepath, name, ext] = fileparts(path);                                                                                                                                                                            

            % If this is an nwb file, we need to specifically check the                                                                                                                                                         
            % size of the appropriate volume within the acquisition module.                                                                                                                                                     
            if strcmp(ext, '.nwb')                                                                                                                                                                                              
                % Check whether we're dealing with an image or a video,                                                                                                                                                         
                % then get the appropriate module.                                                                                                                                                                              
                switch mode                                                                                                                                                                                                     
                    case 'image'                                                                                                                                                                                                
                        proc_nwb = nwbRead(path);                                                                                                                                                                               
                        module = proc_nwb.acquisition.get('NeuroPALImageRaw');                                                                                                                                                  

                    case 'video'                                                                                                                                                                                                
                        app.load_nwb(path);                                                                                                                                                                                     
                        module = app.nwb_mod;                                                                                                                                                                                   
                end                                                                                                                                                                                                             

                % If this module's data array has an "internal" property                                                                                                                                                        
                % (not all do), use that, then get the data array's                                                                                                                                                             
                % dimensions and datatype.                                                                                                                                                                                      
                % Get the module's data object. Handle both legacy arrays                                                                                                                                                       
                % and DataPipe/BoundPipe-backed arrays.                                                                                                                                                                         
                data_obj = module.data;                                                                                                                                                                                         
                data_dims = [];                                                                                                                                                                                                 
                dtype = '';                                                                                                                                                                                                     

                % Prefer using the internal bound pipe when available.                                                                                                                                                          
                if isprop(data_obj, 'internal') && ~isempty(data_obj.internal)                                                                                                                                                  
                    internal_obj = data_obj.internal;                                                                                                                                                                           
                    if isprop(internal_obj, 'dims')                                                                                                                                                                             
                        data_dims = double(internal_obj.dims);                                                                                                                                                                  
                    elseif isprop(internal_obj, 'maxSize')                                                                                                                                                                      
                        data_dims = double(internal_obj.maxSize);                                                                                                                                                               
                    end                                                                                                                                                                                                         

                    if isprop(internal_obj, 'dataType') && ~isempty(internal_obj.dataType)                                                                                                                                      
                        dtype = char(internal_obj.dataType);                                                                                                                                                                    
                    end                                                                                                                                                                                                         
                end                                                                                                                                                                                                             

                % Fall back to direct properties if needed.                                                                                                                                                                     
                if isempty(data_dims)                                                                                                                                                                                           
                    if isprop(data_obj, 'dims')                                                                                                                                                                                 
                        data_dims = double(data_obj.dims);                                                                                                                                                                      
                    elseif isprop(data_obj, 'maxSize')                                                                                                                                                                          
                        data_dims = double(data_obj.maxSize);                                                                                                                                                                   
                    end                                                                                                                                                                                                         
                end                                                                                                                                                                                                             

                if isempty(dtype)                                                                                                                                                                                               
                    if isprop(data_obj, 'dataType') && ~isempty(data_obj.dataType)                                                                                                                                              
                        dtype = char(data_obj.dataType);                                                                                                                                                                        
                    elseif isprop(data_obj, 'config') && ~isempty(data_obj.config) && ...                                                                                                                                       
                            isfield(data_obj.config, 'dataType') && ~isempty(data_obj.config.dataType)                                                                                                                          
                        dtype = char(data_obj.config.dataType);                                                                                                                                                                 
                    end                                                                                                                                                                                                         
                end                                                                                                                                                                                                             

                if isempty(data_dims)                                                                                                                                                                                           
                    error('Unable to determine NWB data dimensions for preprocessing check.');                                                                                                                                  
                end                                                                                                                                                                                                             

                % Extract the bit depth from the data type.                                                                                                                                                                     
                switch dtype                                                                                                                                                                                                    
                    case 'single'                                                                                                                                                                                               
                        bit_depth = 4;                                                                                                                                                                                          
                    case 'double'                                                                                                                                                                                               
                        bit_depth = 8;                                                                                                                                                                                          
                    otherwise                                                                                                                                                                                                   
                        if startsWith(dtype, 'uint') || startsWith(dtype, 'int')                                                                                                                                                
                            bit_depth = str2double(dtype(5:end))/8;                                                                                                                                                             
                        else                                                                                                                                                                                                    
                            bit_depth = NaN;                                                                                                                                                                                    
                        end                                                                                                                                                                                                     
                end                                                                                                                                                                                                             

                if isnan(bit_depth) || bit_depth <= 0                                                                                                                                                                           
                    bit_depth = 2;                                                                                                                                                                                              
                end                                                                                                                                                                                                             

                % Calculate the prospective array size in bytes.                                                                                                                                                                
                f_size = prod(double(data_dims)) * bit_depth;                                                                                                                                                                   

            else                                                                                                                                                                                                                
                % If this is not an nwb file, we assume that the array size                                                                                                                                                     
                % is approximately equal to the file size. Note that in the                                                                                                                                                     
                % agnostic volume reader update, this is changed to                                                                                                                                                             
                % leverage each helper class's get_data_size() function.                                                                                                                                                        
                f_size = cont.bytes;                                                                                                                                                                                            
            end                                                                                                                                                                                                                 

            % Check the size against our thresholds. It it exceeds either                                                                                                                                                       
            % one, set proc_code to true and construct a message that                                                                                                                                                           
            % describes the situation to the user.                                                                                                                                                                              
            if f_size > 13e7                                                                                                                                                                                                    
                % If the size exceeds our soft working limit of 100mb...                                                                                                                                                        
                message_to_user = sprintf( ...                                                                                                                                                                                  
                    "Your volume exceeds the soft " + ...                                                                                                                                                                       
                    "working limit of 100 mb. We strongly " + ...                                                                                                                                                               
                    "recommend preprocessing your file as you " + ...                                                                                                                                                           
                    "may run into GUI and memory issues " + ...                                                                                                                                                                 
                    "otherwise.\nPreprocess now?");                                                                                                                                                                             
                proc_code = 1;                                                                                                                                                                                                  
            elseif f_size > max_arr & app.TabGroup.SelectedTab ~= app.VideoTrackingTab                                                                                                                                          
                % If the size exceeds 90% of the maximum array size.                                                                                                                                                            
                message_to_user = sprintf( ...                                                                                                                                                                                  
                    "Your volume exceeds remaining " + ...                                                                                                                                                                      
                    "free memory. We strongly recommend " + ...                                                                                                                                                                 
                    "preprocessing your file as you may run " + ...                                                                                                                                                             
                    "into GUI and memory issues otherwise.\n" + ...                                                                                                                                                             
                    "Preprocess now?");                                                                                                                                                                                         
                proc_code = 1;                                                                                                                                                                                                  
            else                                                                                                                                                                                                                
                proc_code = path;                                                                                                                                                                                               
            end                                                                                                                                                                                                                 

            % If either of our thresholds were met...                                                                                                                                                                           
            if proc_code == 1                                                                                                                                                                                                   
                % Prompt a dialog window informing the user of the                                                                                                                                                              
                % threshold that the file crosses and asking them whether                                                                                                                                                       
                % they would like to proceed to preprocessing.                                                                                                                                                                  
                check = uiconfirm(app.CELL_ID, message_to_user, ...                                                                                                                                                             
                    "Warning!", "Options", ["Yes", "No"]);                                                                                                                                                                      

                % If the user decided to switch to the preprocessing                                                                                                                                                            
                % routine, trigger proc_load() and return.                                                                                                                                                                      
                if strcmpi(check, "Yes")                                                                                                                                                                                        
                    app.proc_load(mode, path);                                                                                                                                                                                  
                    return                                                                                                                                                                                                      
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 

            % Set proc_code equal to the file path.                                                                                                                                                                             
            proc_code = path;                                                                                                                                                                                                   

            %if app.EnableautoprofilingMenu.Checked                                                                                                                                                                             
            %    profile off                                                                                                                                                                                                    
            %    p = profile("info");                                                                                                                                                                                           
            %    profsave(p, sprintf("profiler/%s/%s", 'proc_check', datetime));                                                                                                                                                
            %end                                                                                                                                                                                                                
        end                                                                                                                                                                                                                     

        function proc_load(app, mode, path)                                                                                                                                                                                     
            %PROC_LOAD This function loads a given file into the                                                                                                                                                                
            % preprocessing tab.                                                                                                                                                                                                
            %                                                                                                                                                                                                                   
            %   Inputs:                                                                                                                                                                                                         
            %   - app: Running app instance.                                                                                                                                                                                    
            %   - mode: String/char defining whether we're dealing with                                                                                                                                                         
            %       an image or a video.                                                                                                                                                                                        
            %   - path: String/char representing the path to the target                                                                                                                                                         
            %       file.                                                                                                                                                                                                       
            %                                                                                                                                                                                                                   
            %   Note that this function has been rendered obsolete in the                                                                                                                                                       
            %   agnostic volume reader update.                                                                                                                                                                                  
                                                                                                                                                                                                                                
            % Add a task to the progress dialogue indicating that we are                                                                                                                                                        
            % now running the preprocessing routine.                                                                                                                                                                            
            Program.GUI.dialogues.add_task( ...                                                                                                                                                                                 
                'Running pre-processing routine...');                                                                                                                                                                           
                                                                                                                                                                                                                                
            % Initialize properties and caches that facilitate                                                                                                                                                                  
            % preprocessing-related functionality.                                                                                                                                                                              
            app.flags = struct();                                       % Keeps track of which preprocessing actions to perform in which order, should the user opt for previews.                                               
            app.rotation_stack.cache = struct( ...                      % Cache used for cropping & rotation.                                                                                                                   
                'Colormap', {{}}, 'Video', {{}});                                                                                                                                                                               
                                                                                                                                                                                                                                
            % Separate the file path into the parent path, the file name,                                                                                                                                                       
            % and the file type.                                                                                                                                                                                                
            [filepath, name, ext] = fileparts(path);                                                                                                                                                                            
                                                                                                                                                                                                                                
            % Check whether we're dealing with an image or a video.                                                                                                                                                             
            switch mode                                                                                                                                                                                                         
                case "image"                                                                                                                                                                                                    
                    % If we're dealing with an image, add this option to                                                                                                                                                        
                    % the volume selection dropdown.                                                                                                                                                                            
                    if ~any(ismember(app.VolumeDropDown.Items, 'Colormap'))                                                                                                                                                     
                        current_items = app.VolumeDropDown.Items;                                                                                                                                                               
                        current_items(end+1) = {'Colormap'};                                                                                                                                                                    
                        app.VolumeDropDown.Items = current_items;                                                                                                                                                               
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                    % Get the .mat version of this file.                                                                                                                                                                        
                    mat_file = fullfile(filepath, [name, '.mat']);                                                                                                                                                              
                                                                                                                                                                                                                                
                    % If no .mat version of this file exists, create                                                                                                                                                            
                    % one.                                                                                                                                                                                                      
                    if ~isfile(mat_file)                                                                                                                                                                                        
                        [app.image_data, app.image_info, app.image_prefs, ~, ~, ~, ~, ~] = DataHandling.NeuroPALImage.open(path);                                                                                               
                        Program.GUI.dialogues.add_task('Loading color stack configuration...');                                                                                                                                 
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                    % Create a reader object for this new mat file.                                                                                                                                                             
                    Program.GUI.dialogues.step('Creating reader...');                                                                                                                                                           
                    app.proc_image = matfile(mat_file);                                                                                                                                                                         
                                                                                                                                                                                                                                
                    % Get the info and prefs structs from this reader.                                                                                                                                                          
                    info = app.proc_image.info;                                                                                                                                                                                 
                    prefs = app.proc_image.prefs;                                                                                                                                                                               
                                                                                                                                                                                                                                
                    % Check whether the info struct contains the channel                                                                                                                                                        
                    % names.                                                                                                                                                                                                    
                    if isfield(info, 'channel_names')                                                                                                                                                                           
                        % If so, use these to initialize the channel                                                                                                                                                            
                        % struct we'll later use to initialize the                                                                                                                                                              
                        % preprocessing gui.                                                                                                                                                                                    
                        channel = struct( ...                                                                                                                                                                                   
                            'names', {info.channel_names});                                                                                                                                                                     
                    else                                                                                                                                                                                                        
                        % If not, initialize an empty channel struct.                                                                                                                                                           
                        channel = struct( ...                                                                                                                                                                                   
                            'names', {{}});                                                                                                                                                                                     
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                    % If the RGBW channel indices are specified in the                                                                                                                                                          
                    % prefs struct...                                                                                                                                                                                           
                    if ~isempty(prefs.RGBW)                                                                                                                                                                                     
                        % Add these to the channel struct.                                                                                                                                                                      
                        channel.idx = prefs.RGBW(:);                                                                                                                                                                            
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                    % If we have a DIC channel...                                                                                                                                                                               
                    if ~isempty(prefs.DIC) && all(prefs.DIC ~= 0)                                                                                                                                                               
                        % Check whether we have just one DIC channel.                                                                                                                                                           
                        if isscalar(prefs.DIC)                                                                                                                                                                                  
                            % If so, add it to the channel struct's idx                                                                                                                                                         
                            % field.                                                                                                                                                                                            
                            channel.idx = [channel.idx; prefs.DIC];                                                                                                                                                             
                        else                                                                                                                                                                                                    
                            % If we have multiple DIC channels, go with                                                                                                                                                         
                            % the first one encountered in the file. This                                                                                                                                                       
                            % is an assumption based on Eric's claim that                                                                                                                                                       
                            % the two DIC channels are always the same. We                                                                                                                                                      
                            % could come up with a better solution here,                                                                                                                                                        
                            % but since this is a hotfix and the agnostic                                                                                                                                                       
                            % volume reader update is about to render this                                                                                                                                                      
                            % function obsolete, this is the quickest                                                                                                                                                           
                            % solution.                                                                                                                                                                                         
                            channel.idx = [channel.idx; prefs.DIC(1)];                                                                                                                                                          
                        end                                                                                                                                                                                                     
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                                                                                                                                                                                                                                
                    % Do the same thing that we did with the DIC channel                                                                                                                                                        
                    % with the GFP channel.                                                                                                                                                                                     
                    if prefs.GFP ~= 0                                                                                                                                                                                           
                        if isscalar(prefs.GFP)                                                                                                                                                                                  
                            channel.idx = [channel.idx; prefs.GFP];                                                                                                                                                             
                        else                                                                                                                                                                                                    
                            channel.idx = [channel.idx; prefs.GFP(1)];                                                                                                                                                          
                        end                                                                                                                                                                                                     
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                    % Check whether the prefs struct specifies gammas...                                                                                                                                                        
                    channel.gammas = Program.Helpers.expand_gamma(prefs.gamma, 6);                                                                                                                                              
                                                                                                                                                                                                                                
                    % Get the size of the data array.                                                                                                                                                                           
                    vol_size = size(app.proc_image, 'data');                                                                                                                                                                    
                                                                                                                                                                                                                                
                    % Get the sizes of the x, y, z, and c dimensions.                                                                                                                                                           
                    nx = vol_size(2);                                                                                                                                                                                           
                    ny = vol_size(1);                                                                                                                                                                                           
                    nz = vol_size(3);                                                                                                                                                                                           
                    nc = vol_size(4);                                                                                                                                                                                           
                                                                                                                                                                                                                                
                    % Get the maximum pixel value in the image, which we                                                                                                                                                        
                    % later use to define the limit of the GUI components                                                                                                                                                       
                    % that facilitate thresholding. Note that calling                                                                                                                                                           
                    % intmax on the data array's data type would be faster                                                                                                                                                      
                    % as it avoids loading the entire variable, but it also                                                                                                                                                     
                    % distorts the histograms because most image volumes                                                                                                                                                        
                    % we encounter are not fully saturated. That said,                                                                                                                                                          
                    % calling max() on the data array is both slower and,                                                                                                                                                       
                    % depending on the matlab version, can trigger the                                                                                                                                                          
                    % entire data variable to be loaded in.                                                                                                                                                                     
                    % max_val = double(intmax(class(app.proc_image.data(1, 1, 1, 1))));                                                                                                                                         
                    Program.GUI.dialogues.step('Setting threshold...');                                                                                                                                                         
                    max_val = double(max(app.proc_image.data, [], ...                                                                                                                                                           
                        'all'));                                                                                                                                                                                                
                                                                                                                                                                                                                                
                    % Set the volume dropdown value to indicate that the                                                                                                                                                        
                    % user is working with an image.                                                                                                                                                                            
                    app.VolumeDropDown.Value = 'Colormap';                                                                                                                                                                      
                                                                                                                                                                                                                                
                    % Update the data flag to indicate that we've loaded                                                                                                                                                        
                    % an image volume that could be saved to an NWB file                                                                                                                                                        
                    % down the road.                                                                                                                                                                                            
                    app.data_flags.('NeuroPAL_Volume') = 1;                                                                                                                                                                     
                                                                                                                                                                                                                                
                    % Prompt the preprocessing gui to assume its image                                                                                                                                                          
                    % configuration. This is obsolete and should be                                                                                                                                                             
                    % commented out.                                                                                                                                                                                            
                    Program.Routines.GUI.toggle_colormap();                                                                                                                                                                     
                                                                                                                                                                                                                                
                    % Resolve the last task added to the progress dialogue.                                                                                                                                                     
                    Program.GUI.dialogues.resolve();                                                                                                                                                                            
                                                                                                                                                                                                                                
                case "video"                                                                                                                                                                                                    
                    % If we're dealing with a video, add a task to the                                                                                                                                                          
                    % progress dialog indicating that we're now loading                                                                                                                                                         
                    % the video configuration.                                                                                                                                                                                  
                    Program.GUI.dialogues.add_task('Loading video configuration...');                                                                                                                                           
                                                                                                                                                                                                                                
                    % Set the video_path property equal to the given path.                                                                                                                                                      
                    app.video_path = path;                                                                                                                                                                                      
                                                                                                                                                                                                                                
                    % Aadd the video option to the volume selection dropdown.                                                                                                                                                   
                    if ~any(ismember(app.VolumeDropDown.Items, 'Video'))                                                                                                                                                        
                        current_items = app.VolumeDropDown.Items;                                                                                                                                                               
                        current_items(end+1) = {'Video'};                                                                                                                                                                       
                        app.VolumeDropDown.Items = current_items;                                                                                                                                                               
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                    % Call the appropriate video load function based on                                                                                                                                                         
                    % the file type that we are dealing with. Note that                                                                                                                                                         
                    % this does not load in the entire data array, but                                                                                                                                                          
                    % rather loads in metadata that is used to populate                                                                                                                                                         
                    % video-related properties. For details, see the                                                                                                                                                            
                    % video_info property.                                                                                                                                                                                      
                    Program.GUI.dialogues.step('Creating reader...');                                                                                                                                                           
                    if strcmp(ext, '.h5')                                                                                                                                                                                       
                        app.load_h5(path);                                                                                                                                                                                      
                        channel_names = {};                                                                                                                                                                                     
                    elseif strcmp(ext, '.nwb')                                                                                                                                                                                  
                        app.load_nwb(path);                                                                                                                                                                                     
                        channel_names = {};                                                                                                                                                                                     
                    elseif strcmp(ext, '.nd2')                                                                                                                                                                                  
                        app.load_nd2(path);                                                                                                                                                                                     
                        channel_names = DataHandling.Helpers.nd2.get_channel_names(path);                                                                                                                                       
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                    % Get the size of the x, y, z, and c dimensions.                                                                                                                                                            
                    nx = app.video_info.nx;                                                                                                                                                                                     
                    ny = app.video_info.ny;                                                                                                                                                                                     
                    nz = app.video_info.nz;                                                                                                                                                                                     
                    nc = app.video_info.nc;                                                                                                                                                                                     
                                                                                                                                                                                                                                
                    % If we have names for each channel...                                                                                                                                                                      
                    if ~isempty(channel_names)                                                                                                                                                                                  
                        % Parse these channel names associate them with                                                                                                                                                         
                        % colors.                                                                                                                                                                                               
                        channels = Program.Handlers.channels.parse_info( ...                                                                                                                                                    
                            channel_names);                                                                                                                                                                                     
                                                                                                                                                                                                                                
                        % Check for duplicate fluorophores.                                                                                                                                                                     
                        [~, has_duplicate, duplicate_indices] = Program.Validation.check_for_duplicate_fluorophores(channels);                                                                                                  
                        if has_duplicate && ~isempty(duplicate_indices)                                                                                                                                                         
                            % If we have duplicates, update the channel GUI                                                                                                                                                     
                            % appropriately.                                                                                                                                                                                    
                            Program.Handlers.channels.add_reference(channel_names{duplicate_indices})                                                                                                                           
                        end                                                                                                                                                                                                     
                    else                                                                                                                                                                                                        
                        % If we don't have names for each channel,                                                                                                                                                              
                        % initialize them as "Fluo #n" where n is the                                                                                                                                                           
                        % channel index.                                                                                                                                                                                        
                        channel_names = arrayfun(@(x) ...                                                                                                                                                                       
                            sprintf('Fluo #%d', x), 1:nc, ...                                                                                                                                                                   
                            'UniformOutput', false);                                                                                                                                                                            
                    end                                                                                                                                                                                                         
                                                                                                                                                                                                                                
                    % Videos have no info struct, so we create one. Since                                                                                                                                                       
                    % the video tracking tab currently makes no use of                                                                                                                                                          
                    % channels beyond RGB, we don't need to worry about                                                                                                                                                         
                    % these values being correct. This is handles more                                                                                                                                                          
                    % mindfully in the agnostic volume reader update.                                                                                                                                                           
                    info = struct( ...                                                                                                                                                                                          
                        'White', {0}, ...                                                                                                                                                                                       
                        'DIC', {0}, ...                                                                                                                                                                                         
                        'GFP', {0});                                                                                                                                                                                            
                                                                                                                                                                                                                                
                    % Same goes for the channel struct.                                                                                                                                                                         
                    channel = struct( ...                                                                                                                                                                                       
                        'names', {channel_names}, ...                                                                                                                                                                           
                        'idx', {1:length(channel_names)}, ...                                                                                                                                                                   
                        'gammas', {ones(1, length(channel_names))});                                                                                                                                                            
                                                                                                                                                                                                                                
                    % Retrieve a frame and call intmax to determine the                                                                                                                                                         
                    % maximum pixel value, which we will later use to                                                                                                                                                           
                    % configure thresholding-related GUI components.                                                                                                                                                            
                    Program.GUI.dialogues.step('Setting threshold...');                                                                                                                                                         
                    test_frame = app.retrieve_frame(3);                                                                                                                                                                         
                    max_val = double(intmax(class(test_frame)));                                                                                                                                                                
                                                                                                                                                                                                                                
                    % Initialize video-related video components. This is                                                                                                                                                        
                    % largely obsolete at this point and can be commented                                                                                                                                                       
                    % out -- see the preprocessing_gui class for details.                                                                                                                                                       
                    app.ProcTStartEditField.Value = 1;                                                                                                                                                                          
                    app.ProcTStopEditField.Value = app.video_info.nt;                                                                                                                                                           
                    app.StartFrameEditField.Value = 1;                                                                                                                                                                          
                    app.EndFrameEditField.Value = app.video_info.nt;                                                                                                                                                            
                    app.proc_tSlider.Limits = [1 app.video_info.nt];                                                                                                                                                            
                    app.proc_tSlider.Value = 1;                                                                                                                                                                                 
                                                                                                                                                                                                                                
                    app.ProcAxGrid.RowHeight{end+1} = 'fit';                                                                                                                                                                    
                    app.PlaceholderProcTimeline.Parent = app.ProcAxGrid;                                                                                                                                                        
                    app.PlaceholderProcTimeline.Layout.Row = max(size(app.ProcAxGrid.RowHeight));                                                                                                                               
                    app.PlaceholderProcTimeline.Layout.Column = [1 max(size(app.ProcAxGrid.ColumnWidth))];                                                                                                                      
                                                                                                                                                                                                                                
                    app.VolumeDropDown.Value = 'Video';                                                                                                                                                                         
                    app.data_flags.('Video_Volume') = 1;                                                                                                                                                                        
                                                                                                                                                                                                                                
                    set(app.PlaceholderProcTimeline, 'Visible', 'on');                                                                                                                                                          
                    Program.Routines.GUI.toggle_video();                                                                                                                                                                        
                    Program.GUI.dialogues.resolve();                                                                                                                                                                            
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            % Construct a persistent preprocessing_gui class instance.                                                                                                                                                          
            Program.GUI.dialogues.set_value(1/5);                                                                                                                                                                               
            Program.GUI.dialogues.step('Loading gui config...');                                                                                                                                                                
            preprocessing_gui = Program.GUI.preprocessing_gui(mode);                                                                                                                                                            
            app.create_backup();                                                                                                                                                                                                
                                                                                                                                                                                                                                
                                                                                                                                                                                                                                
            % Use the maximum pixel intensity to set the limit of                                                                                                                                                               
            % thresholding-related GUI components.                                                                                                                                                                              
            Program.GUI.dialogues.step('Setting bounds...');                                                                                                                                                                    
            Program.GUI.dialogues.set_value(2/5);                                                                                                                                                                               
            Program.GUIHandling.set_thresholds(app, max_val);                                                                                                                                                                   
                                                                                                                                                                                                                                
            % Use the channel struct to initialize our channel-related GUI                                                                                                                                                      
            % components.                                                                                                                                                                                                       
            Program.GUI.dialogues.step('Mapping channels...');                                                                                                                                                                  
            Program.GUI.dialogues.set_value(3/5);                                                                                                                                                                               
            Program.Handlers.channels.populate(channel)                                                                                                                                                                         
            if strcmp(mode, "colormap")                                                                                                                                                                                         
                Program.Handlers.channels.set_references(info);                                                                                                                                                                 
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            % Initialize the nameMap and shortMap properties, which are                                                                                                                                                         
            % container maps that assign names to each channel. Note that                                                                                                                                                       
            % this is largely obsolete (in case the fact that these are                                                                                                                                                         
            % still container maps rather than dictionaries didn't give                                                                                                                                                         
            % that fact away) and can be removed once we have time to                                                                                                                                                           
            % ensure that doing so doesn't break anything.                                                                                                                                                                      
            app.nameMap = containers.Map( ...                                                                                                                                                                                   
                {app.ProcRDropDown.Value, app.ProcGDropDown.Value, app.ProcBDropDown.Value, ...                                                                                                                                 
                 app.ProcWDropDown.Value, app.ProcDICDropDown.Value, app.ProcGFPDropDown.Value}, ...                                                                                                                            
                {'Red', 'Green', 'Blue', 'White', 'DIC', 'GFP'} ...                                                                                                                                                             
            );                                                                                                                                                                                                                  
                                                                                                                                                                                                                                
            app.shortMap = containers.Map( ...                                                                                                                                                                                  
                {app.ProcRDropDown.Value, app.ProcGDropDown.Value, app.ProcBDropDown.Value, ...                                                                                                                                 
                 app.ProcWDropDown.Value, app.ProcDICDropDown.Value, app.ProcGFPDropDown.Value}, ...                                                                                                                            
                {'r', 'g', 'b', 'k', 'k', 'y'} ...                                                                                                                                                                              
            );                                                                                                                                                                                                                  
                                                                                                                                                                                                                                
            % Set the data aspect ratio of the xy Axes to [1 1 1].                                                                                                                                                              
            Program.GUI.dialogues.step('Initializing settings...');                                                                                                                                                             
            Program.GUI.dialogues.set_value(4/5);                                                                                                                                                                               
            daspect(app.proc_xyAxes, [1 1 1]);                                                                                                                                                                                  
                                                                                                                                                                                                                                
            % If we have less than 4 channels, set the grid layout                                                                                                                                                              
            % containing color channel histograms to only use one row, as                                                                                                                                                       
            % we have three columns per row.                                                                                                                                                                                    
            if nc < 4                                                                                                                                                                                                           
                app.ProcHistogramGrid.RowHeight = {'1x'};                                                                                                                                                                       
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            if nx ~= 1                                                                                                                                                                                                          
                app.proc_xSlider.Limits = [1 nx];                                                                                                                                                                               
            else                                                                                                                                                                                                                
                error("Loaded volume has invalid dimensions:\n" + ...                                                                                                                                                           
                    "nx = %.f\n" + ...                                                                                                                                                                                          
                    "ny = %.f\n" + ...                                                                                                                                                                                          
                    "nz = %.f\n" + ...                                                                                                                                                                                          
                    "nc = %.f\n", ...                                                                                                                                                                                           
                    nx, ny, nz, nc)                                                                                                                                                                                             
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            if ny ~= 1                                                                                                                                                                                                          
                app.proc_ySlider.Limits = [1 ny];                                                                                                                                                                               
            else                                                                                                                                                                                                                
                error("Loaded volume has invalid dimensions:\n" + ...                                                                                                                                                           
                    "nx = %.f\n" + ...                                                                                                                                                                                          
                    "ny = %.f\n" + ...                                                                                                                                                                                          
                    "nz = %.f\n" + ...                                                                                                                                                                                          
                    "nc = %.f\n", ...                                                                                                                                                                                           
                    nx, ny, nz, nc)                                                                                                                                                                                             
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            if nz ~= 1                                                                                                                                                                                                          
                Program.Helpers.configure_processing_zsliders(app, nz, round(nz/2));                                                                                                                                            
            else                                                                                                                                                                                                                
                error("Loaded volume has invalid dimensions:\n" + ...                                                                                                                                                           
                    "nx = %.f\n" + ...                                                                                                                                                                                          
                    "ny = %.f\n" + ...                                                                                                                                                                                          
                    "nz = %.f\n" + ...                                                                                                                                                                                          
                    "nc = %.f\n", ...                                                                                                                                                                                           
                    nx, ny, nz, nc)                                                                                                                                                                                             
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            app.proc_xSlider.Value = round(app.proc_xSlider.Limits(2)/2);                                                                                                                                                       
            app.proc_ySlider.Value = round(app.proc_ySlider.Limits(2)/2);                                                                                                                                                       
            app.proc_tEditField.Value = round(app.proc_tSlider.Value);                                                                                                                                                          
                                                                                                                                                                                                                                
            app.ProcZSlicesEditField.Value = app.proc_zSlider.Value;                                                                                                                                                            
            app.ProcXYFactorEditField.Enable = 'on';                                                                                                                                                                            
            app.ProcZSlicesEditField.Enable = 'on';                                                                                                                                                                             
                                                                                                                                                                                                                                
            set(app.proc_xEditField, 'Enable', 'off');                                                                                                                                                                          
            set(app.proc_yEditField, 'Enable', 'off');                                                                                                                                                                          
                                                                                                                                                                                                                                
            gamma_values = channel.gammas(:);                                                                                                                                                                                   
            if isempty(gamma_values)                                                                                                                                                                                            
                app.tl_GammaEditField.Value = 1;                                                                                                                                                                                
                app.tm_GammaEditField.Value = 1;                                                                                                                                                                                
                app.tr_GammaEditField.Value = 1;                                                                                                                                                                                
                app.bl_GammaEditField.Value = 1;                                                                                                                                                                                
                app.bm_GammaEditField.Value = 1;                                                                                                                                                                                
                app.br_GammaEditField.Value = 1;                                                                                                                                                                                
            else                                                                                                                                                                                                                
                for n=1:min(numel(gamma_values), 6)                                                                                                                                                                             
                    switch n                                                                                                                                                                                                    
                        case 1                                                                                                                                                                                                  
                            app.tl_GammaEditField.Value = gamma_values(1);                                                                                                                                                      
                        case 2                                                                                                                                                                                                  
                            app.tm_GammaEditField.Value = gamma_values(2);                                                                                                                                                      
                        case 3                                                                                                                                                                                                  
                            app.tr_GammaEditField.Value = gamma_values(3);                                                                                                                                                      
                        case 4                                                                                                                                                                                                  
                            app.bl_GammaEditField.Value = gamma_values(4);                                                                                                                                                      
                        case 5                                                                                                                                                                                                  
                            app.bm_GammaEditField.Value = gamma_values(5);                                                                                                                                                      
                        case 6                                                                                                                                                                                                  
                            app.br_GammaEditField.Value = gamma_values(6);                                                                                                                                                      
                    end                                                                                                                                                                                                         
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            if strcmp(mode, "image")                                                                                                                                                                                            
                Program.Helpers.sync_processing_from_main(app, mat_file);                                                                                                                                                       
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            % Draw the image.                                                                                                                                                                                                   
            Program.GUI.dialogues.set_value(5/5);                                                                                                                                                                               
            Program.GUI.dialogues.add_task('Rendering slice...');                                                                                                                                                               
            app.drawProcImage();                                                                                                                                                                                                
            Program.GUI.dialogues.resolve();                                                                                                                                                                                    
                                                                                                                                                                                                                                
            % Set the preprocessing tab to be visible.                                                                                                                                                                          
            app.ImageProcessingTab.Tag = 'rendered';                                                                                                                                                                            
            set(app.ProcessingButton, 'Visible', 'off');                                                                                                                                                                        
            set(app.ProcessingGridLayout, 'Visible', 'on');                                                                                                                                                                     
                                                                                                                                                                                                                                
            app.TabGroup.SelectedTab = app.ImageProcessingTab;                                                                                                                                                                  
            Program.GUI.dialogues.resolve();                                                                                                                                                                                    
                                                                                                                                                                                                                                
            % Tell the user that we recommend starting by cropping their                                                                                                                                                        
            % image and ask them if that's what they'd like to do. If so,                                                                                                                                                       
            % trigger the cropping routine.                                                                                                                                                                                     
            check = uiconfirm(app.CELL_ID, "We recommend " + ...                                                                                                                                                                
                "starting by cropping your image to ensure " + ...                                                                                                                                                              
                "that there is no superfluous space taking up " + ...                                                                                                                                                           
                "memory. Do you want to do so now?", ...                                                                                                                                                                        
                "NeuroPAL_ID", ...                                                                                                                                                                                              
                "Options", ["Yes", "No, skip cropping."]);                                                                                                                                                                      
                                                                                                                                                                                                                                
            switch check                                                                                                                                                                                                        
                case "Yes"                                                                                                                                                                                                      
                    app.ProcCropImageButtonPushed([]);                                                                                                                                                                          
                    Program.GUIHandling.gui_lock(app, 'unlock', 'processing_tab');                                                                                                                                              
                case "No, skip cropping."                                                                                                                                                                                       
                    Program.GUIHandling.gui_lock(app, 'unlock', 'processing_tab');                                                                                                                                              
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            % Unlock the preprocessing GUI.                                                                                                                                                                                     
            Program.GUIHandling.gui_lock(app, 'unlock', 'processing_tab');                                                                                                                                                      
        end                                                                                                                                                                                                                     
                                                                                                                                                                                                                                

        function frame = retrieve_frame(app, t)                                                                                                                                                                                 
            %RETRIEVE_FRAME This function loads in a given frame from the                                                                                                                                                       
            % currently loaded image.                                                                                                                                                                                           
            %                                                                                                                                                                                                                   
            %   Inputs:                                                                                                                                                                                                         
            %   - app: Running app instance.                                                                                                                                                                                    
            %   - t: Integer representing the frame to be loaded.                                                                                                                                                               
            %                                                                                                                                                                                                                   
            %   Outputs:                                                                                                                                                                                                        
            %   - frame: Multi-dimensional array representing the frame                                                                                                                                                         
            %       as loaded from the video file.                                                                                                                                                                              
            %                                                                                                                                                                                                                   
            %   Note that this function is rendered obsolete by the                                                                                                                                                             
            %   agnostic volume reader update.                                                                                                                                                                                  

            % If no frame was passed, retrieve the first frame.                                                                                                                                                                 
            if ~exist('t', 'var')                                                                                                                                                                                               
                t = 1;                                                                                                                                                                                                          
            end                                                                                                                                                                                                                 

            % Round the frame to ensure we don't try to load an invalid                                                                                                                                                         
            % index.                                                                                                                                                                                                            
            t = round(t);                                                                                                                                                                                                       

            % Check which file type we're dealing with. Different file                                                                                                                                                          
            % types required diffferent chunk loading approaches.                                                                                                                                                               
            if endsWith(app.video_info.file, '.h5')                                                                                                                                                                             
                % If we're dealing with an h5 file, call h5info on the                                                                                                                                                          
                % dataset labeled "data".                                                                                                                                                                                       
                validated_info = h5info(app.video_info.file, '/data');                                                                                                                                                          

                % Check whether the file's metadata specifies a chunk                                                                                                                                                           
                % size. Note that h5 files support indexing without                                                                                                                                                             
                % this chunk size, but retrieving it ensures that we                                                                                                                                                            
                % don't run into indexing errors.                                                                                                                                                                               
                if isfield(validated_info, 'ChunkSize')                                                                                                                                                                         
                    % Get this chunk size.                                                                                                                                                                                      
                    chunk_size = validated_info.ChunkSize;                                                                                                                                                                      

                    % Swap the x and y dimensions.                                                                                                                                                                              
                    if chunk_size(1)>chunk_size(2)                                                                                                                                                                              
                        chunk_size(1:2) = permute(chunk_size(1:2), [2 1]);                                                                                                                                                      
                    end                                                                                                                                                                                                         
                else                                                                                                                                                                                                            
                    % If no chunk size is specified in the metadata, we                                                                                                                                                         
                    % use the image dimensions.                                                                                                                                                                                 
                    chunk_size = [app.video_info.nx, ...                                                                                                                                                                        
                        app.video_info.ny, ...                                                                                                                                                                                  
                        app.video_info.nz, ...                                                                                                                                                                                  
                        app.video_info.nc, ...                                                                                                                                                                                  
                        app.video_info.nc, 1];                                                                                                                                                                                  
                end                                                                                                                                                                                                             

                % Call h5read.                                                                                                                                                                                                  
                frame = h5read(app.video_info.file, '/data', ...                                                                                                                                                                
                    [1 1 1 1 t], ...                                    % Where to start reading.                                                                                                                               
                    chunk_size);                                                                                                                                                                                                

            elseif endsWith(app.video_info.file, '.nwb')                                                                                                                                                                        
                % If this is an NWB file, check to make sure that we're                                                                                                                                                         
                % not accidentally zero-indexing.                                                                                                                                                                               
                if t == 0                                                                                                                                                                                                       
                    t = 1;                                                                                                                                                                                                      
                end                                                                                                                                                                                                             

                % Use the frame to index the nwb_mod property, which points                                                                                                                                                     
                % to the video's acquisition module.                                                                                                                                                                            
                frame = app.nwb_mod(:, :, :, :, t);                                                                                                                                                                             

                % Convert to uin8 and rearrange the array. This is a                                                                                                                                                            
                % temporary hardcoded fix for Daniel's files and is handled                                                                                                                                                     
                % more mindfully in the agnostic volume reader update. We                                                                                                                                                       
                % already have a more dynamic solution in the nwb helper                                                                                                                                                        
                % class, which we could also leverage here.                                                                                                                                                                     
                frame = uint8(squeeze(permute(frame, [3 4 2 1])));                                                                                                                                                              

            elseif endsWith(app.video_info.file, '.nd2')                                                                                                                                                                        
                % If this is an nd2 file, generate a BioformatsImage                                                                                                                                                            
                % object.                                                                                                                                                                                                       
                file = BioformatsImage(app.video_info.file);                                                                                                                                                                    

                % Initialize a zero array of the size and class that we                                                                                                                                                         
                % expect our target frame to be.                                                                                                                                                                                
                all_data = zeros( ...                                                                                                                                                                                           
                    [app.video_info.ny, ...                                                                                                                                                                                     
                    app.video_info.nx, ...                                                                                                                                                                                      
                    app.video_info.nz, ...                                                                                                                                                                                      
                    app.video_info.nc, 1], ...                                                                                                                                                                                  
                    class(getPlane(file, 1, 1, 1)));                                                                                                                                                                            

                % Get the channel names.                                                                                                                                                                                        
                channelNames = file.channelNames;                                                                                                                                                                               

                % Iterate over each channel.                                                                                                                                                                                    
                for c = 1:app.video_info.nc                                                                                                                                                                                     
                    % Get this channel's name.                                                                                                                                                                                  
                    channel = channelNames{c};                                                                                                                                                                                  

                    % Iterate over each slice.                                                                                                                                                                                  
                    for z = 1:app.video_info.nz                                                                                                                                                                                 
                        % Populate our previously initialized zero array                                                                                                                                                        
                        % with the appropriate plane from the nd2 file by                                                                                                                                                       
                        % passing the file, the slice, the channel name,                                                                                                                                                        
                        % and the frame to the bioformats toolbox's                                                                                                                                                             
                        % getPlane() function. Note that bioformats'                                                                                                                                                            
                        % lazy loading of ND2 files is based on planes                                                                                                                                                          
                        % and indices, so you're effectively left with                                                                                                                                                          
                        % no option but to go plane by plane.                                                                                                                                                                   
                        all_data(:, :, z, c, 1) = getPlane( ...                                                                                                                                                                 
                            file, z, channel, t);                                                                                                                                                                               
                    end                                                                                                                                                                                                         
                end                                                                                                                                                                                                             

                frame = squeeze(all_data);                                                                                                                                                                                      

            elseif endsWith(app.video_info.file, '.tif')                                                                                                                                                                        
                % If this is a tif file, we essentially do the same thing                                                                                                                                                       
                % because tif reading is also handled by bioformats.                                                                                                                                                            
                file = BioformatsImage(app.video_info.file);                                                                                                                                                                    
                all_data = zeros( ...                                                                                                                                                                                           
                    [app.video_info.ny, ...                                                                                                                                                                                     
                    app.video_info.nx, ...                                                                                                                                                                                      
                    app.video_info.nz, ...                                                                                                                                                                                      
                    app.video_info.nc, 1], ...                                                                                                                                                                                  
                    sprintf('uint%.f', file.bitDepth));                                                                                                                                                                         

                for c = 1:app.video_info.nc                                                                                                                                                                                     
                    for z = 1:app.video_info.nz                                                                                                                                                                                 
                        all_data(:, :, z, c, 1) = getPlane(file, z, c, t);                                                                                                                                                      
                    end                                                                                                                                                                                                         
                end                                                                                                                                                                                                             

                frame = squeeze(all_data);                                                                                                                                                                                      
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     

        function [image_data, image_view] = DrawProcData(app, image_data)                                                                                                                                                       
            %DRAWPROCDATA This function takes a given data array and                                                                                                                                                            
            % processed it based on user-defined gamma values, histogram                                                                                                                                                        
            % limits, etc. This is largely obsolete.                                                                                                                                                                            
            %                                                                                                                                                                                                                   
            %   Inputs:                                                                                                                                                                                                         
            %   - app: Running app instance.                                                                                                                                                                                    
            %   - image_data: The data array to be processed. For example,                                                                                                                                                      
            %       a slice or a frame of an image or a video.                                                                                                                                                                  
            %                                                                                                                                                                                                                   
            %   Outputs:                                                                                                                                                                                                        
            %   - image_data: The original image data array.                                                                                                                                                                    
            %   - image_view: The processed data array.                                                                                                                                                                         

            % Get the current gamma values.                                                                                                                                                                                     
            gammas = [ ...                                                                                                                                                                                                      
                app.tl_GammaEditField.Value, ...                                                                                                                                                                                
                app.tm_GammaEditField.Value, ...                                                                                                                                                                                
                app.tr_GammaEditField.Value, ...                                                                                                                                                                                
                app.bl_GammaEditField.Value, ...                                                                                                                                                                                
                app.bm_GammaEditField.Value, ...                                                                                                                                                                                
                app.br_GammaEditField.Value];                                                                                                                                                                                   

            % Determine the color channel indices.                                                                                                                                                                              
            red = str2double(app.ProcRDropDown.Value);                                                                                                                                                                          
            green = str2double(app.ProcGDropDown.Value);                                                                                                                                                                        
            blue = str2double(app.ProcBDropDown.Value);                                                                                                                                                                         
            white = str2double(app.ProcWDropDown.Value);                                                                                                                                                                        
            dic = str2double(app.ProcDICDropDown.Value);                                                                                                                                                                        
            gfp = str2double(app.ProcGFPDropDown.Value);                                                                                                                                                                        

            % Determine the channel=color assignments for displaying.                                                                                                                                                           
            color_indices = [red, green, blue];                                                                                                                                                                                 

            try                                                                                                                                                                                                                 
                % Draw the 3 color channels.                                                                                                                                                                                    
                image_view = image_data(:,:,:,color_indices);                                                                                                                                                                   
            catch                                                                                                                                                                                                               
                image_view = image_data(:, :, :,1:3);                                                                                                                                                                           
            end                                                                                                                                                                                                                 

            % Remove unchecked color channels.                                                                                                                                                                                  
            if ~app.ProcRCheckBox.Value % Red                                                                                                                                                                                   
                image_view(:,:,:,1) = 0;                                                                                                                                                                                        
            end                                                                                                                                                                                                                 
            if ~app.ProcGCheckBox.Value % Green                                                                                                                                                                                 
                image_view(:,:,:,2) = 0;                                                                                                                                                                                        
            end                                                                                                                                                                                                                 
            if ~app.ProcBCheckBox.Value % Blue                                                                                                                                                                                  
                image_view(:,:,:,3) = 0;                                                                                                                                                                                        
            end                                                                                                                                                                                                                 

            % Add in the white channel.                                                                                                                                                                                         
            if app.ProcWCheckBox.Value % White                                                                                                                                                                                  

                % Compute the white channel.                                                                                                                                                                                    
                wchannel = image_data(:,:,:,white);                                                                                                                                                                             

                % Adjust the gamma.                                                                                                                                                                                             
                if length(gammas) >= white && gammas(white) ~= 1                                                                                                                                                                
                    wchannel = imadjustn(wchannel,[],[], gammas(white));                                                                                                                                                        
                end                                                                                                                                                                                                             

                % Add the white channel.                                                                                                                                                                                        
                image_view = image_view + repmat(wchannel, [1,1,1,3]);                                                                                                                                                          
            end                                                                                                                                                                                                                 

            % Add in the DIC channel.                                                                                                                                                                                           
            if app.ProcDICCheckBox.Value % DIC                                                                                                                                                                                  

                % Compute the DIC channel.                                                                                                                                                                                      
                dic_channel = image_data(:,:,:,dic);                                                                                                                                                                            

                % Adjust the gamma.                                                                                                                                                                                             
                if length(gammas) >= dic && gammas(dic) ~= 1                                                                                                                                                                    
                    dic_channel = imadjustn(dic_channel,[],[], gammas(dic));                                                                                                                                                    
                end                                                                                                                                                                                                             

                % Add the DIC channel.                                                                                                                                                                                          
                image_view = image_view + repmat(dic_channel, [1,1,1,3]);                                                                                                                                                       
            end                                                                                                                                                                                                                 

            % Add in the GFP channel.                                                                                                                                                                                           
            if app.ProcGFPCheckBox.Value % GFP                                                                                                                                                                                  

                % Compute the GFP channel.                                                                                                                                                                                      
                gfp_channel = image_data(:,:,:,gfp);                                                                                                                                                                            

                % Adjust the gamma.                                                                                                                                                                                             
                if length(gammas) >= gfp && gammas(gfp) ~= 1                                                                                                                                                                    
                    gfp_channel = imadjustn(gfp_channel,[],[], gammas(gfp));                                                                                                                                                    
                end                                                                                                                                                                                                             

                % Add the GFP channel.                                                                                                                                                                                          
                gfp_channel = repmat(gfp_channel, [1,1,1,3]);                                                                                                                                                                   
                gfp_channel(:,:,:,~gfp_color) = 0;                                                                                                                                                                              
                image_view = image_view + gfp_channel;                                                                                                                                                                          
            end                                                                                                                                                                                                                 

            % Adjust the gamma.                                                                                                                                                                                                 
            % Note: the image only shows RGB. We added the other channels                                                                                                                                                       
            % (W, DIC, GFP) to the RGB in order to show these as well.                                                                                                                                                          
            image_view = uint16(double(intmax('uint16')) * ...                                                                                                                                                                  
                double(image_view)/double(max(image_view(:))));                                                                                                                                                                 
            for c = 1:size(image_view, 4)                                                                                                                                                                                       
                if gammas(c) ~= 1                                                                                                                                                                                               
                    image_view(:,:,:,c) = ...                                                                                                                                                                                   
                        imadjustn(squeeze(image_view(:,:,:,c)),[],[], ...                                                                                                                                                       
                        gammas(c));                                                                                                                                                                                             
                end                                                                                                                                                                                                             
            end                                                                                                                                                                                                                 

            image_view = double(image_view)/double(max(image_view(:)));                                                                                                                                                         
        end                                                                                                                                                                                                                     


        function proc_apply_processing(app, specific_action)                                                                                                                                                                    
            %PROC_APPLY_PROCESSING This function triggers the routines                                                                                                                                                          
            % that apply preprocessing actions to a file.                                                                                                                                                                       
            %                                                                                                                                                                                                                   
            %   Inputs:                                                                                                                                                                                                         
            %   - app: Running app instance.                                                                                                                                                                                    
            %   - specific_action: Optional argument specifying that we                                                                                                                                                         
            %       are applying a specific method rather than all                                                                                                                                                              
            %       preprocessing actions that have been been added to                                                                                                                                                          
            %       the stack by the user.                                                                                                                                                                                      
                                                                                                                                                                                                                                
            % Add a task to the progress dialogue indicating that the file                                                                                                                                                      
            % is being updated.                                                                                                                                                                                                 
            Program.GUI.dialogues.add_task("Updating file...");                                                                                                                                                                 

            % Check whether specific actions were requested.                                                                                                                                                                    
            if ~exist('specific_action', 'var')                                                                                                                                                                                 
                % If not, we grab the entire stack of preprocessing                                                                                                                                                             
                % actions taken and then clear.                                                                                                                                                                                 
                preprocessing_actions = fieldnames(app.flags);                                                                                                                                                                  
                app.flags = struct();                                                                                                                                                                                           
            else                                                                                                                                                                                                                
                % If specific actions were requested, we package these                                                                                                                                                          
                % into a cell array.                                                                                                                                                                                            
                preprocessing_actions = {specific_action};                                                                                                                                                                      
            end                                                                                                                                                                                                                 

            % Check whether we're working with an image or a video.%%%%                                                                                                                                                         
            switch app.VolumeDropDown.Value                                                                                                                                                                                     
                case 'Colormap'                                                                                                                                                                                                 
                    % If we're working with an image, call the appropriate                                                                                                                                                      
                    % function within the ChunkyMethods class.                                                                                                                                                                  
                    Methods.ChunkyMethods.apply_colormap(app, ...                                                                                                                                                               
                        preprocessing_actions);                                                                                                                                                                                 

                    % Use the image file's path to initialize an event                                                                                                                                                          
                    % struct.                                                                                                                                                                                                   
                    fEvent.file = app.proc_image.Properties.Source;                                                                                                                                                             

                    % Call the OpenFile function.                                                                                                                                                                               
                    app.OpenFile(fEvent);                                                                                                                                                                                       

                case 'Video'                                                                                                                                                                                                    
                    % If we're working with a video, call the appropriate                                                                                                                                                       
                    % function within the chunky methods class. Note that                                                                                                                                                       
                    % because the tracking tab works off of the video_info                                                                                                                                                      
                    % struct property and we update this within the                                                                                                                                                             
                    % apply_video function, we don't need to explicitly                                                                                                                                                         
                    % pass the processed video back to the open_video                                                                                                                                                           
                    % function. This isn't a great way of doing things                                                                                                                                                          
                    % and is handled more mindfully in the agnostic volume                                                                                                                                                      
                    % reader update.                                                                                                                                                                                            
                    Methods.ChunkyMethods.apply_video(app, ...                                                                                                                                                                  
                        preprocessing_actions);                                                                                                                                                                                 
            end                                                                                                                                                                                                                 
                                                                                                                                                                                                                                
            % Resolve the last task added to the progress dialog.                                                                                                                                                               
            Program.GUI.dialogues.resolve();                                                                                                                                                                                    

            % Let the user know that the file has been successfully                                                                                                                                                             
            % updated.                                                                                                                                                                                                          
            uiconfirm(app.CELL_ID, "Successfully updated file.", ...                                                                                                                                                            
                "NeuroPAL_ID", "Options", ["OK"]);                                                                                                                                                                              
        end                                                                                                                                                                                                                     
                                                                                                                                                                                                                                
        function proc_apply_settings(app)                                                                                                                                                                                       
            % Propagates updates to colormap preferences, which must be                                                                                                                                                         
            % done explicitly if changed in processing tab since it                                                                                                                                                             
            % operates on lazy load.                                                                                                                                                                                            

            % Grab channel indices.                                                                                                                                                                                             
            channels = [app.RDropDown.Value, app.GDropDown.Value, app.BDropDown.Value, app.WDropDown.Value];                                                                                                                    
                                                                                                                                                                                                                                
            % Grab channel gammas.                                                                                                                                                                                              
            gammas = [app.tl_GammaEditField.Value, app.tm_GammaEditField.Value, app.tr_GammaEditField.Value, app.bl_GammaEditField.Value, app.bm_GammaEditField.Value, app.br_GammaEditField.Value];                            

            switch lower(app.VolumeDropDown.Value)                                                                                                                                                                              
                case 'colormap'                                                                                                                                                                                                 
                    % Set file writable & update settings.                                                                                                                                                                      
                    app.proc_image.Properties.Writable = true;                                                                                                                                                                  
                    temp_prefs = app.proc_image.prefs;                                                                                                                                                                          
                    temp_prefs.rgbw = channels;                                                                                                                                                                                 
                    temp_prefs.DIC = app.ProcDICDropDown.Value;                                                                                                                                                                 
                    temp_prefs.GFP = app.ProcGFPDropDown.Value;                                                                                                                                                                 
                    temp_prefs.gamma = gammas;                                                                                                                                                                                  
                    app.proc_image.prefs = temp_prefs;                                                                                                                                                                          
                    app.proc_image.Properties.Writable = false;                                                                                                                                                                 
                case 'video'                                                                                                                                                                                                    
                    % tbd                                                                                                                                                                                                       
            end                                                                                                                                                                                                                 
        end                                                                                                                                                                                                                     
    end                                                                                                                                                                                                                         


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Program.Routines.initialize()
        end

        % Callback function: IdButton, OpenButton, OpenMenu
        function OpenFile(app, event)
            % Are we already opening a file?
            if app.is_opening_file
                return;
            end
            app.is_opening_file = true;
            
            % Unselect any neurons.
            UnselectNeuron(app);
            if ~isempty(app.id_file) && exist(app.id_file, 'file')
                app.SaveIDToFile();
            end

            % Setup the file chooser's path.
            %path = '../';
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            if isfield(event, 'file')
                [path, name] = fileparts(event.file);
                filename = event.file;
    
                % Load the file.
                Program.GUI.dialogues.add_task('Reloading processed file...');
    
                if app.DisplayNeuronActivityMenu.Checked
                    app.DisplayNeuronActivityMenu.Checked = ~app.DisplayNeuronActivityMenu.Checked;
                    app.TabGroup4.SelectedTab = app.MaximumIntensityProjectionTab;
                end

                app.trigger_reload = 0;
            else
                % Ask the user which file they want.
                file_info = [path ';*.mat;*.czi;*.nd2;*.tif;*.tiff;*.h5;*.nwb'];
                app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
                [name, path, ~] = uigetfile(file_info, 'Select Worm Image');
                app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
                if name == 0
                    app.is_opening_file = false;
                    return; % user cancelled
                end
    
                % Load the file.
                Program.GUI.dialogues.add_task('Loading file...');
    
                if app.DisplayNeuronActivityMenu.Checked
                    app.DisplayNeuronActivityMenu.Checked = ~app.DisplayNeuronActivityMenu.Checked;
                    app.TabGroup4.SelectedTab = app.MaximumIntensityProjectionTab;
                end
    
                filename = [path, name];
                Program.GUI.dialogues.resolve();

                % Save the path in our preferences.
                GUI_prefs.image_dir = path;
                GUI_prefs.save();

                proc_code = app.proc_check("image", filename);
                if proc_code == 1
                    app.is_opening_file = false;
                    return
                end
            end

            app.logEvent('Main',sprintf('Loading file from %s...', filename), 1)

            % Save the path in our preferences.
            GUI_prefs.image_dir = path;
            GUI_prefs.save();
            try                
                [data, info, prefs, worm, mp, neurons, np_file, id_file] = ...
                    DataHandling.NeuroPALImage.open(filename);
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {['Cannot read "' filename '"!'], ['Error:' msg]}, ...
                    'Image File Failure', 'Icon', 'error');
                return;
            end

            % Check the worm info.
            if ~any(strcmp(worm.body, app.BodyDropDown.Items))
                uialert(app.CELL_ID, ...
                    ['Unrecognized worm body region "' worm.body '" in "' filename '"!'], ...
                    'File Format Failure', 'Icon', 'error');
                return;
            end
            if ~any(strcmp(worm.age, app.AgeDropDown.Items))
                uialert(app.CELL_ID, ...
                    ['Unrecognized worm age region "' worm.age '" in "' filename '"!'], ...
                    'File Format Failure', 'Icon', 'error');
                return;
            end
            if ~any(strcmp(worm.sex, app.SexDropDown.Items))
                uialert(app.CELL_ID, ...
                    ['Unrecognized worm sex region "' worm.sex '" in "' filename '"!'], ...
                    'File Format Failure', 'Icon', 'error');
                return;
            end

            % Fix the prefs for z-axis orientation.
            if ~isfield(prefs, 'z_center')
                prefs.z_center = ceil(size(data,3) / 2);
                prefs.is_Z_LR = true;
                prefs.is_Z_flip = true;
            end

            % Setup the file.
            app.image_file = np_file;
            app.id_file = [];
            app.image_prefs = prefs;

            % Setup the image.
            app.image_name = name; %strrep(name, '_', '\_');
            app.image_data = data;

            % Z-score the image.
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Load and update the gamma.
            gamma_size = length(app.gamma_RGBW_DIC_GFP_index);
            if isscalar(prefs.gamma)
                app.image_gamma = ones(gamma_size, 1);
                app.image_gamma(1:3) = prefs.gamma;
                app.image_prefs.gamma = app.image_gamma;
            elseif length(prefs.gamma) < gamma_size
                app.image_gamma = ones(gamma_size, 1);
                app.image_gamma(1:length(prefs.gamma)) = prefs.gamma;
                app.image_prefs.gamma = app.image_gamma;
            else
                app.image_gamma = prefs.gamma;
            end

            % Load the image scale and info.
            app.image_um_scale = info.scale;
            app.image_info = info;

            % Setup the color channels.
            RGBW = prefs.RGBW;
            RGBW_nan = isnan(RGBW);
            RGBW(RGBW_nan) = 1; % default unassigned colors to channel 1
            channels_str = arrayfun(@num2str, 1:size(app.image_data, 4), 'UniformOutput', false);
            % Red.
            app.RDropDown.Items = channels_str;
            app.RDropDown.Value = app.RDropDown.Items{RGBW(1)};
            app.RCheckBox.Value = true;
            % Green.
            app.GDropDown.Items = channels_str;
            app.GDropDown.Value = app.GDropDown.Items{RGBW(2)};
            app.GCheckBox.Value = true;
            % Blue.
            app.BDropDown.Items = channels_str;
            app.BDropDown.Value = app.BDropDown.Items{RGBW(3)};
            app.BCheckBox.Value = true;
            % White.
            app.WDropDown.Items = channels_str;
            if size(app.image_data, 4)>3
                app.WDropDown.Value = app.WDropDown.Items{RGBW(4)};
            end
            app.WCheckBox.Value = false;
            % DIC.
            app.DICDropDown.Items = channels_str;
            if ~isnan(prefs.DIC)
                try
                    app.DICDropDown.Value = app.DICDropDown.Items{prefs.DIC};
                catch
                    app.DICDropDown.Value = '5';
                end
            end
            app.DICCheckBox.Value = false;
            % GFP.
            app.GFPDropDown.Items = channels_str;
            if ~isnan(prefs.GFP)
                try
                    app.GFPDropDown.Value = app.GFPDropDown.Items{prefs.GFP};
                catch
                    app.GFPDropDown.Value = '6';
                end
            end
            app.GFPCheckBox.Value = false;

            % Setup the worm info.
            app.worm = worm;
            app.BodyDropDown.Value = worm.body;
            app.AgeDropDown.Value = worm.age;
            app.SexDropDown.Value = worm.sex;
            app.StrainEditField.Value = worm.strain;
            app.SubjectNotesTextArea.Value = worm.notes;

            % Which part of the worm is this?
            %if isempty(worm.body)
            %    worm.body = uiconfirm(app.CELL_ID, ...
            %        'Which part of the worm is in the image?', ...
            %        'Worm Body Part', 'Options', {'Head', 'Tail'}, ...
            %        'DefaultOption', 1, 'Icon', 'question');
            %   	%'Worm Body Part', 'Head', 'Midbody', 'Tail', 'Head');
            %    save(app.image_file, 'prefs', '-append');
            %end

            % Enable the image GUI.
            Program.GUIHandling.gui_lock(app, 'enable', 'identification_tab');
            Program.GUIHandling.gui_lock(app, 'disable', 'neuron_gui');

            % Determine the image scale.
            scale = ones(1,3);
            if ~isempty(info.scale)
                scale = info.scale;
            end

            % Did we detect neurons?
            app.id_file = id_file;
            app.mp_params = mp;
            read_nwb_neurons = 0;
            if ~isempty(neurons)
                app.image_neurons = neurons;
                Program.GUIHandling.gui_lock(app, 'enable', 'neuron_gui');
            elseif contains(filename,'.nwb')
                
                nwb_data = nwbRead(filename);

                if any(ismember(nwb_data.processing.keys, 'NeuroPAL')) & (any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.keys, 'NeuroPALSegmentation')) | any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.keys, 'ImageSegmentation')) | any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.keys, 'VolumeSegmentation')) | any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.keys, 'NeuroPALNeurons')))
                    read_nwb_neurons = 1;
                end             

                app.image_neurons = Neurons.Image([], worm.body, 'scale', app.image_um_scale');
            else
                app.image_neurons = Neurons.Image([], worm.body, 'scale', app.image_um_scale');
            end

            % Restrict the slider to the z stack.
            num_z_slices = size(app.image_data, 3);
            if num_z_slices <= 1
                uialert(app.CELL_ID, 'The image is not a volume!', ...
                    'Image Not a Volume', 'Icon', 'error');
                return;
            end
            Program.Helpers.configure_main_zslider(app, num_z_slices);

            % Setup the z-axis orientation.
            app.ZCenterEditField.Value = round((prefs.z_center - 1) * info.scale(3), 1);
            if prefs.is_Z_LR
                app.ZAxisDropDown.Value = 'L/R';
                app.ZLeftLabel.Text = 'LEFT';
                app.ZRightLabel.Text = 'RIGHT';
            else
                app.ZAxisDropDown.Value = 'D/V';
                app.ZLeftLabel.Text = 'DORSAL';
                app.ZRightLabel.Text = 'VENTRAL';
            end

            % Setup the max projection.
            daspect(app.XY,[1 1 1]);
            daspect(app.MaxProjection,[1 1 1]);
            axis(app.MaxProjection, 'off');

            % Constrain the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];

            % Label the image.
            app.XY.Title.Interpreter = 'none';
            app.XY.Title.String = app.image_name;
            app.XY.TitleFontSizeMultiplier = 2;
            app.XY.TitleFontWeight = 'bold';
            x_ticks = linspace(0, size(app.image_data, 2), 15);
            y_ticks = linspace(0, size(app.image_data, 1), 5);
            app.XY.XTick = x_ticks;
            app.XY.YTick = y_ticks;
            x_labels = arrayfun(@(x) num2str(x, '%.1f'), x_ticks * info.scale(1), 'UniformOutput', false);
            y_labels = arrayfun(@(y) num2str(y, '%.1f'), y_ticks * info.scale(2), 'UniformOutput', false);
            x_labels{1} = '0';
            y_labels{1} = '0';
            app.XY.XTickLabel = x_labels;
            app.XY.YTickLabel = flip(y_labels);

            % Select no neurons.
            app.selected_neuron = [];

            % Draw everything.
            app.UnselectNeuron();
            app.UserNeuronIDsListBox.Items = {};
            app.UserNeuronIDsListBox.ItemsData = [];
            app.UserNeuronIDsListBox.Value = {};

            if ismember('is_matched', fieldnames(app.image_prefs))
                if app.image_prefs.is_matched == 1
                    app.image_data(:, :, :, app.image_info.RGBW(1:3)) = Methods.run_histmatch(app.image_data, app.image_info.RGBW);
                end
            end
            
            app.DrawImageData();
            app.UpdateSexNeuronLists();
            if ~isempty(app.image_neurons)
                app.UpdateNeuronLists();
                app.DrawAutoIDList();
            end

            if read_nwb_neurons == 1
                app.load_neurons_from_nwb(nwb_data);
                Program.GUIHandling.gui_lock(app, 'enable', 'neuron_gui');
            end

            % Go to the middle.
            zEvent.Value = round(num_z_slices / 2);
            app.ZSliderValueChanging(zEvent);
            Program.GUI.dialogues.resolve();

            addlistener(app.CELL_ID, 'WindowMousePress', @(src, event) app.DragManager('down', event));
            addlistener(app.CELL_ID, 'WindowMouseRelease', @(src, event) app.DragManager('up', event));
            
            % Done.
            app.is_opening_file = false;
            app.data_flags.('NeuroPAL_Volume') = 1;

            set(app.VolumeDropDown, 'Enable', 'on');
            
            if ~any(ismember(app.VolumeDropDown.Items, 'Colormap'))
                app.VolumeDropDown.Items{end+1} = 'Colormap';
            end
            app.VolumeDropDown.Value = 'Colormap';

            set(app.IdGridLayout, 'Visible', 'on');
            set(app.ProcessingGridLayout, 'Visible', 'on');
            set(app.IdButton, 'Visible', 'off');
            set(app.ProcessingButton, 'Visible', 'off');
        end

        % Menu selected function: DownsampleImageMenu
        function DecimateButtonPushed(app, event)
            Program.GUI.Apps.downsample_window(app, app.image_data, app.image_view, app.image_gamma, app.image_neurons);        
        end

        % Menu selected function: ThresholdImageMenu
        function AdaptThreshButtonPushed(app, event)
            % Open a propt to ask the adaptive thresholding parameters
            % from user.
            prompt = {'Enter the method (adaptive/fixed/otsu):', 'Enter the parameter for the model:'};
            title = 'Thresholding Parameters';
            dims = [1 35];
            definput = {'adaptive', '0.9'};
            answer = inputdlg(prompt,title,dims,definput);

            % Run thresholding based on user input.
            app.image_data = Methods.Preprocess.threshold_frame(app.image_data, answer{1}, str2double(answer{2}));
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            
            % Update the view using the thresholded image.
            app.DrawImageData();
        end

        % Callback function: HistogramMatchingMenu, 
        % ...and 1 other component
        function HistMatchButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,"Indeterminate","on", "Message", 'Matching Histograms');
            % Histogram matching for color normalization. The function
            % has no parameters.
            if app.TabGroup.SelectedTab == app.NeuroPALIDTab
                app.image_data(:, :, :, app.image_info.RGBW(1:3)) = Methods.run_histmatch(app.image_data, app.image_info.RGBW);
                app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
                
                % Update the view using the histogram matched image.
                app.DrawImageData();
    
                % Save to file.
                app.image_prefs.('is_matched') = 1;
                prefs = app.image_prefs;
                save(app.image_file, 'prefs', '-append');
            else
                if strcmp(app.VolumeDropDown.Value, 'Colormap')
                    prefs = app.proc_image.prefs;
                    if isfield(prefs, 'is_matched')
                        check = uiconfirm(app.CELL_ID, "This file has already had histogram matching applied to it. Are you sure you want match again?", "Warning!", "Options", ["Yes", "No, cancel"]);

                        if strcmp(check, "No, cancel")
                            return
                        end
                    end
                end

                Program.Routines.Processing.save_prompt('histmatch');
                app.drawProcImage();
            end
            
            close(d)
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)

            % Snap the slider into place.
            z = round(event.Value);
            app.ZSlider.Value = z;

            % Draw the neurons in this z-slice.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
        end

        % Button pushed function: AutoDetectButton
        function AutoDetectButtonPushed(app, event)

            % Determine the number of neurons.
            body = app.BodyDropDown.Value;
            num_neurons = app.neuron_info.numNeurons(body);
            if isempty(num_neurons)
                return;
            end

            % Do we already have neurons detected?
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                answer = uiconfirm(app.CELL_ID, ...
                    'Neurons have already been marked, would you like to overwrite them?', ...
                    'Neurons Already Marked', ...
                    'Options', {'Overwrite', 'Cancel'}, 'DefaultOption', 2, 'CancelOption', 2, ...
                    'Icon', 'warning');
                if strcmpi(answer, 'cancel')
                    return;
                end
            end

            % Unselect neurons.
            UnselectNeuron(app);
            app.UserNeuronIDsListBox.Items = {};
            app.UserNeuronIDsListBox.ItemsData = [];
            app.UserNeuronIDsListBox.Value = {};

            % Detect the neurons.
            GUI_prefs = Program.GUIPreferences.instance();
            if GUI_prefs.is_MP_detect
                [sp, app.mp_params] = Methods.AutoDetect.instance().detect(app.image_file, ...
                    app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                    num_neurons, 1.0, app.image_um_scale', app.worm, 2);
            else
                [sp, app.mp_params] = Methods.NNDetect.detect(app.image_file, ...
                    app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))));
            end

            % Do we have any neuron predictions?
            if isempty(sp)

                % Warn the user.
                uiconfirm(app.CELL_ID, ...
                    'Auto detect neurons failed to find any neurons!', ...
                    'Auto Detect Neurons Failed', ...
                    'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'error');

                % Enable the neuron GUI.
                if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                    Program.GUIHandling.gui_lock(app, 'enable', 'neuron_gui');
                end
                return;
            end

            % Add the detected neurons to the image.
            app.image_neurons = Neurons.Image(sp, app.worm.body, 'scale', app.image_um_scale');

            % Remove duplicate detections.
            % Neurons within 2 microns of each other.
            Methods.Utils.removeNearbyNeurons(app.image_neurons, 2, 2);

            % Save the IDs.
            SaveIDToFile(app);

            % Update the ID'd neuron count.
            UpdateNeuronLists(app);

            % Enable the neuron GUI.
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                Program.GUIHandling.gui_lock(app, 'enable', 'neuron_gui');
            end

            % Draw the image.
            app.DrawImageData();

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Auto detect neurons completed successfully.', 'Auto Detect Neurons Completed', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Button pushed function: AutoIDAllButton
        function AutoIDAllButtonPushed(app, event)

            % Are there any neurons?
            if isempty(app.image_neurons) || app.image_neurons.num_neurons() < 1
                uiconfirm(app.CELL_ID, ...
                    'There are no neurons to auto ID!', 'No Neurons to ID', ...
                    'Options', {'Cancel'}, 'DefaultOption', 1, 'CancelOption', 1, ...
                    'Icon', 'warning');
                return;
            end

            % Can we handle this body part?
            %body = app.image_neurons.bodypart; DEPRECATED
            body = app.worm.body;
            switch lower(body)
                case 'head'
                    % all good
                case 'tail'
                    % all good
                otherwise
                    uiconfirm(app.CELL_ID, ...
                        {['Presently, there is no atlas to auto ID the "' body '".'], ...
                        'Please manually ID this region.'}, ...
                        'No Atlas for Auto ID', 'Options', {'Cancel'}, ...
                        'DefaultOption', 1, 'CancelOption', 1, 'Icon', 'error');
                    return;
            end

            % Are the neurons already ID'd?
            if ~isempty(app.image_neurons.get_probabilistic_probs())
                answer = uiconfirm(app.CELL_ID, ...
                    'The neurons have already been auto ID''d, would you like to re-ID them?', ...
                    'Neurons Already Auto ID''d', ...
                    'Options', {'Re-ID', 'Cancel'}, 'DefaultOption', 2, 'CancelOption', 2, ...
                    'Icon', 'warning');
                if strcmpi(answer, 'cancel')
                    return;
                end
            end

            % Unselect neurons.
            UnselectNeuron(app);

            % Auto ID the neurons.
            try
                Methods.AutoId.instance().id(app.image_file, ...
                    app.image_neurons, app.worm);
            catch E
                uiconfirm(app.CELL_ID, ...
                    {E.message}, ...
                    'Error', 'Options', {'Cancel'}, ...
                    'DefaultOption', 1, 'CancelOption', 1, 'Icon', 'error');
                return;
            end

            % Update the auto ID'd neuron list.
            DrawAutoIDList(app);

            % Draw the image.
            app.DrawImageData();

            % Save the IDs.
            SaveIDToFile(app);

            % Update ID confidences
            UpdateNeuronIDConfidence(app);

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Auto ID neurons completed successfully.', 'Auto ID Neurons Completed', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Callback function
        function AutoNameNeurons(app, event)
            % Save the GUI preferences.
            GUI_prefs = Program.GUIPreferences.instance();
            GUI_prefs.is_auto_name = app.AutoNameCheckBox.Value;
            GUI_prefs.save();
        end

        % Callback function: NormalizeColorsMenu, ProcNormalizeColorsButton
        function ZScoringMenuSelected(app, event)
            if app.TabGroup.SelectedTab == app.NeuroPALIDTab
                app.image_data = Methods.Preprocess.zscore_frame(app.image_data);
                app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
                app.DrawImageData();
            else
                Program.Routines.Processing.save_prompt('zscore');
                app.drawProcImage();
            end
        end

        % Menu selected function: RotateAllHorizontalMenu
        function RotateAllHorizontal(app, event)
            % Rotate the image and neurons pi degrees around x-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [pi,0,0]);
            app.image_data = app.image_neurons.rotate_X_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateAllVerticalMenu
        function RotateAllVertical(app, event)
            % Rotate the image and neurons pi degrees around y-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [0,pi,0]);
            app.image_data = app.image_neurons.rotate_Y_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateAllClockwiseMenu
        function RotateAllClockwise(app, event)
            % Rotate the image and neurons pi/2 degrees around z-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [pi/2,0,0]);
            [app.image_data, app.image_info.scale] = ...
                app.image_neurons.rotate_Z_90(app.image_data, app.image_info.scale);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: RotateAllAntiClockwiseMenu
        function RotateAllAntiClockwise(app, event)
            % Rotate the image and neurons -pi/2 degrees around z-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [-pi/2,0,0]);
            if app.TabGroup.SelectedTab == app.NeuroPALIDTab
                [app.image_data, app.image_info.scale] = ...
                    app.image_neurons.rotate_Z_270(app.image_data, app.image_info.scale);
                app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
    
                % Save the image & neurons.
                data = app.image_data;
                info = app.image_info;
                save(app.image_file, 'data', 'info', '-append');
                SaveIDToFile(app);
    
                % Redraw the image.
                app.XY.XLim = [0, size(app.image_data, 2)];
                app.XY.YLim = [0, size(app.image_data, 1)];
                app.DrawImageData();
            else
                check = uiconfirm(app.CELL_ID, "Do you want to save this operation to the file?", "NeuroPAL_ID", "Options", ["Yes", "No, stick with preview"]);

                switch check
                    case "Yes"
                        app.proc_apply_rot('acc');
                        ac_flag = 0;
                    case "No"
                        ac_flag = 1;
                end

                app.drawProcImage();
            end
        end

        % Menu selected function: RotateImageHorizontalMenu
        function RotateImageHorizontal(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image pi degrees around x-axis.
            app.image_data = app.image_neurons.rotate_image_X_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateImageVerticalMenu
        function RotateImageVertical(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image pi degrees around y-axis.
            app.image_data = app.image_neurons.rotate_image_Y_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateImageClockwiseMenu
        function RotateImageClockwise(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image pi/2 degrees around z-axis.
            [app.image_data, app.image_info.scale] = ...
                app.image_neurons.rotate_image_Z_90(app.image_data, app.image_info.scale);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: RotateImageAntiClockwiseMenu
        function RotateImageAntiClockwise(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image -pi/2 degrees around z-axis.
            [app.image_data, app.image_info.scale] = ...
                app.image_neurons.rotate_image_Z_270(app.image_data, app.image_info.scale);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: RotateNeuronsHorizontalMenu
        function RotateNeuronsHorizontal(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the neurons separately from the image?', ...
                'Rotate Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the neurons pi degrees around x-axis.
            app.image_neurons.rotate_neurons_X_180(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end
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